classdef MotionControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        Recording                       matlab.ui.container.Tab
        CapturingparametersPanel        matlab.ui.container.Panel
        ClockInputChannelSpinner        matlab.ui.control.Spinner
        ClockInputChannelSpinnerLabel   matlab.ui.control.Label
        Ao_1DCSpinner                   matlab.ui.control.Spinner
        Ao_1DCSpinnerLabel              matlab.ui.control.Label
        Ao_1FreqSpinner                 matlab.ui.control.Spinner
        Ao_1FreqSpinnerLabel            matlab.ui.control.Label
        Ao_0DCSpinner                   matlab.ui.control.Spinner
        Ao_0DCSpinnerLabel              matlab.ui.control.Label
        Ao_0FreqSpinner                 matlab.ui.control.Spinner
        Ao_0FreqSpinnerLabel            matlab.ui.control.Label
        ArmedText                       matlab.ui.control.Label
        PlotColumnCount                 matlab.ui.control.NumericEditField
        PlotcolumnsEditFieldLabel       matlab.ui.control.Label
        Rate                            matlab.ui.control.NumericEditField
        DurationsEditField_2Label       matlab.ui.control.Label
        ArmedLamp                       matlab.ui.control.Lamp
        AcquireSwitch                   matlab.ui.control.Switch
        AcquireSwitchLabel              matlab.ui.control.Label
        DeviceName                      matlab.ui.control.EditField
        DeviceEditFieldLabel            matlab.ui.control.Label
        InputChannelsList               matlab.ui.control.EditField
        InputChannelslistLabel          matlab.ui.control.Label
        LivePlotLengthField             matlab.ui.control.NumericEditField
        PlottingtimesEditFieldLabel     matlab.ui.control.Label
        BiasTick                        matlab.ui.control.CheckBox
        CalibrationTick                 matlab.ui.control.CheckBox
        ArmButton                       matlab.ui.control.Button
        PostcapturesetupPanel           matlab.ui.container.Panel
        IgnoreChannelsPlot              matlab.ui.control.EditField
        InputChannelslistLabel_2        matlab.ui.control.Label
        DataexportchecklistPanel        matlab.ui.container.Panel
        TestmatCheckBox                 matlab.ui.control.CheckBox
        forceData_wPreOffset_wPostOffsetCheckBox  matlab.ui.control.CheckBox
        forceData_wPreOffset_woPostOffsetCheckBox  matlab.ui.control.CheckBox
        forceData_woOffsetCheckBox      matlab.ui.control.CheckBox
        data_wPreOffset_wPostOffsetCheckBox  matlab.ui.control.CheckBox
        data_wPreOffset_woPostOffsetCheckBox  matlab.ui.control.CheckBox
        data_woOffsetCheckBox           matlab.ui.control.CheckBox
        ExporttomatButton               matlab.ui.control.Button
        ExporttocsvButton               matlab.ui.control.Button
        ExportmatStatus                 matlab.ui.control.Label
        ExportcsvStatus                 matlab.ui.control.Label
        SavingdirEditField              matlab.ui.control.EditField
        SavingdirEditFieldLabel         matlab.ui.control.Label
        ExperimentalsetupPanel          matlab.ui.container.Panel
        UseclockchannelCheckBox         matlab.ui.control.CheckBox
        RestperiodfirstCheckBox         matlab.ui.control.CheckBox
        ChecktocommitsettingsCheckBox   matlab.ui.control.CheckBox
        RecordingModeButtonGroup        matlab.ui.container.ButtonGroup
        MultipleCyclesButton            matlab.ui.control.RadioButton
        SingleCycleButton               matlab.ui.control.RadioButton
        RestperiodsEditField            matlab.ui.control.NumericEditField
        RestperiodsEditFieldLabel       matlab.ui.control.Label
        RecordingperiodsEditField       matlab.ui.control.NumericEditField
        RecordingperiodsEditFieldLabel  matlab.ui.control.Label
        cyclesEditField                 matlab.ui.control.NumericEditField
        cyclesEditFieldLabel            matlab.ui.control.Label
        AutosaveduringrestCheckBox      matlab.ui.control.CheckBox
        CycleNumberField                matlab.ui.control.Spinner
        TrialLabel                      matlab.ui.control.Label
        SubjectNameEditField            matlab.ui.control.EditField
        SubjectnameLabel                matlab.ui.control.Label
        VariableNameEditField           matlab.ui.control.EditField
        VariablenameEditFieldLabel      matlab.ui.control.Label
        TriggerCondition                matlab.ui.control.DropDown
        TriggerConditionDropDownLabel   matlab.ui.control.Label
        CaptureLengthField              matlab.ui.control.NumericEditField
        DurationsEditField_2Label_2     matlab.ui.control.Label
        TriggerVoltage                  matlab.ui.control.NumericEditField
        TriggerLevelVLabel              matlab.ui.control.Label
        TriggerChannel                  matlab.ui.control.NumericEditField
        TriggerChannelLabel             matlab.ui.control.Label
        TriggerDelaysLabel              matlab.ui.control.Label
        OffsetDurationField             matlab.ui.control.NumericEditField
        LiveplotduringcapturingCheckBox  matlab.ui.control.CheckBox
        CaptureButton                   matlab.ui.control.StateButton
        TabGroup                        matlab.ui.container.TabGroup
        SubplotView                     matlab.ui.container.Tab
        SubplotAxesPanel                matlab.ui.container.Panel
        CombinedPlotView                matlab.ui.container.Tab
        LiveDataAxes                    matlab.ui.control.UIAxes
        PostCaptureplotTab              matlab.ui.container.Tab
        PostCaptureAxesPanel            matlab.ui.container.Panel
        RunningstatPanel                matlab.ui.container.Panel
        StatusText_2                    matlab.ui.control.Label
        ExperimentalstatusLabel         matlab.ui.control.Label
        StatusText                      matlab.ui.control.Label
        SystemstatusLabel               matlab.ui.control.Label
        CapturetimerLabelValue          matlab.ui.control.Label
        CapturetimerLabel               matlab.ui.control.Label
        TriggerChannelLabelValue        matlab.ui.control.Label
        TriggerchannelLabel             matlab.ui.control.Label
        FIFOCountValue                  matlab.ui.control.Label
        FIFOcountLabel                  matlab.ui.control.Label
        BufferSizeValue                 matlab.ui.control.Label
        BuffersizeLabel                 matlab.ui.control.Label
        CaptureDataAxes                 matlab.ui.control.UIAxes
        Plotting                        matlab.ui.container.Tab
        PostOffsetPeriodSpinner         matlab.ui.control.Spinner
        PostOffsetPeriodSpinnerLabel    matlab.ui.control.Label
        PostOffsetSwitch                matlab.ui.control.ToggleSwitch
        PostOffsetSwitchLabel           matlab.ui.control.Label
        ChannelplotselectDropDown       matlab.ui.control.DropDown
        ChannelplotselectDropDownLabel  matlab.ui.control.Label
        PreOffsetSwitch                 matlab.ui.control.ToggleSwitch
        PreOffsetSwitchLabel            matlab.ui.control.Label
        PlotChosenChannel               matlab.ui.control.Button
        ApplycalibrationButton          matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        DataPlotPanel                   matlab.ui.container.Panel
        SettingsTab                     matlab.ui.container.Tab
        CalibratedMatrixLabel           matlab.ui.control.Label
        calibratedMatrixTable           matlab.ui.control.Table
        MinMaxYTable                    matlab.ui.control.Table
        rotationMatrixTable             matlab.ui.control.Table
        translationMatrixTable          matlab.ui.control.Table
        ReloadMatricesButton            matlab.ui.control.Button
        UpdateMatricesButton            matlab.ui.control.Button
        CalibrationMatrixLabel          matlab.ui.control.Label
        TranslationalMatrixLabel        matlab.ui.control.Label
        RotationalMatrixLabel           matlab.ui.control.Label
        calibrationMatrixTable          matlab.ui.control.Table
    end

    
    properties (Access = private)
        DAQ                      % Handle to DAQ object
        DevicesInfo              % Array of devices that provide analog input voltage or audio input measurements
        TimestampsFIFOBuffer     % Timestamps FIFO buffer used for live plot of latest "N" seconds of acquired data
        DataFIFOBuffer           % Data FIFO buffer used for live plot of latest "N" seconds of acquired data
        FIFOMaxSize = 1E+7;      % Maximum allowed FIFO buffer size for DataFIFOBuffer and TimestampsFIFOBuffer
        FIFOIndex = 1;           % FIFO index
        LivePlotsLineSubplot     % Handle to line plot of acquired data
        LivePlotsLineCombined    % Handle to line plot of single plot of acquired data
        LiveVectorPointSubplot   % List of scatter point in vector view
        CapturePlotLine          % Handle to line plot of capture data
        CyclePlotLine            % Handle to line plot of cycle data
        DataPlotsLine            % Handle to line plot of captured data
        TrigActive = false;      % Trigger detected flag
        TrigMoment = [];         % Relative timestamp (s) of detected trigger event
        CaptureTimestamps        % Captured data timestamps (s)
        CaptureData              % Captured data
        CaptureDataCalibrate     % Calibrated captured data
        Timestamps = [];         % Latest timestamps values used for trigger detection
        Data = [];               % Latest data values used for trigger detection       
        CallbackTimeSpan         % Timespan of data read in ScansAvailable callback
        TriggerDelay = 0         % Trigger delay (s)
        CaptureDuration          % Capture duration (s)
        ViewTimeWindow           % Live view timespan (s)
        CaptureStartMoment       % Relative timestamp of capture start moment (s)

        DatetimeStopMoment       % Current date time of when the stop signal assigned
        TimerValue               % Time value
        BufferSize               % Size of the data FIFO buffer
        TriggerDelayMax = 3600;  % Upper limit for trigger delay (s)
        CurrentState = '';       % App current state       
        ChannelNumbers = [];     % List of channels in array
        LiveAxesList             % List of live channels' plots
        DataAxesList             % List of captured data's plots
        CaptureAxesList = [];    % List of captured channels' plots
        ChannelCount             % Number of recording channels
        BufferRowSize            % Number of recording channels including diff channels

        InputChannelNumbers      % List of input channels
        OutputChannelNumbers     % List of output channels
        AddedChannelIDs          % Initialize or reset the counter channel list
        CounterChannelStatus     % Status of the adding/removing channel counter
        ClockChannelNumber       % List of clock channels
        OutputChannelsListLength % Length of the output channel list (for repmat)
        ClockData                % The value from the clock channel
        TimestampVideoList       % List of video frame timestamp
        TimestampOffset          % Because turning on/off the counter reset the read timestamp, store the current timestamp before that
        TriggerIndex             % Index of the trigger
        
        ChannelIndexLU           % Channel lookup table
        PreOffsetData            % Pre-Offset data
        PreOffsetDataRaw         % Pre-Offset data raw
        PostOffsetData           % Post-Offset data
        OffsetStartMoment        % Offset starting moment
        OffsetDuration           % Offset duration

        % Calibration matrices
        CalibratedMatrix         % Calibrated matrix - RotationMatrix*TranslationMatrix*CalibrationMatrix
        TranslationMatrix        % Translation matrix
        RotationMatrix           % Rotation matrix
        CalibrationMatrix        % Calibration matrix
        
        % Strain gauge data
        SGChannelList            % List of strain gage from channel list
        PostCaptureAxesList      % List of subplot axes in post-capture view
        SGChannelCheck           % Check if channels 16-18 presented
        CaptureDataRaw           % Capture data raw
        NameForceList            % List of force components name
        CurrentPlottingData      % Contain currently plotted data
        RecordingMode            % Single or Multi-cycle recordings
        CycleCountTotal          % Maximum number of cycles per multi-cycle recording session
        CycleCountCurrent        % Current cycles in the multi-cycle recordding session
        CyclePlotRowCount        % Number of rows for multiple cycle plot
        CyclePlotColumnCount     % Number of columns for multiple cycle plot
        RestPeriodLength         % The defined length of the resting period
        RestPeriodStartTime      % The starting time of the resting period
        RestPeriodFirst          % Rest period first - True/False
        RecordingPeriodLength    % The defined length of the recording period
        RecordingPeriodStartTime % The starting time of the recording period

    end
    
    methods (Access = private)
        
        function setAppViewState(app, state)
            switch state                           
                case 'Unarm'
                    app.DeviceName.Enable = 'on';
                    app.AcquireSwitch.Enable = 'off';
                    app.AcquireSwitch.Value = 'Stop';
                    app.InputChannelsList.Enable = 'on';
                    app.Rate.Enable = 'on';
                    app.RecordingModeButtonGroup.Enable = 'on';
                    app.UseclockchannelCheckBox.Enable = 'on';
                    if app.SingleCycleButton.Value == 1
                        app.CaptureLengthField.Enable = 'on';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    elseif app.MultipleCyclesButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'on';
                        app.RecordingperiodsEditField.Enable = 'on';
                        app.RestperiodsEditField.Enable = 'on';
                    end
                    app.LivePlotLengthField.Enable = 'on';
                    app.SubjectNameEditField.Enable = 'on';
                    app.VariableNameEditField.Enable = 'on';
                    app.ExporttomatButton.Enable = 'on';
                    app.ExporttocsvButton.Enable = 'on';                    
                    app.ExportcsvStatus.Text = 'Do not export without capturing data';
                    app.ExportmatStatus.Text = 'Do not export without capturing data';

                    app.CaptureButton.Enable = 'off';
                    app.StatusText.Text = 'Configurating...';
                    app.CaptureButton.Text = 'Capture';
                    app.CaptureButton.Value = 0;

                    app.OffsetDurationField.Enable = 'on';
                    app.ArmedLamp.Color = 'red';
                    app.ArmedText.Text = 'Unarm';
                    app.ArmButton.Enable = 'on';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'on';
                    app.BiasTick.Enable = 'on';

                case 'Arming'
                    app.DeviceName.Enable = 'off';
                    app.AcquireSwitch.Enable = 'off';
                    app.AcquireSwitch.Value = 'Stop';
                    app.InputChannelsList.Enable = 'off';
                    app.Rate.Enable = 'off';
                    app.RecordingModeButtonGroup.Enable = 'off';
                    app.UseclockchannelCheckBox.Enable = 'off';

                    app.CaptureLengthField.Enable = 'off';
                    app.cyclesEditField.Enable = 'off';
                    app.RecordingperiodsEditField.Enable = 'off';
                    app.RestperiodsEditField.Enable = 'off';

                    app.CaptureLengthField.Enable = 'off';
                    app.cyclesEditField.Enable = 'off';
                    app.RecordingperiodsEditField.Enable = 'off';
                    app.RestperiodsEditField.Enable = 'off';

                    app.LivePlotLengthField.Enable = 'on';
                    app.SubjectNameEditField.Enable = 'on';
                    app.VariableNameEditField.Enable = 'on';
                    app.RecordingModeButtonGroup.Enable = "on";

                    app.ExporttomatButton.Enable = 'on';
                    app.ExporttocsvButton.Enable = 'on';
                    app.ExportcsvStatus.Text = 'Do not export without capturing data';
                    app.ExportmatStatus.Text = 'Do not export without capturing data';
                    app.CaptureButton.Enable = 'off';

                    app.OffsetDurationField.Enable = 'off';

                    app.StatusText.Text = 'Arming ...';
                    app.ArmedLamp.Color = 'yellow';
                    app.ArmedText.Text = 'Arming';
                    app.ArmButton.Enable = 'off';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.BiasTick.Enable = 'off';


                case 'Armed'
                    app.DeviceName.Enable = 'on';
                    app.AcquireSwitch.Enable = 'on';
                    app.AcquireSwitch.Value = 'Stop';
                    app.InputChannelsList.Enable = 'on';
                    app.Rate.Enable = 'on';
                    app.RecordingModeButtonGroup.Enable = 'on';
                    app.UseclockchannelCheckBox.Enable = 'on';

                    if app.SingleCycleButton.Value == 1
                        app.CaptureLengthField.Enable = 'on';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    elseif app.MultipleCyclesButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'on';
                        app.RecordingperiodsEditField.Enable = 'on';
                        app.RestperiodsEditField.Enable = 'on';
                    end
                    app.LivePlotLengthField.Enable = 'on';
                    app.SubjectNameEditField.Enable = 'on';
                    app.VariableNameEditField.Enable = 'on';
                    app.RecordingModeButtonGroup.Enable = "on";

                    app.ExporttomatButton.Enable = 'on';
                    app.ExporttocsvButton.Enable = 'on';
                    app.ExportcsvStatus.Text = 'Do not export without capturing data';
                    app.ExportmatStatus.Text = 'Do not export without capturing data';

                    app.CaptureButton.Enable = 'off';
                    app.StatusText.Text = 'Armed. Ready for capture!';
                    
                    app.OffsetDurationField.Enable = 'on';
                    app.ArmedLamp.Color = 'green';
                    app.ArmedText.Text = 'Armed';
                    app.ArmButton.Enable = 'on';
                    app.BiasTick.Enable = 'on';

                case 'Acquisition.Buffering'
                    app.DeviceName.Enable = 'off';
                    app.InputChannelsList.Enable = 'off';
                    app.Rate.Enable = 'off';
                    app.LivePlotLengthField.Enable = 'on';
                    app.SubjectNameEditField.Enable = 'on';
                    app.VariableNameEditField.Enable = 'on';
                    app.RecordingModeButtonGroup.Enable = "on";
                    app.UseclockchannelCheckBox.Enable = 'on';

                    app.CaptureButton.Enable = 'off';
                    app.ArmButton.Enable = 'off';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.BiasTick.Enable = 'on';

                    app.OffsetDurationField.Enable = 'on';
                    app.RecordingModeButtonGroup.Enable = 'on';
                    if app.SingleCycleButton.Value == 1
                        app.CaptureLengthField.Enable = 'on';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                        app.RestperiodfirstCheckBox.Enable = 'off';
                    elseif app.MultipleCyclesButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'on';
                        app.RecordingperiodsEditField.Enable = 'on';
                        app.RestperiodsEditField.Enable = 'on';
                        app.RestperiodfirstCheckBox.Enable = 'on';

                    end
                    app.ExporttomatButton.Enable = 'off';
                    app.ExporttocsvButton.Enable = 'off';
                    app.ExportcsvStatus.Text = 'Not available';
                    app.ExportmatStatus.Text = 'Not available';
                    app.StatusText.Text = 'Buffering pre-trigger data...';
                
                case 'Acquisition.CollectingOffset'
                    app.DeviceName.Enable = 'off';
                    app.InputChannelsList.Enable = 'off';
                    app.Rate.Enable = 'off';
                    app.LivePlotLengthField.Enable = 'on';
                    app.SubjectNameEditField.Enable = 'on';
                    app.VariableNameEditField.Enable = 'on';
                    app.RecordingModeButtonGroup.Enable = 'on';
                    app.UseclockchannelCheckBox.Enable = 'on';

                    app.CaptureButton.Enable = 'off';
                    app.ArmButton.Enable = 'off';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.BiasTick.Enable = 'off';

                    app.OffsetDurationField.Enable = 'on';
                    app.RecordingModeButtonGroup.Enable = 'on';
                    if app.SingleCycleButton.Value == 1
                        app.CaptureLengthField.Enable = 'on';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                        app.MultipleCyclesButton.Enable = 'off';
                        app.RestperiodfirstCheckBox.Enable = 'off';

                    elseif app.MultipleCyclesButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'on';
                        app.RecordingperiodsEditField.Enable = 'on';
                        app.RestperiodsEditField.Enable = 'on';
                        app.MultipleCyclesButton.Enable = 'on';
                        app.RestperiodfirstCheckBox.Enable = 'on';
                    end
                    app.ExporttomatButton.Enable = 'off';
                    app.ExporttocsvButton.Enable = 'off';
                    app.ExportcsvStatus.Text = 'Not available';
                    app.ExportmatStatus.Text = 'Not available';
                    app.StatusText.Text = 'Buffering pre-trigger data...';

                case 'Acquisition.ReadyForCapture'
                    app.OffsetDurationField.Enable = 'on';
                    app.ArmButton.Enable = 'on';
                    app.RecordingModeButtonGroup.Enable = 'on';
                    app.UseclockchannelCheckBox.Enable = 'on';

                    if app.SingleCycleButton.Value == 1
                        app.CaptureLengthField.Enable = 'on';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                        app.CaptureButton.Enable = 'on'; 
                        app.CaptureButton.Text = 'Capture';

                    elseif app.MultipleCyclesButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        if app.ChecktocommitsettingsCheckBox.Value == 1
                            app.CaptureButton.Enable = 'on';
                            app.CaptureButton.Text = 'Capture';
                            app.cyclesEditField.Enable = 'off';
                            app.RecordingperiodsEditField.Enable = 'off';
                            app.RestperiodsEditField.Enable = 'off';
                            app.RestperiodfirstCheckBox.Enable = 'off';
                            commitCycleSettings(app)
                        elseif app.ChecktocommitsettingsCheckBox.Value == 0
                            app.CaptureButton.Enable = 'off';
                            app.CaptureButton.Text = 'Off';
                            app.cyclesEditField.Enable = 'on';
                            app.RecordingperiodsEditField.Enable = 'on';
                            app.RestperiodsEditField.Enable = 'on';
                            app.RestperiodfirstCheckBox.Enable = 'on';
                        end
                    end

                    app.StatusText.Text = 'Ready for capture';
                    app.TrigActive = 0;
                    app.PlotButton.Enable = 'on';
                    app.PlotChosenChannel.Enable = 'on';
                    app.ChannelplotselectDropDown.Enable = 'on';
                    app.ApplycalibrationButton.Enable = 'on';
                    app.RecordingModeButtonGroup.Enable = 'on';
                    app.CaptureButton.Value = 0;
                    app.CycleNumberField.Value = 1; % Reset the cycle count value
                    app.SubjectNameEditField.Enable = 'on';
                    app.VariableNameEditField.Enable = 'on';
                    app.ExporttomatButton.Enable = 'on';
                    app.ExporttocsvButton.Enable = 'on';
                    app.ExportcsvStatus.Text = 'Do not export without capturing data';
                    app.ExportmatStatus.Text = 'Do not export without capturing data';
                    app.BiasTick.Enable = 'on';
                
                case 'Acquisition.WaitingForClockTrigger'
                    app.CaptureButton.Enable = 'on';
                    app.StatusText.Text = 'Waiting for clock trigger ...';
                    app.CaptureButton.Text = 'Cancel capture';
                    app.cyclesEditField.Enable = 'off';
                    app.RecordingperiodsEditField.Enable = 'off';
                    app.RestperiodsEditField.Enable = 'off';
                    app.RestperiodfirstCheckBox.Enable = 'off';
                    app.RecordingModeButtonGroup.Enable = 'off';
                    app.UseclockchannelCheckBox.Enable = 'off';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.SubjectNameEditField.Enable = 'off';
                    app.VariableNameEditField.Enable = 'off';
                    app.ExporttomatButton.Enable = 'off';
                    app.ExporttocsvButton.Enable = 'off';
                    app.ExportcsvStatus.Text = 'Not available';
                    app.ExportmatStatus.Text = 'Not available';

                case 'Capture-Single.CollectingOffset'
                    app.StatusText.Text = 'Collecting offset...';
                    app.OffsetDurationField.Enable = 'off';
                    app.RecordingModeButtonGroup.Enable = 'off';
                    app.UseclockchannelCheckBox.Enable = 'off';
                    if app.SingleCycleButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    elseif app.MultipleCyclesButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    end
                    app.ArmButton.Enable = 'off';
                    app.CaptureButton.Text = 'Cancel capture';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.SubjectNameEditField.Enable = 'off';
                    app.VariableNameEditField.Enable = 'off';
                    app.ExporttomatButton.Enable = 'off';
                    app.ExporttocsvButton.Enable = 'off';
                    app.ExportcsvStatus.Text = 'Not available';
                    app.ExportmatStatus.Text = 'Not available';

                case 'Capture-Single.LookingForTrigger'
                    app.StatusText.Text = 'Waiting for trigger...';
                    app.OffsetDurationField.Enable = 'off';
                    app.RecordingModeButtonGroup.Enable = 'off';
                    app.UseclockchannelCheckBox.Enable = 'off';
                    if app.SingleCycleButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    elseif app.MultipleCyclesButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    end
                    app.ArmButton.Enable = 'off';
                    app.CaptureButton.Text = 'Cancel capture';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.SubjectNameEditField.Enable = 'off';
                    app.VariableNameEditField.Enable = 'off';
                    app.ExporttomatButton.Enable = 'off';
                    app.ExporttocsvButton.Enable = 'off';
                    app.ExportcsvStatus.Text = 'Not available';
                    app.ExportmatStatus.Text = 'Not available';
                    
                case 'Capture-Single.CapturingData'
                    app.StatusText.Text = 'Trigger detected. Capturing...';
                    app.OffsetDurationField.Enable = 'off';
                    app.RecordingModeButtonGroup.Enable = 'off';
                    app.UseclockchannelCheckBox.Enable = 'off';
                    if app.SingleCycleButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    elseif app.MultipleCyclesButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    end
                    app.ArmButton.Enable = 'off';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.CapturetimerLabelValue.Text = num2str(app.TimerValue);
                    app.SubjectNameEditField.Enable = 'off';
                    app.VariableNameEditField.Enable = 'off';
                    app.ExporttomatButton.Enable = 'off';
                    app.ExporttocsvButton.Enable = 'off';
                    app.ExportcsvStatus.Text = 'Not available';
                    app.ExportmatStatus.Text = 'Not available';

                case 'Capture-Single.CaptureComplete'
                    app.StatusText.Text = 'Capture completed!';
                    app.OffsetDurationField.Enable = 'off';
                    app.RecordingModeButtonGroup.Enable = "off";
                    app.UseclockchannelCheckBox.Enable = 'off';
                    if app.SingleCycleButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    elseif app.MultipleCyclesButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    end

                    app.ArmButton.Enable = 'on';
                    app.PlotButton.Enable = 'on';
                    app.PlotChosenChannel.Enable = 'on';
                    app.ChannelplotselectDropDown.Enable = 'on';
                    app.ApplycalibrationButton.Enable = 'on';
                    app.CapturetimerLabelValue.Text = '0';
                    app.SubjectNameEditField.Enable = 'on';
                    app.VariableNameEditField.Enable = 'on';
                    app.RecordingModeButtonGroup.Enable = "on";
                    app.UseclockchannelCheckBox.Enable = 'on';
                    app.ExporttomatButton.Enable = 'on';
                    app.ExporttocsvButton.Enable = 'on';
                    app.ExportcsvStatus.Text = 'Ready to export';
                    app.ExportmatStatus.Text = 'Ready to export';

                case 'Capture-Multiple.CollectingOffset'
                    app.StatusText.Text = 'Collecting offset...';
                    app.OffsetDurationField.Enable = 'off';
                    app.RecordingModeButtonGroup.Enable = 'off';
                    app.UseclockchannelCheckBox.Enable = 'off';
                    if app.SingleCycleButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    elseif app.MultipleCyclesButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    end
                    app.ArmButton.Enable = 'off';
                    app.CaptureButton.Text = 'Cancel capture';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.SubjectNameEditField.Enable = 'off';
                    app.VariableNameEditField.Enable = 'off';
                    app.ExporttomatButton.Enable = 'off';
                    app.ExporttocsvButton.Enable = 'off';
                    app.ExportcsvStatus.Text = 'Not available';
                    app.ExportmatStatus.Text = 'Not available';
                       
                case 'Capture-Multiple.LookingForTrigger'
                    cycleCountString = "Current cycle #: " + string(app.CycleCountCurrent) + ". Waiting for trigger...";
                    app.StatusText.Text = cycleCountString;
                    app.OffsetDurationField.Enable = 'off';
                    app.RecordingModeButtonGroup.Enable = 'off';
                    app.UseclockchannelCheckBox.Enable = 'off';
                    if app.SingleCycleButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    elseif app.MultipleCyclesButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    end
                    app.ArmButton.Enable = 'off';
                    app.CaptureButton.Text = 'Cancel capture';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.SubjectNameEditField.Enable = 'off';
                    app.VariableNameEditField.Enable = 'off';
                    app.ExporttomatButton.Enable = 'off';
                    app.ExporttocsvButton.Enable = 'off';
                    app.ExportcsvStatus.Text = 'Not available';
                    app.ExportmatStatus.Text = 'Not available';

                case 'Capture-Multiple.CapturingData'
                    cycleCountString = "Current cycle #: " + string(app.CycleCountCurrent) + ". Trigger detected. Capturing ...";
                    app.StatusText.Text = cycleCountString;
                    app.OffsetDurationField.Enable = 'off';
                    app.RecordingModeButtonGroup.Enable = 'off';
                    app.UseclockchannelCheckBox.Enable = 'off';
                    if app.SingleCycleButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    elseif app.MultipleCyclesButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    end
                    app.ArmButton.Enable = 'off';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.CapturetimerLabelValue.Text = num2str(app.TimerValue);
                    app.SubjectNameEditField.Enable = 'off';
                    app.VariableNameEditField.Enable = 'off';
                    app.ExporttomatButton.Enable = 'off';
                    app.ExporttocsvButton.Enable = 'off';
                    app.ExportcsvStatus.Text = 'Not available';
                    app.ExportmatStatus.Text = 'Not available';
                    
                case 'Capture-Multiple.CycleComplete'
                    cycleCountString = "Current cycle #: " + string(app.CycleCountCurrent) + " completed. Saving data ...";
                    app.StatusText.Text = cycleCountString;
                    app.OffsetDurationField.Enable = 'off';
                    app.RecordingModeButtonGroup.Enable = 'off';
                    app.UseclockchannelCheckBox.Enable = 'off';
                    if app.SingleCycleButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    elseif app.MultipleCyclesButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    end
                    app.ArmButton.Enable = 'off';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.CapturetimerLabelValue.Text = num2str(app.TimerValue);
                    app.SubjectNameEditField.Enable = 'off';
                    app.VariableNameEditField.Enable = 'off';
                    app.ExporttomatButton.Enable = 'off';
                    app.ExporttocsvButton.Enable = 'off';
                    app.ExportcsvStatus.Text = 'Not available';
                    app.ExportmatStatus.Text = 'Not available';

                case 'Capture-Multiple.Resting'
                    cycleCountString = "Saving data completed. Resting until cycle " + string(app.CycleCountCurrent);
                    app.StatusText.Text = cycleCountString;
                    app.OffsetDurationField.Enable = 'off';
                    app.RecordingModeButtonGroup.Enable = 'off';
                    app.UseclockchannelCheckBox.Enable = 'off';
                    if app.SingleCycleButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    elseif app.MultipleCyclesButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    end
                    app.ArmButton.Enable = 'off';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.CapturetimerLabelValue.Text = num2str(app.TimerValue);
                    app.SubjectNameEditField.Enable = 'off';
                    app.VariableNameEditField.Enable = 'off';
                    app.ExporttomatButton.Enable = 'off';
                    app.ExporttocsvButton.Enable = 'off';
                    app.ExportcsvStatus.Text = 'Not available';
                    app.ExportmatStatus.Text = 'Not available';

                case 'Capture-Multiple.CaptureComplete'
                    app.StatusText.Text = 'Session completed!';
                    app.OffsetDurationField.Enable = 'off';
                    app.RecordingModeButtonGroup.Enable = "off";
                    app.UseclockchannelCheckBox.Enable = 'off';
                    if app.SingleCycleButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    elseif app.MultipleCyclesButton.Value == 1
                        app.CaptureLengthField.Enable = 'off';
                        app.cyclesEditField.Enable = 'off';
                        app.RecordingperiodsEditField.Enable = 'off';
                        app.RestperiodsEditField.Enable = 'off';
                    end

                    app.ArmButton.Enable = 'on';
                    app.PlotButton.Enable = 'on';
                    app.PlotChosenChannel.Enable = 'on';
                    app.ChannelplotselectDropDown.Enable = 'on';
                    app.ApplycalibrationButton.Enable = 'on';
                    app.CapturetimerLabelValue.Text = '0';
                    app.SubjectNameEditField.Enable = 'on';
                    app.VariableNameEditField.Enable = 'on';
                    app.RecordingModeButtonGroup.Enable = "on";
                    app.UseclockchannelCheckBox.Enable = 'on';
                    app.ExporttomatButton.Enable = 'on';
                    app.ExporttocsvButton.Enable = 'on';
                    app.ExportcsvStatus.Text = 'Ready to export';
                    app.ExportmatStatus.Text = 'Ready to export';
                
                case 'DataPlot.NotAvailable'
                    

                case 'DataPlot.Available'
                    
                    
            end
            
        end
        
        function setDeviceParameters(app)
            delete(app.DAQ);
            app.DAQ = daq('ni');
            app.DAQ.Rate = app.Rate.Value;
            app.InputChannelNumbers = str2num(app.InputChannelsList.Value); %#ok<ST2NM> 

            % % Check to make sure there is only 1 clock channel (for now)
            % if length(app.ClockChannelNumber) > 1
            %     errordlg('Please make sure there is only one clock channel', 'Setting Error')
            % end
            
            % Storing the length of the output channel list
            % app.OutputChannelsListLength = length(app.OutputChannelNumbers);

            app.ChannelCount = length(app.InputChannelNumbers);
            app.PreOffsetData = zeros(1, app.ChannelCount);
            unitName = "Voltage";

            app.ChannelIndexLU = zeros(36, 1);
            indexLU = 1;
            for indexChannel = 1:length(app.InputChannelNumbers)
                channelNumber = app.InputChannelNumbers(indexChannel);
                if channelNumber < 16
                    channel = addinput(app.DAQ, app.DeviceName.Value, "ai" + num2str(channelNumber), unitName);
                    channel.TerminalConfig = 'SingleEnded';
                    app.ChannelIndexLU(channelNumber+1) = indexLU; 
                    indexLU = indexLU + 1;
                else
                    channel = addinput(app.DAQ, app.DeviceName.Value, "ai" + num2str(channelNumber), unitName);
                    app.ChannelIndexLU(channelNumber+1) = indexLU; 
                    indexLU = indexLU + 1;
                    channelRef = addinput(app.DAQ, app.DeviceName.Value, "ai" + num2str(channelNumber + 8), unitName);
                    app.ChannelIndexLU(channelNumber+1 + 8) = indexLU; 
                    indexLU = indexLU + 1;
                end
            end

            % % Add Output Channels
            % for indexChannel = 1:length(app.OutputChannelNumbers)
            %     channelNumber = app.OutputChannelNumbers(indexChannel);
            %     channel = addoutput(app.DAQ, app.DeviceName.Value, "ctr" + num2str(channelNumber), "PulseGeneration");
            %     channel.Frequency = 200;
            %     channel.DutyCycle = 0.1;
            % end

            % % Add Clock Channels
            % channelNumber = app.ClockChannelNumber(1);
            % disp(channelNumber)
            % channel = addinput(app.DAQ, app.DeviceName.Value, "ai" + num2str(channelNumber), unitName);
            % channel.TerminalConfig = 'SingleEnded';
            % app.ChannelIndexLU(channelNumber+1) = indexLU;
            % indexLU = indexLU + 1;

            % Configure DAQ ScansAvailableFcn callback function
            app.DAQ.ScansAvailableFcn = @(src,event) scansAvailable_Callback(app, src, event);
            app.CallbackTimeSpan = double(app.DAQ.ScansAvailableFcnCount)/app.DAQ.Rate;

            % Calculate required FIFO data buffer size
            if app.SingleCycleButton.Value == 1
                app.CaptureDuration = app.CaptureLengthField.Value;
            elseif app.MultipleCyclesButton.Value == 1
                app.CaptureDuration = app.RecordingperiodsEditField.Value;
            end

            app.BufferRowSize = indexLU - 1;
            app.BufferSize = calculateBufferSize(app, app.CallbackTimeSpan, ...
                                                      app.LivePlotLengthField.Value, ...
                                                      app.OffsetDurationField.Value, ...
                                                      app.CaptureDuration, ...
                                                      app.DAQ.Rate);
            app.BufferSizeValue.Text = num2str(app.BufferSize);

            app.CounterChannelStatus = 0;
        end
        
        function updateSubplotPanel(app)
            numberOfColumns = app.PlotColumnCount.Value;
            channelList = str2num(app.InputChannelsList.Value); %#ok<ST2NM> 
            if mod(app.ChannelCount, numberOfColumns) > 0
                addRow = 1;
            else
                addRow = 0;
            end
            numberOfRows = floor(app.ChannelCount/numberOfColumns) + addRow;

            for indexPlot = 1:app.ChannelCount
                app.LiveAxesList{indexPlot} = subplot(numberOfRows, numberOfColumns, indexPlot, 'Parent', app.SubplotAxesPanel);
                app.LivePlotsLineSubplot{indexPlot} = plot(app.LiveAxesList{indexPlot}, NaN, NaN);

                % Setting subplot title
                channelNumber = channelList(indexPlot);
                channelName = "Channel " + num2str(channelNumber);
                title(app.LiveAxesList{indexPlot}, channelName)

                % Setting capture plot line - After capturing
                app.CapturePlotLine{indexPlot} = plot(app.CaptureDataAxes, NaN, NaN, 'DisplayName', channelName);
                hold(app.CaptureDataAxes, 'on');

                % Setting combined live plot line
                app.LivePlotsLineCombined{indexPlot} = plot(app.LiveDataAxes, NaN, NaN, 'DisplayName', channelName);
                hold(app.LiveDataAxes, 'on');
            end
            setYLimAxes(app)
            legend(app.CaptureDataAxes, 'Location', 'northeastoutside')
            
        end

        function updateCycleCapturePanel(app)
           channelList = str2num(app.InputChannelsList.Value);

            if app.SingleCycleButton.Value == 1
                app.PostCaptureAxesList = subplot(1, 1, 1, 'Parent', app.PostCaptureAxesPanel);
                for indexChannel = 1:app.ChannelCount
                    channelNumber = channelList(indexChannel);
                    channelName = "Channel " + num2str(channelNumber);
                    app.CyclePlotLine{indexChannel} = plot(app.PostCaptureAxesList, NaN, NaN, 'DisplayName', channelName);
                    hold(app.CaptureDataAxes, 'on');
                end
            elseif app.MultipleCyclesButton.Value == 1
                app.CycleCountTotal = app.cyclesEditField.Value;
                if app.CycleCountTotal < app.PlotColumnCount.Value
                    numberOfColumns = app.CycleCountTotal;
                else
                    numberOfColumns = app.PlotColumnCount.Value;
                end
                    
                if mod(app.CycleCountTotal, numberOfColumns) > 0
                    addRow = 1;
                else
                    addRow = 0;
                end
    
                numberOfRows = floor(app.CycleCountTotal/numberOfColumns) + addRow;
                for indexPlot = 1:app.CycleCountTotal
                    app.PostCaptureAxesList{indexPlot} = subplot(numberOfRows, numberOfColumns, indexPlot, 'Parent', app.PostCaptureAxesPanel);
                    for indexChannel = 1:app.ChannelCount
                        channelNumber = channelList(indexChannel);
                        channelName = "Channel " + num2str(channelNumber);
                        app.CyclePlotLine{indexPlot, indexChannel} = plot(app.PostCaptureAxesList{indexPlot}, NaN, NaN, 'DisplayName', channelName);
                        hold(app.PostCaptureAxesList{indexPlot}, 'on');
                    end
                end

                app.CyclePlotRowCount = numberOfRows;
                app.CyclePlotColumnCount = numberOfColumns;
            end
        end
        
        function plotParams = generatePlotParams(app)
            plotParams = struct;
            plotParams.ylabel = 'Voltage (V)';
            plotParams.ylim = [-10 10];
        end

        function updateDataPlotPanel(app, plotParams)
            numberOfColumns = app.PlotColumnCount.Value;
            channelList = str2num(app.InputChannelsList.Value); %#ok<ST2NM> 
            if mod(app.ChannelCount, numberOfColumns) > 0
                addRow = 1;
            else
                addRow = 0;
            end
            numberOfRows = floor(app.ChannelCount/numberOfColumns) + addRow;
            for indexPlot = 1:app.ChannelCount
                app.DataAxesList{indexPlot} = subplot(numberOfRows, numberOfColumns, indexPlot, 'Parent', app.DataPlotPanel);
                app.DataPlotsLine{indexPlot} = plot(app.DataAxesList{indexPlot}, NaN, NaN);
                xlabel(app.DataAxesList{indexPlot}, "Time (s)");
                
                % Setting subplot title
                channelNumber = channelList(indexPlot);
                channelName = "Channel " + num2str(channelNumber) + ...
                              " - " + num2str(app.PreOffsetData(indexPlot), '%.4f') + " V";
                if channelNumber > 15
                    ylabel(app.DataAxesList{indexPlot}, plotParams.ylabel);
                    ylim(app.DataAxesList{indexPlot}, plotParams.ylim);
                else
                    ylabel(app.DataAxesList{indexPlot}, 'Voltage (V)');
                    ylim(app.DataAxesList{indexPlot}, [-10 10]);
                end
                title(app.DataAxesList{indexPlot}, channelName);
            end
        end


        function updateCapturePlotPanel(app)
            cla(app.CaptureDataAxes, 'reset')
            channelList = str2num(app.InputChannelsList.Value); %#ok<ST2NM> 
            
            for indexPlot = 1:app.ChannelCount
                channelNumber = channelList(indexPlot);
                channelName = "Channel " + num2str(channelNumber);
                app.CapturePlotLine{indexPlot} = plot(app.CaptureDataAxes, NaN, NaN, 'DisplayName', channelName);
                hold(app.CaptureDataAxes, 'on');
            end
            legend(app.CaptureDataAxes, 'Location', 'northeastoutside')
        end


        function scansAvailable_Callback(app, src, ~)
            if ~isvalid(app)
                return
            end
            

            % Continuous acquisition data and timestamps are stored in FIFO data buffers
            % Calculate required buffer size -- this should be large enough to accomodate the
            % the data required for the live view time window and the data for the requested
            % capture duration.
            app.ViewTimeWindow = app.LivePlotLengthField.Value;
            app.OffsetDuration = app.OffsetDurationField.Value;
            if app.SingleCycleButton.Value == 1
                app.CaptureDuration = app.CaptureLengthField.Value;
            elseif app.MultipleCyclesButton.Value == 1
                app.CaptureDuration = app.RecordingperiodsEditField.Value;
            end
            
            [data, timestamps] = read(src, src.ScansAvailableFcnCount, 'OutputFormat','Matrix');

            % Offset straingage
            SGRawIndexList = app.ChannelIndexLU(app.SGChannelList + 1);
            SGRefIndexList = app.ChannelIndexLU(app.SGChannelList + 1 + 8);
            data(:, SGRawIndexList) = data(:, SGRawIndexList) - data(:, SGRefIndexList);

            % Store continuous acquisition data in FIFO data buffers
            % app.TimestampsFIFOBuffer = storeDataInFIFO(app, app.TimestampsFIFOBuffer, app.BufferSize, timestamps);
            % app.DataFIFOBuffer = storeDataInFIFO(app, app.DataFIFOBuffer, app.BufferSize, data);
            
            % updated version of storeDataInFIFO
            % it should save data directly to DataFIFOBuffer and
            % TimestampFIFOBuffer
            storeDataRawInFIFO(app, timestamps, data);  

            app.FIFOCountValue.Text = num2str(size(app.TimestampsFIFOBuffer, 1));
    
            plotDataBool = true;
            if app.LiveplotduringcapturingCheckBox.Value == 0
                plotDataBool = false;
            end

            % Update live plot data
            if plotDataBool==1 && app.FIFOIndex > 1
                samplesToPlot = min([round(app.ViewTimeWindow * src.Rate), app.FIFOIndex-1]);
                firstPoint = (app.FIFOIndex-1) - samplesToPlot + 1;
                lastPoint = firstPoint + samplesToPlot - 2;
                if samplesToPlot > 20 && app.TimestampsFIFOBuffer(lastPoint, 1) > 0
                    
                    dataPlot = app.DataFIFOBuffer(firstPoint:lastPoint, :);
                    if app.BiasTick.Value && app.CurrentState == "Acquisition.ReadyForCapture"
                        dataIndex = app.ChannelIndexLU(app.InputChannelNumbers+1);
                        dataPlot(:, dataIndex) = dataPlot(:, dataIndex) - app.PreOffsetDataRaw(:, dataIndex);
                    end

                    if app.CalibrationTick.Value == 1
                        dataPlot(:, SGRawIndexList) = (app.CalibratedMatrix*dataPlot(:, SGRawIndexList)')';
                    end


                    for indexChannel = 1:app.ChannelCount
                        channelNumber = app.InputChannelNumbers(indexChannel);
                        dataIndex = app.ChannelIndexLU(channelNumber+1);

                        if samplesToPlot > 1
                            xlim(app.LiveAxesList{indexChannel}, [app.TimestampsFIFOBuffer(firstPoint, 1), app.TimestampsFIFOBuffer(lastPoint, 1)])
                            xlim(app.LiveDataAxes, [app.TimestampsFIFOBuffer(firstPoint, 1), app.TimestampsFIFOBuffer(lastPoint, 1)])
                        end

                        switch  app.TabGroup.SelectedTab.Title
                            case "Subplot View"
                                set(app.LivePlotsLineSubplot{indexChannel}, 'XData', app.TimestampsFIFOBuffer(firstPoint:lastPoint, 1), ...
                                    'YData', dataPlot(:, dataIndex));

                                channelName = "Channel " + num2str(channelNumber);
                                title(app.LiveAxesList{indexChannel}, channelName)
                            case "Combined Plot View"
                                set(app.LivePlotsLineCombined{indexChannel}, 'XData', app.TimestampsFIFOBuffer(firstPoint:lastPoint, 1), ...
                                    'YData', dataPlot(:, dataIndex));
                            case "Vector View"
                                break
                        end
                    end

                end
            end
         
            % If capture is requested, analyze latest acquired data until a trigger
            % condition is met. After enough data is acquired for a complete capture,
            % as specified by the capture duration, extract the capture data from the
            % data buffer and save it to a base workspace variable.
            
            % For trigger detection, store previous and current ScansAvailable callback data and timestamps
            if isempty(app.Timestamps)
                app.Data = data;
                app.Timestamps = timestamps;
            else
                app.Data = [app.Data(end, :); data];
                app.Timestamps = [app.Timestamps(end); timestamps];
            end

            % App state control logic 
            switch app.CurrentState
                case 'Acquisition.Buffering'
                   % Buffering pre-trigger data
                    if isEnoughDataBuffered(app)
                        app.CurrentState = 'Acquisition.CollectingOffset';
                        setAppViewState(app, app.CurrentState)

                        app.OffsetStartMoment = app.Timestamps(end);
                        app.PreOffsetDataRaw = app.Data;
                    end
                
                case 'Acquisition.CollectingOffset' % Offset for live-plotting only
                    if isEnoughDataOffset(app)
                        app.PreOffsetDataRaw = mean(app.PreOffsetDataRaw, 1);
                        app.CurrentState = 'Acquisition.ReadyForCapture';
                        setAppViewState(app, app.CurrentState)
                    end
                case 'Acquisition.ReadyForCapture' % Capture for plotting -> not recording
                    % Ready for capture
                    if app.CaptureButton.Value
                        if app.UseclockchannelCheckBox.Value == 1
                            app.CurrentState = 'Acquisition.WaitingForClockTrigger';
                            setAppViewState(app, app.CurrentState);
                        else
                            if app.SingleCycleButton.Value == 1
                                app.CurrentState = 'Capture-Single.CollectingOffset';
                                app.CycleCountCurrent = 1; % place holder purpose
                            elseif app.MultipleCyclesButton.Value == 1
                                app.CurrentState = 'Capture-Multiple.CollectingOffset';
                                app.CycleCountCurrent = 1;
                                app.CycleCountTotal = app.cyclesEditField.Value;
                                app.RestPeriodLength = app.RestperiodsEditField.Value;
                                app.RecordingPeriodLength = app.RecordingperiodsEditField.Value;
                            end
                            setAppViewState(app, app.CurrentState);
                            app.OffsetStartMoment = app.Timestamps(end);
                            app.PreOffsetDataRaw = app.Data;
                        end
                    end
                case 'Acquisition.WaitingForClockTrigger'
                    % Waiting for the clock trigger signal after clicking
                    % capture
                    if app.CounterChannelStatus == 0
                        flipCounterChannel(app, 1);
                    else
                        if app.DAQ.WaitingForDigitalTrigger == 0 && app.DAQ.Running == 1
                            if app.SingleCycleButton.Value == 1
                                app.CurrentState = 'Capture-Single.CollectingOffset';
                                app.CycleCountCurrent = 1; % place holder purpose
                            elseif app.MultipleCyclesButton.Value == 1
                                app.CurrentState = 'Capture-Multiple.CollectingOffset';
                                app.CycleCountCurrent = 1;
                                app.CycleCountTotal = app.cyclesEditField.Value;
                                app.RestPeriodLength = app.RestperiodsEditField.Value;
                                app.RecordingPeriodLength = app.RecordingperiodsEditField.Value;
                            end
                            setAppViewState(app, app.CurrentState);
                            app.OffsetStartMoment = app.Timestamps(end);
                            app.PreOffsetDataRaw = app.Data;
                        end
                    end

                %% Cases for single-cycle capturing mode
                case 'Capture-Single.CollectingOffset'
                    if isEnoughDataOffset(app)
                        app.PreOffsetDataRaw = mean(app.PreOffsetDataRaw, 1);
                        app.CurrentState = 'Capture-Single.LookingForTrigger';
                        setAppViewState(app, app.CurrentState)
                    end
                case 'Capture-Single.LookingForTrigger'
                    % Looking for trigger event in the latest data
                    detectTrigger(app)
                    if app.TrigActive
                        tic;
                        app.CurrentState = 'Capture-Single.CapturingData';
                        setAppViewState(app, app.CurrentState)
                    end
                case 'Capture-Single.CapturingData'
                    % Capturing data
                    % Not enough acquired data to cover capture timespan during this ScansAvailable callback execution
                    app.CapturetimerLabelValue.Text = num2str(toc);
                    if isEnoughDataCaptured(app)     
                        app.CurrentState = 'Capture-Single.CaptureComplete';
                        setAppViewState(app, app.CurrentState)
                    end
                case 'Capture-Single.CaptureComplete'
                    % Acquired enough data to complete capture of specified duration
                    completeCapture(app);
                    flipCounterChannel(app, 0);
                    app.CurrentState = 'Acquisition.ReadyForCapture';
                    setAppViewState(app, app.CurrentState)             
                    
                %% Case for multi-cycle capturing mode
                case 'Capture-Multiple.CollectingOffset'
                    if isEnoughDataOffset(app)
                        app.PreOffsetDataRaw = mean(app.PreOffsetDataRaw, 1);
                        app.RestPeriodFirst = app.RestperiodfirstCheckBox.Value;
                        app.CurrentState = 'Capture-Multiple.LookingForTrigger';
                        setAppViewState(app, app.CurrentState)
                    end
                case 'Capture-Multiple.LookingForTrigger'
                    % Looking for trigger event in the latest data
                    detectTrigger(app)
                    if app.TrigActive
                        app.RecordingPeriodStartTime = datetime('now');
                        if app.RestPeriodFirst == 0
                            app.CurrentState = 'Capture-Multiple.CapturingData';
                            setAppViewState(app, app.CurrentState)
                            
                        elseif app.RestPeriodFirst == 1
                            app.RestPeriodFirst = 0;
                            app.CurrentState = 'Capture-Multiple.Resting';
                            setAppViewState(app, app.CurrentState)
                            app.RestPeriodStartTime = datetime('now'); % get the current time
                        end
                    end
                case 'Capture-Multiple.CapturingData'
                    % Capturing data
                    % Not enough acquired data to cover capture timespan during this ScansAvailable callback execution
                    app.CapturetimerLabelValue.Text = num2str(seconds(datetime('now') - app.RecordingPeriodStartTime));
                    if isEnoughDataCaptured(app)
                        % flipCounterChannel(app, 0);
                        app.CurrentState = 'Capture-Multiple.CycleComplete';
                        setAppViewState(app, app.CurrentState);  
                    end
                case 'Capture-Multiple.CycleComplete'
                    % Acquired enough data to complete capture of specified duration
                    completeCycle(app)
                    app.CurrentState = 'Capture-Multiple.Resting';
                    setAppViewState(app, app.CurrentState)

                case 'Capture-Multiple.Resting'
                    % Post-cycle period, check for number of completed
                    % cycles as well as waiting time
                     if isCycleOver(app) % check if enough recording cycles have been completed
                        
                        app.CurrentState = 'Capture-Multiple.CaptureComplete';
                        setAppViewState(app, app.CurrentState)
                     elseif isRestingOver(app)
                        app.CurrentState = 'Capture-Multiple.LookingForTrigger';
                        setAppViewState(app, app.CurrentState)
                     end
                case 'Capture-Multiple.CaptureComplete'
                    if app.UseclockchannelCheckBox.Value == 1
                        flipCounterChannel(app, 0);
                    end
                    % Acquired enough data to complete capture of specified duration
                    app.CurrentState = 'Acquisition.ReadyForCapture';
                    setAppViewState(app, app.CurrentState)
            end
        end

        function data = storeDataInFIFO(~, data, buffersize, datablock)
        %storeDataInFIFO Store continuous acquisition data in a FIFO data buffer
        %  Storing data in a finite-size FIFO buffer is used to plot the latest "N" seconds of acquired data for
        %  a smooth live plot update and without continuously increasing memory use.
        %  The most recently acquired data (datablock) is added to the buffer and if the amount of data in the
        %  buffer exceeds the specified buffer size (buffersize) the oldest data is discarded to cap the size of
        %  the data in the buffer to buffersize.
        %  input data is the existing data buffer (column vector Nx1).
        %  buffersize is the desired buffer size (maximum number of rows in data buffer) and can be changed.
        %  datablock is a new data block to be added to the buffer (column vector Kx1).
        %  output data is the updated data buffer (column vector Mx1).
        
            % If the data size is greater than the buffer size, keep only the
            % the latest "buffer size" worth of data
            % This can occur if the buffer size is changed to a lower value during acquisition
            if size(data,1) > buffersize
                data = data(end-buffersize+1:end,:);
            end
            
            if size(datablock, 1) < buffersize
                % Data block size (number of rows) is smaller than the buffer size
                if size(data,1) == buffersize
                    % Current data size is already equal to buffer size.
                    % Discard older data and append new data block,
                    % and keep data size equal to buffer size.
                    shiftPosition = size(datablock, 1);
                    data = circshift(data, -shiftPosition);
                    data(end-shiftPosition+1:end, :) = datablock;
                elseif (size(data, 1) < buffersize) && (size(data,1) + size(datablock, 1) > buffersize)
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size greater than the buffer size.
                    data = [data; datablock];
                    shiftPosition = size(data,1 ) - buffersize;
                    data = circshift(data,- shiftPosition);
                    data(buffersize+1:end, :) = [];
                else
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size smaller than or equal to the buffer size.
                    % (if (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) <= buffersize))
                    data = [data; datablock];
                end
            else
                % Data block size (number of rows) is larger than or equal to buffer size
                data = datablock(end-buffersize+1:end,:);
            end
            
        end

        function storeDataRawInFIFO(app, dataTime, dataForce)
            % append data directly to TimestampFIFOBuffer and
            % DataFIFOBuffer and increase the FIFOIndex value
            numRows = size(dataTime, 1);
            currentIndex = app.FIFOIndex;
            arraySize = size(app.TimestampsFIFOBuffer, 1);
            durationSize = ceil(arraySize/2); % get the original captureDuration size 
            currentMaxIndex = currentIndex  + numRows - 1;

            if currentMaxIndex < arraySize
                % Append the data block into data matrices
                app.TimestampsFIFOBuffer(currentIndex:currentMaxIndex, :) = dataTime; % + app.TimestampOffset;
                app.DataFIFOBuffer(currentIndex:currentMaxIndex, :) = dataForce;
                app.FIFOIndex = currentMaxIndex + 1;
            elseif currentMaxIndex >= arraySize
                % if the max index is bigger than the array size (x2), circshift
                shiftAmount = currentMaxIndex - durationSize;
                app.FIFOIndex = arraySize - shiftAmount + 1;
                shiftedMaxIndex = app.FIFOIndex + numRows - 1;

                % shift the data
                app.TimestampsFIFOBuffer = circshift(app.TimestampsFIFOBuffer, -shiftAmount);
                app.DataFIFOBuffer = circshift(app.DataFIFOBuffer, -shiftAmount);

                % add the new data in
                app.TimestampsFIFOBuffer(app.FIFOIndex:shiftedMaxIndex, :) = dataTime; % + app.TimestampOffset;
                app.DataFIFOBuffer(app.FIFOIndex:shiftedMaxIndex, :) = dataForce;

                % delete the leftover data
                app.TimestampsFIFOBuffer(shiftedMaxIndex+1:end, :) = 0;
                app.DataFIFOBuffer(shiftedMaxIndex+1:end, :) = 0;

                app.FIFOIndex = shiftedMaxIndex + 1;
            end

            
        end

        function  detectTrigger(app)
            % detectTrigger Detects trigger condition and updates relevant app properties
            % Updates TrigActive, TrigMoment, and CaptureStartMoment app properties
            
            trigConfig.Channel = find(app.InputChannelNumbers==app.TriggerChannel.Value);
            trigConfig.Level = app.TriggerVoltage.Value;
            trigConfig.Condition = app.TriggerCondition.Value;
            [app.TrigActive, app.TrigMoment] = ...
                trigDetect(app, app.Timestamps, app.Data, trigConfig);
            app.CaptureStartMoment = app.TrigMoment + app.TriggerDelay;
            
        end
        
        function [trigDetected, trigMoment] = trigDetect(app, timestamps, data, trigConfig)
           %trigDetect Detect if trigger event condition is met in acquired data
            %   [trigDetected, trigMoment] = trigDetect(app, data, trigConfig)
            %   Returns a detection flag (trigDetected) and the corresponding data point index
            %   (trigMoment) of the first data point which meets the trigger condition
            %   based on signal level and condition specified by the trigger parameters
            %   structure (trigConfig).
            %   The input data (data) is an M x N matrix corresponding to M acquired
            %   data scans from N channels.
            %   trigConfig.Channel = index of trigger channel in DAQ channels
            %   trigConfig.Level   = signal trigger level
            %   trigConfig.Condition = trigger condition ('Rising' or 'Falling')
            
            switch trigConfig.Condition
                case 'Rising'
                    % Logical array condition for signal trigger level
                    trigConditionMet = data(:, trigConfig.Channel) > trigConfig.Level;
                    trigDetected = any(trigConditionMet) & ~all(trigConditionMet);
                case 'Falling'
                    % Logical array condition for signal trigger level
                    trigConditionMet = data(:, trigConfig.Channel) < trigConfig.Level;
                    trigDetected = any(trigConditionMet) & ~all(trigConditionMet);
                case 'Leveling'
                    trigConditionMet = data(:, trigConfig.Channel) > trigConfig.Level;
                    trigDetected = all(trigConditionMet);
            end
            
            trigMoment = [];
            if trigDetected
                %% Find time moment when trigger condition has been met
                trigMomentIndex = 1 + find((diff(trigConditionMet))==1, 1, 'first');
                trigMoment = timestamps(trigMomentIndex, 1);

                %% shift the FIFO to the beginning to clear up space
                arraySize = size(app.TimestampsFIFOBuffer, 1);
                durationSize = ceil(arraySize/2); % get the original captureDuration size 

                % Find index of first sample in data buffer to be captured
                firstSampleIndex = find(app.TimestampsFIFOBuffer(:, 1) >= trigMoment, 1, 'first');
                currentMaxIndex = firstSampleIndex + durationSize;
                
                if currentMaxIndex > arraySize
                    disp('Shifting the buffer');
                    % If from the triggered moment until complete, the data
                    % size becomes bigger than the current array size, shift
                    shiftAmount = ceil(1.05*(currentMaxIndex - arraySize)); % shift doubled the amount needed
                    app.FIFOIndex = app.FIFOIndex  - shiftAmount;

                    % shift the data
                    app.TimestampsFIFOBuffer = circshift(app.TimestampsFIFOBuffer, -shiftAmount, 1);
                    app.DataFIFOBuffer = circshift(app.DataFIFOBuffer, -shiftAmount, 1);

                    % delete the leftover data
                    app.TimestampsFIFOBuffer(app.FIFOIndex+1:end, :) = -1;
                    app.DataFIFOBuffer(app.FIFOIndex+1:end, :) = 0;
                end
            
            end
            
        end

        function flipCounterChannel(app, addChannelBool)
            if addChannelBool ~= app.CounterChannelStatus
                stop(app.DAQ);
                flush(app.DAQ);
                app.DatetimeStopMoment = datetime('now');
                if addChannelBool == 1
                    app.AddedChannelIDs = []; % Initialize or reset the list
                    app.CounterChannelStatus = 1;
          
                    [channel_0, channelIndex_0] = addoutput(app.DAQ, app.DeviceName.Value, "ctr0", "PulseGeneration");
                    channel_0.Frequency = app.Ao_0FreqSpinner.Value;
                    channel_0.DutyCycle = app.Ao_0DCSpinner.Value/100;
    
                    [channel_1, channelIndex_1] = addoutput(app.DAQ, app.DeviceName.Value, "ctr1", "PulseGeneration");
                    channel_1.Frequency = app.Ao_1FreqSpinner.Value;
                    channel_1.DutyCycle = app.Ao_1DCSpinner.Value/100;
    
                    [~, app.TriggerIndex] = addtrigger(app.DAQ, 'Digital', 'StartTrigger', 'External', 'Dev1/PFI0');
                    
                    % Store the channel ID
                    app.AddedChannelIDs = [app.AddedChannelIDs; channelIndex_0; channelIndex_1];
                    prepareBuffer(app);
                    app.DAQ.DigitalTriggerTimeout = Inf;
                    
                elseif addChannelBool == 0 && ~isempty(app.AddedChannelIDs)
                    
                    app.CounterChannelStatus = 0;
                    % Remove Output Channels
                    for indexChannel = length(app.AddedChannelIDs):-1:1
                        channelIdToRemove = app.AddedChannelIDs(indexChannel);
                        removechannel(app.DAQ, channelIdToRemove);
                    end
                    removetrigger(app.DAQ, app.TriggerIndex);
                    app.AddedChannelIDs = []; % Clear the list after removal
                end
                start(app.DAQ, 'continuous'); % Turn back to continuous mode without running the clock
            end
        end

        function completeCycle(app)
            app.RestPeriodStartTime = datetime('now'); % get the current time
            completeCapture(app); % Save the data to workspace
            exportTocsv(app); % Then export the data as the check boxed are checked
            app.CycleCountCurrent = app.CycleCountCurrent + 1; % update the current cycle count
            app.CycleNumberField.Value = app.CycleCountCurrent; % update the cycle count label
            prepareBuffer(app); % Then erase the buffer and wait until the rest is over
        end

        function completeCapture(app)
        %completeCapture Saves captured data to workspace variable and plots it
            
            % Find index of first sample in data buffer to be captured
            % if app.CycleCountCurrent == 1
            %     firstSampleIndex = find(app.TimestampsFIFOBuffer == 0.0001, 1, 'first');
            % else
            firstSampleIndex = find(app.TimestampsFIFOBuffer >= app.CaptureStartMoment, 1, 'first');
            % end

            % Find index of last sample in data buffer that complete the capture
            lastSampleIndex = firstSampleIndex + round(app.CaptureDuration * app.DAQ.Rate);
            if isempty(firstSampleIndex) || isempty(lastSampleIndex) || lastSampleIndex > size(app.TimestampsFIFOBuffer, 1)
                % Something went wrong
                % Abort capture
                app.StatusText.Text = 'Capture error';
                app.CaptureButton.Value = 0;
                uialert(app.UIFigure, 'Could not complete capture.', 'Capture error');
                return
            end
            
            % Extract capture data and shift timestamps so that 0 corresponds to the trigger moment
            app.CaptureDataRaw = app.DataFIFOBuffer(firstSampleIndex:lastSampleIndex, :);
            app.CaptureTimestamps = app.TimestampsFIFOBuffer(firstSampleIndex:lastSampleIndex, :) - app.CaptureStartMoment;
         
            app.CaptureData = zeros(size(app.CaptureDataRaw, 1), app.ChannelCount+1);
            
            % Add raw timestamp data to the very last column of the data
            % table, just for reference
            app.CaptureData(:, app.ChannelCount+1) = app.TimestampsFIFOBuffer(firstSampleIndex:lastSampleIndex, :);

            % Remove ref channels from raw captured data and offset data
            for indexChannel = 1:app.ChannelCount
                channelNumber = app.InputChannelNumbers(indexChannel);
                dataIndex = app.ChannelIndexLU(channelNumber+1);
                app.CaptureData(:, indexChannel) = app.CaptureDataRaw(:, dataIndex);
                if isempty(app.PreOffsetDataRaw)
                    app.PreOffsetData(1, indexChannel) = app.CaptureDataRaw(1, dataIndex);
                else
                    app.PreOffsetData(1, indexChannel) = app.PreOffsetDataRaw(:, dataIndex);
                end
                
                % Get the data from the clock channel 
                if channelNumber == app.ClockInputChannelSpinner.Value
                    app.ClockData = diff(app.CaptureDataRaw(:, dataIndex)) > 1;
                    app.TimestampVideoList = app.CaptureData(app.ClockData, app.ChannelCount+1);
                    assignin('base', 'time_data', app.TimestampVideoList);
                end

            end
            
            ignoredChannelsList = str2num(app.IgnoreChannelsPlot.Value);
            % Update captured data plot (one line for each acquisition channel)
            for indexChannel = 1:app.ChannelCount
                channelNumber = app.InputChannelNumbers(indexChannel);
                if app.UseclockchannelCheckBox.Value == 1
                    if ~ismember(channelNumber, ignoredChannelsList)
                        set(app.CapturePlotLine{indexChannel}, 'XData', app.CaptureTimestamps, ...
                                                               'YData', app.CaptureData(:, indexChannel));
                    end
                else
                    set(app.CapturePlotLine{indexChannel}, 'XData', app.CaptureTimestamps, ...
                                                           'YData', app.CaptureData(:, indexChannel));
                end
                
            end

            app.CaptureDataAxes.XLim = [min(app.CaptureTimestamps), max(app.CaptureTimestamps)];
            
            % Update captured data plot, same as above, but for multiple
            % cycles
            if app.MultipleCyclesButton.Value == 1
                indexPlot = app.CycleCountCurrent;
                app.PostCaptureAxesList{indexPlot} = subplot(app.CyclePlotRowCount, app.CyclePlotColumnCount, indexPlot, 'Parent', app.PostCaptureAxesPanel);
                for indexChannel = 1:app.ChannelCount
                    set(app.CyclePlotLine{indexPlot, indexChannel}, 'XData', app.CaptureTimestamps, ...
                                                           'YData', app.CaptureData(:, indexChannel));
                end
                app.PostCaptureAxesList{indexPlot}.XLim = [min(app.CaptureTimestamps), max(app.CaptureTimestamps)];
            end
            % Save capture data
            varCaptureName = app.VariableNameEditField.Value;
            saveCaptureData(app, app.CaptureData, varCaptureName);

            % Save offset data
            varOffsetName = 'OffsetData';
            assignin('base', varOffsetName, app.PreOffsetData);
            
        end

        function plotCaptureData(app, data)
            if isempty(app.CaptureData) || isempty(app.CaptureTimestamps)
                return
            end
            app.CurrentPlottingData = data;
            
            dataYLim = app.MinMaxYTable.Data;
            for indexChannel = 1:app.ChannelCount
                
                xlim(app.DataAxesList{indexChannel}, [app.CaptureTimestamps(1), app.CaptureTimestamps(end)]);
                ylim(app.DataAxesList{indexChannel}, [dataYLim(indexChannel, 1), dataYLim(indexChannel, 2)]);
                set(app.DataPlotsLine{indexChannel}, 'XData', app.CaptureTimestamps(:), ...
                                                     'YData', data(:, indexChannel));
            end
        end
        
        function data = applyOffset(app, data)
            if isempty(app.CaptureData) || isempty(app.CaptureTimestamps)
                return
            end
            %% Process pre-offset data
            if app.PreOffsetSwitch.Value == "Off"
                offsetDataSelect = zeros(size(app.PreOffsetData));
            else
                offsetDataSelect = app.PreOffsetData;
            end
            for indexChannel = 1:app.ChannelCount
                data(:, indexChannel) = data(:, indexChannel) - offsetDataSelect(:, indexChannel);
            end

            %% Process post-offset data
            if app.PostOffsetSwitch.Value == "Off"
                return
            end
            postOffsetDataCount = round(app.PostOffsetPeriodSpinner.Value * app.Rate.Value);
            if postOffsetDataCount == 0
                return
            end
            app.PostOffsetData = zeros(app.ChannelCount, 1);
            for indexChannel = 1:app.ChannelCount
                postOffsettingValue = mean(data(1:postOffsetDataCount, indexChannel), 1);
                data(:, indexChannel) = data(:, indexChannel) - postOffsettingValue;
                app.PostOffsetData(indexChannel) = postOffsettingValue;
            end

        end

        function [buffersize, bufferTimeSpan] = calculateBufferSize(~, callbackTimeSpan, liveViewTimeSpan, triggerDelay, captureDuration, rate)
        %calculateBufferSize Calculates the required FIFO data buffer size 
        %  callbackTimeSpan is the wall-clock timespan (seconds) of the data read in the DAQ ScansAvailable callback function.
        %  liveViewTimeSpan is the wall-clock timespan (seconds) of the DAQ data in the live view plot.
        %  triggerDelay is the trigger delay (seconds).
        %  captureDuration is the total capture duration (seconds).
        %  rate is the DAQ acquisition rate (scans/second).
        %  buffersize is the calculated buffer size (number of scans).
        %  bufferTimeSpan is the wall-clock timespan (seconds) corresponding to <buffersize> scans.       
        
            if triggerDelay < 0
                bufferTimeSpan = max([abs(triggerDelay), captureDuration, liveViewTimeSpan]);
            else
                bufferTimeSpan = max([captureDuration, liveViewTimeSpan]);
            end
            bufferTimeSpan = (bufferTimeSpan + 2*callbackTimeSpan)*2;
            buffersize = ceil(rate * bufferTimeSpan) + 1;
            
        end
        

        function results = isEnoughDataBuffered(app)
        %isEnoughDataBuffered Checks whether buffering pre-trigger data is complete    
       
            % If specified trigger delay is less than 0, need to check
            % whether enough pre-trigger data is buffered so that a
            % triggered capture can be requested
            
            results = (app.TriggerDelay >= 0) || ...
                ((app.FIFOIndex - 1) > ceil(abs(app.TriggerDelay)*app.DAQ.Rate));
            
        end

        function results = isEnoughDataCaptured(app)
        %isEnoughDataCaptured Check whether captured-data duration exceeds specified capture duration
            currentCaptureLength = app.TimestampsFIFOBuffer(app.FIFOIndex - 1, 1) - app.CaptureStartMoment;
            results = (currentCaptureLength > app.CaptureDuration);
            % results = (app.DAQ.NumScansAcquired >= app.DAQ.Rate*app.CaptureDuration);
            app.StatusText_2.Text = "Recording: " + string(currentCaptureLength) + "s out of " + string(app.CaptureDuration) + "s completed!";
        end

        function results = isEnoughDataOffset(app)
            app.PreOffsetDataRaw = [app.PreOffsetDataRaw; app.Data];
            results = (app.TimestampsFIFOBuffer(app.FIFOIndex - 1, 1) - app.OffsetStartMoment) > app.OffsetDuration;
        end

        function results = isCycleOver(app)
            prepareBuffer(app); % Reset the buffer
            if app.CycleCountCurrent <= app.CycleCountTotal
                results = false;
            else
                results = true;
            end
        end

        function results = isRestingOver(app)
            if seconds(datetime('now') - app.RestPeriodStartTime) < app.RestPeriodLength
                results = false;
                app.StatusText_2.Text = "Resting: " + string(app.RestPeriodLength  - seconds(datetime('now') - app.RestPeriodStartTime)) + " s remaining.";
            else
                results = true;
            end
        end

        function commitCycleSettings(app)
            app.CycleCountCurrent = 1;
            app.CycleCountTotal = app.cyclesEditField.Value;
            app.RestPeriodLength = app.RestperiodsEditField.Value;
            app.RecordingPeriodLength = app.RecordingperiodsEditField.Value;
            app.RestPeriodFirst = app.RestperiodfirstCheckBox.Value;
            app.BufferSize = calculateBufferSize(app, app.CallbackTimeSpan, ...
                                                      app.LivePlotLengthField.Value, ...
                                                      app.OffsetDurationField.Value, ...
                                                      app.CaptureDuration, ...
                                                      app.DAQ.Rate);
            app.BufferSizeValue.Text = num2str(app.BufferSize);
            updateCycleCapturePanel(app)
        end

        function results = doesTriggerDelayChangeRequireBuffering(app)
            value = app.TriggerDelayEditField.Value;
            if value < 0 && size(app.TimestampsFIFOBuffer,1) < ceil(abs(value)*app.DAQ.Rate)
                results = true;
            else
                results = false;
            end            
        end

        function setChannelPropertyDefault(app, minY, maxY)
            RowName = {};
            app.MinMaxYTable.ColumnEditable = [true, true, true];
            channelList = str2num(app.InputChannelsList.Value); %#ok<ST2NM> 
            for indexChannel = 1:app.ChannelCount
                channelNumber = channelList(indexChannel);
                RowName{indexChannel} = "Channel " + num2str(channelNumber);
                
                app.MinMaxYTable.Data(indexChannel, 1) = minY;
                app.MinMaxYTable.Data(indexChannel, 2) = maxY;
                if channelNumber > 15
                    app.MinMaxYTable.Data(indexChannel, 3) = "Strain gauge " + num2str(channelNumber - 15);
                else
                    app.MinMaxYTable.Data(indexChannel, 3) = RowName{indexChannel};
                end
             
            end
            app.MinMaxYTable.RowName = RowName;

        end

        function setYLimAxes(app)
            data = app.MinMaxYTable.Data;
            for indexChannel = 1:app.ChannelCount
                app.LiveAxesList{indexChannel}.YLim = [data(indexChannel, 1), data(indexChannel, 2)];
                app.DataAxesList{indexChannel}.YLim = [data(indexChannel, 1), data(indexChannel, 2)];
            end
        end

        function setMatricesPropertyDefault(app)
            
        end
        function flipAcquire(app, value)
            if value == "Start"  
                prepareBuffer(app);  % Reset FIFO buffer data
                app.TimestampOffset = 0;
                % Reset Data and Timestamps
                app.Data = [];
                app.Timestamps = [];
                
                try
                    start(app.DAQ, 'continuous');
                    % Configure the app for Acquisition state
                    app.CurrentState = 'Acquisition.Buffering';
                    setAppViewState(app, app.CurrentState);
               
                    setYLimAxes(app)               
                catch exception
                    uialert(app.UIFigure, exception.message, 'Start error');   
                end
            elseif value == "Stop"
                stop(app.DAQ);

                prepareBuffer(app); % reset the FIFO
                % Configure the app for device configuration state
                app.CurrentState = 'Armed';
                setAppViewState(app, app.CurrentState);
            end

        end
        
        function loadCalibrationMatrices(app)
            app.CalibrationMatrix = load('calibrationMatrixATI.mat');
            app.calibrationMatrixTable.Data = app.CalibrationMatrix.calibrationMatrixATI;
            
            app.TranslationMatrix = load('toolTransMatrix.mat'); 
            app.translationMatrixTable.Data = app.TranslationMatrix.toolTransMatrix;

            app.RotationMatrix = load('rotationMatrix.mat');
            app.rotationMatrixTable.Data = app.RotationMatrix.rotationMatrix;
        end

        function calculateCalibratedMatrix(app)
            loadCalibrationMatrices(app)
            app.CalibratedMatrix = app.RotationMatrix.rotationMatrix*app.TranslationMatrix.toolTransMatrix*app.CalibrationMatrix.calibrationMatrixATI;
            app.calibratedMatrixTable.Data = app.CalibratedMatrix;
            assignin('base', 'calibratedMatrix', app.CalibratedMatrix);
        end

        function updateCalibratedMatrix(app)
            app.CalibrationMatrix.calibrationMatrixATI = app.calibrationMatrixTable.Data;
            app.TranslationMatrix.toolTransMatrix = app.translationMatrixTable.Data;
            app.RotationMatrix.rotationMatrix = app.rotationMatrixTable.Data;

            app.CalibratedMatrix = app.RotationMatrix.rotationMatrix*app.TranslationMatrix.toolTransMatrix*app.CalibrationMatrix.calibrationMatrixATI;
            app.calibratedMatrixTable.Data = app.CalibratedMatrix;
            assignin('base', 'calibratedMatrix', app.CalibratedMatrix);
        end

        function applyCalibration(app, data)
            indexSG = find(app.InputChannelNumbers>15);
            countSG = size(indexSG, 2);
            captureSG= zeros(size(data, 1), countSG);
            indexCurrentSG = 1;
            for indexChannel = 1:app.ChannelCount
                channelNumber = app.InputChannelNumbers(indexChannel);
                if channelNumber >= 16
                    captureSG(:, indexCurrentSG) = data(:, indexChannel);
                    indexCurrentSG = indexCurrentSG + 1;
                end
            end
            calibratedSG = app.CalibratedMatrix*captureSG';
            app.CaptureDataCalibrate = data;
            app.CaptureDataCalibrate(:, indexSG) = calibratedSG';

            varCaptureName = char(string(app.VariableNameEditField.Value) + "_force");
            saveCaptureData(app, app.CaptureDataCalibrate, varCaptureName);

        end
        

        function captureData = saveCaptureData(app, data, varnameInput, options)
            % Save captured data to a base workspace variable
            % For simplicity, validation of user input and checking whether a variable
            % with the same name already exists are not addressed in this example.
            % Get the variable name from UI text input
            arguments
                app
                data
                varnameInput
                options.force (1, 1) {mustBeNumericOrLogical} = false
                options.toWorkspace (1, 1) {mustBeNumericOrLogical} = true
                options.timetable (1, 1) {mustBeNumericOrLogical} = true
            end
            columnNames = {};
            channelList = str2num(app.InputChannelsList.Value); %#ok<ST2NM> 
            for indexChannel = 1:app.ChannelCount
                channelNumber = channelList(indexChannel);
                if channelNumber >= 16 && options.force
                    columnNames{indexChannel} = char(app.NameForceList{channelNumber-15});
                else
                    columnNames{indexChannel} = char("Channel " + num2str(channelNumber));
                end
            end
            columnNames{app.ChannelCount+1} = char("Timestamps Raw");

            if app.PreOffsetSwitch.Value == "Off"
                varName = genvarname(char(string(varnameInput) + "_wo_PreOffset"));
            else
                varName = genvarname(char(string(varnameInput) + "_w_PreOffset"));
            end
            if app.PostOffsetSwitch.Value == "Off"
                varName = genvarname(char(string(varName) + "_wo_PostOffset"));
            else
                varName = genvarname(char(string(varName) + "_w_PostOffset"));
            end

            if options.timetable
                captureData = timetable(seconds(app.CaptureTimestamps), data, 'VariableNames', {varName});
            else
                columnName = [{'Timestamps'}, varName];
                captureData = table(app.CaptureTimestamps, data, 'VariableNames', columnName);
            end
            captureData = splitvars(captureData, varName, 'NewVariableNames', columnNames);
            
            % Use assignin function to save the captured data in a base workspace variable
            if options.toWorkspace
                assignin('base', varName, captureData);
            end
            
        end
        
        function results = exportTocsv(app)
            app.ExportcsvStatus.Text = 'Exporting to csv...';
            currentDIR = app.SavingdirEditField.Value;
            
            currentDateTime = datetime('now', 'Format', '_yyMMdd_HHmmSS_');
            datetimeUnique = string(currentDateTime, '_yyMMdd_HHmmSS_');
            exportFileBaseName = char(string(app.SubjectNameEditField.Value) + datetimeUnique + "_Cycle_" + num2str(app.CycleNumberField.Value));
            exportFolderDIR = fullfile(currentDIR);
            % mkdir(exportFolderDIR);
            force = true;
            toWorkspace = false;
            timetable = false;
            initialPreOffsetState = app.PreOffsetSwitch.Value;
            initialPostOffsetState = app.PostOffsetSwitch.Value;
            
            %% Get the timestamp of video's frame
            exportFileName = fullfile(exportFolderDIR, exportFileBaseName + "_video_timestamp.csv");
            if isfile(exportFileName)
                delete(exportFileName);
                pause(1);
            end
            writematrix(app.TimestampVideoList, exportFileName, 'Delimiter', ',');
            pause(0.5);
                    
            %% Get data without offset
            app.PreOffsetSwitch.Value = "Off";
            app.PostOffsetSwitch.Value = "Off";
            data_woOffset = applyOffset(app, app.CaptureData);
            applyCalibration(app, data_woOffset);
            forceData_woOffset = app.CaptureDataCalibrate;
  
            % Exporting raw data
            if app.data_woOffsetCheckBox.Value == 1
                data_woOffset = saveCaptureData(app, data_woOffset, "savingData", toWorkspace=toWorkspace, timetable=timetable);
                exportFileName = fullfile(exportFolderDIR, exportFileBaseName + "_data_woOffset.csv");
                if isfile(exportFileName)
                    delete(exportFileName);
                    pause(1);
                end
                writetable(data_woOffset, exportFileName, 'Delimiter', ',')
                pause(1);
            end

            % Exporting force data
            if app.forceData_woOffsetCheckBox.Value == 1
                forceData_woOffset = saveCaptureData(app, forceData_woOffset, "savingData", force=force, toWorkspace=toWorkspace, timetable=timetable);
                exportFileName = fullfile(exportFolderDIR, exportFileBaseName + "_forceData_woOffset.csv");
                if isfile(exportFileName)
                    delete(exportFileName);
                    pause(1);
                end
                writetable(forceData_woOffset, exportFileName, 'Delimiter', ',')
                pause(1);
            end

            %% get data with pre-offset and post-offset
            app.PreOffsetSwitch.Value = "On";
            app.PostOffsetSwitch.Value = "On";
            
            data_wPreOffset_wPostOffset = applyOffset(app, app.CaptureData);
            applyCalibration(app, data_wPreOffset_wPostOffset);
            forceData_wPreOffset_wPostOffset = app.CaptureDataCalibrate;

            % Exporting data
            if app.data_wPreOffset_wPostOffsetCheckBox.Value == 1
                data_wPreOffset_wPostOffset = saveCaptureData(app, data_wPreOffset_wPostOffset, "savingData", toWorkspace=toWorkspace, timetable=timetable);
                exportFileName = fullfile(exportFolderDIR, exportFileBaseName + "_data_wPreOffset_wPostOffset.csv");
                if isfile(exportFileName)
                    delete(exportFileName);
                    pause(1);
                end
                writetable(data_wPreOffset_wPostOffset, exportFileName, 'Delimiter', ',')
                pause(1);
            end

            % Exporting force data
            if app.forceData_wPreOffset_wPostOffsetCheckBox.Value == 1
                forceData_wPreOffset_wPostOffset = saveCaptureData(app, forceData_wPreOffset_wPostOffset, "savingData", force=force, toWorkspace=toWorkspace, timetable=timetable);
                exportFileName = fullfile(exportFolderDIR, exportFileBaseName + "_forceData_wPreOffset_wPostOffset.csv");
                if isfile(exportFileName)
                    delete(exportFileName);
                    pause(1);
                end
                writetable(forceData_wPreOffset_wPostOffset, exportFileName, 'Delimiter', ',')
                pause(1);
            end
    
            %% get data with pre-offset only
            app.PreOffsetSwitch.Value = "On";
            app.PostOffsetSwitch.Value = "Off";
            
            data_wPreOffset_woPostOffset = applyOffset(app, app.CaptureData);
            applyCalibration(app, data_wPreOffset_woPostOffset);
            forceData_wPreOffset_woPostOffset = app.CaptureDataCalibrate;

            % Exporting data
            if app.data_wPreOffset_woPostOffsetCheckBox.Value == 1
                data_wPreOffset_woPostOffset = saveCaptureData(app, data_wPreOffset_woPostOffset, "savingData", toWorkspace=toWorkspace, timetable=timetable);
                exportFileName = fullfile(exportFolderDIR, exportFileBaseName + "_data_wPreOffset_woPostOffset.csv");
                if isfile(exportFileName)
                    delete(exportFileName);
                    pause(1);
                end
                writetable(data_wPreOffset_woPostOffset, exportFileName, 'Delimiter', ',')
                pause(1);
            end

            % Exporting force data
            if app.forceData_wPreOffset_woPostOffsetCheckBox.Value == 1
                forceData_wPreOffset_woPostOffset = saveCaptureData(app, forceData_wPreOffset_woPostOffset, "savingData", force=force, toWorkspace=toWorkspace, timetable=timetable);
                exportFileName = fullfile(exportFolderDIR, exportFileBaseName + "_forceData_wPreOffset_woPostOffset.csv");
                if isfile(exportFileName)
                    delete(exportFileName);
                    pause(1);
                end
                writetable(forceData_wPreOffset_woPostOffset, exportFileName, 'Delimiter', ',')
                pause(1);
            end
            
            % Export other params
            if app.TestmatCheckBox.Value == 1
                params.Channels = app.InputChannelNumbers;
                params.ChannelsInfo = app.DAQ.Channels;
                params.OffsetDuration = app.OffsetDuration;
                params.offsetData = app.PreOffsetData;
                params.CalibratedMatrix = app.CalibratedMatrix;
                params.CaptureDuration = app.CaptureDuration;
    
                % Raw data
                rawData.CaptureData = app.CaptureDataRaw;
                rawData.OffsetData = app.PreOffsetDataRaw;
                
                % Timestamps data
                timestamps = app.CaptureTimestamps;
                exportFileName = fullfile(exportFolderDIR, "Test.mat");
                save(exportFileName, "rawData", ...
                                     "timestamps", ...
                                     "params");
            end

            app.PreOffsetSwitch.Value = initialPreOffsetState;
            app.PostOffsetSwitch.Value = initialPostOffsetState;
            app.ExportcsvStatus.Text = 'Done!';
            results = true;
        end

        function results = exportTomat(app)
            app.ExportmatStatus.Text = 'Exporting to Mat';

            exportFileName = char(string(app.SubjectNameEditField.Value) + "_" + num2str(app.CycleNumberField.Value) + ".mat");
            initialPreOffsetState = app.PreOffsetSwitch.Value;
            initialPostOffsetState = app.PostOffsetSwitch.Value;
            force = true;
            toWorkspace = false;

            %% Get data without offset
            app.PreOffsetSwitch.Value = "Off";
            app.PostOffsetSwitch.Value = "Off";
            data_woOffset = applyOffset(app, app.CaptureData);
            applyCalibration(app, data_woOffset);
            forceData_woOffset = app.CaptureDataCalibrate;
  
            data_woOffset = saveCaptureData(app, data_woOffset, "savingData", toWorkspace=toWorkspace);
            forceData_woOffset = saveCaptureData(app, forceData_woOffset, "savingData", force=force, toWorkspace=toWorkspace);

            %% Get data with offset
            app.PreOffsetSwitch.Value = "On";

            % Export data with Post-Offset On
            app.PostOffsetSwitch.Value = "On";
            data_wPreOffset_wPostOffset = applyOffset(app, app.CaptureData);
            applyCalibration(app, data_wPreOffset_wPostOffset);
            forceData_wPreOffset_wPostOffset = app.CaptureDataCalibrate;

            data_wPreOffset_wPostOffset = saveCaptureData(app, data_wPreOffset_wPostOffset, "savingData", toWorkspace=toWorkspace);
            forceData_wPreOffset_wPostOffset = saveCaptureData(app, forceData_wPreOffset_wPostOffset, "savingData", force=force, toWorkspace=toWorkspace);
            
            % Export data with Post-Offset Off
            app.PostOffsetSwitch.Value = "Off";
            data_wPreOffset_woPostOffset = applyOffset(app, app.CaptureData);
            applyCalibration(app, data_wPreOffset_woPostOffset);
            forceData_wPreOffset_woPostOffset = app.CaptureDataCalibrate;

            data_wPreOffset_woPostOffset = saveCaptureData(app, data_wPreOffset_woPostOffset, "savingData", toWorkspace=toWorkspace);
            forceData_wPreOffset_woPostOffset = saveCaptureData(app, forceData_wPreOffset_woPostOffset, "savingData", force=force, toWorkspace=toWorkspace);
            

            %% Capture parameters
            params.Channels = app.InputChannelNumbers;
            params.ChannelsInfo = app.DAQ.Channels;
            params.OffsetDuration = app.OffsetDuration;
            params.offsetData = app.PreOffsetData;
            params.CalibratedMatrix = app.CalibratedMatrix;
            params.CaptureDuration = app.CaptureDuration;

            % Raw data
            rawData.CaptureData = app.CaptureDataRaw;
            rawData.OffsetData = app.PreOffsetDataRaw;
            
            % Timestamps data
            timestamps = app.CaptureTimestamps;

            save(exportFileName, "data_woOffset", ...
                                 "forceData_woOffset", ...
                                 "data_wPreOffset_wPostOffset", ...
                                 "forceData_wPreOffset_wPostOffset", ...
                                 "data_wPreOffset_woPostOffset", ...
                                 "forceData_wPreOffset_woPostOffset", ...
                                 "rawData", ...
                                 "timestamps", ...
                                 "params");

            app.PreOffsetSwitch.Value = initialPreOffsetState;
            app.PostOffsetSwitch.Value = initialPostOffsetState;
            app.ExportmatStatus.Text = 'Done!';
            results = true;
        end
        
        function prepareBuffer(app)
            % Reset FIFO buffer data
            app.DataFIFOBuffer = zeros(app.BufferSize, app.BufferRowSize);
            app.TimestampsFIFOBuffer = zeros(app.BufferSize, 1);
            app.FIFOIndex = 1;

            % Reset Data and Timestamps
            app.Data = [];
            app.Timestamps = [];

            app.CaptureTimestamps = [];
            % app.TimestampOffset = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InputChannelNumbers = str2num(app.InputChannelsList.Value); %#ok<ST2NM> 
            app.ChannelCount = length(app.InputChannelNumbers);
            app.SGChannelList = app.InputChannelNumbers(find(app.InputChannelNumbers>=16));
            app.NameForceList = {"Fx", "Fy", "Fz", "Tx", "Ty", "Tz"};

            app.PreOffsetData = zeros(1, app.ChannelCount);
            
            calculateCalibratedMatrix(app)
            setChannelPropertyDefault(app, -10, 10)
            plotParams = generatePlotParams(app);
            updateSubplotPanel(app)
            updateCycleCapturePanel(app)
            updateDataPlotPanel(app, plotParams)
            setYLimAxes(app)

            % Populate the plot selected channel list
            if isrow(app.InputChannelNumbers)
                channelListCell = cellstr(num2str(app.InputChannelNumbers'));
            else
                channelListCell = cellstr(num2str(app.InputChannelNumbers));
            end

            app.ChannelplotselectDropDown.Items = channelListCell;
            app.ChannelplotselectDropDown.Value = channelListCell{1};

            app.CurrentState = 'Unarm';
            setAppViewState (app, app.CurrentState)
            app.StatusText.Text = 'Unarm - Initial startup.';
            
        end

        % Button pushed function: ArmButton
        function ArmButtonPushed(app, event)
            app.CurrentState = 'Arming';
            
            cla(app.CaptureDataAxes, 'reset');
            setAppViewState (app, app.CurrentState)
            
            app.StatusText.Text = 'Arming - Setting device parameters...';
            setDeviceParameters(app)

            app.StatusText.Text = 'Arming - Updating subplot panel...';
            updateSubplotPanel(app)

            app.StatusText.Text = 'Arming - Updating cycle plot panel...';
            updateCycleCapturePanel(app)

            app.StatusText.Text = 'Arming - Preparing buffer...';
            prepareBuffer(app)
            
            app.StatusText.Text = 'Arming - Updating data plot panel...';
            plotParams = generatePlotParams(app);
            updateDataPlotPanel(app, plotParams)

            app.CurrentState = 'Armed';
            setAppViewState (app, app.CurrentState)
        end

        % Value changed function: AcquireSwitch
        function AcquireSwitchFlip(app, event)
            value = app.AcquireSwitch.Value;
            flipAcquire(app, value);
        end

        % Value changed function: CaptureLengthField
        function CaptureLengthFieldValueChanged(app, event)
            app.CurrentState = 'Unarm';
            if app.AcquireSwitch.Value == "Start"
                app.AcquireSwitch.Value = "Stop";
                flipAcquire(app, "Stop");
            end
            setAppViewState(app, app.CurrentState);
            app.StatusText.Text = 'Unarmed - Capture length changed.';
        end

        % Value changed function: Rate
        function RateValueChanged(app, event)
            app.CurrentState = 'Unarm';
            setAppViewState(app, app.CurrentState);
            app.StatusText.Text = 'Unarmed - Rate value changed.';
        end

        % Value changed function: InputChannelsList
        function InputChannelsListValueChanged(app, event)
            app.InputChannelNumbers = str2num(app.InputChannelsList.Value); %#ok<ST2NM> 
            app.ChannelCount = length(app.InputChannelNumbers);
            setChannelPropertyDefault(app, -10, 10)
            app.PreOffsetData = zeros(1, app.ChannelCount);
            app.SGChannelList = app.InputChannelNumbers(find(app.InputChannelNumbers>=16));

            % Populate the plot selected channel list
            if isrow(app.InputChannelNumbers)
                channelListCell = cellstr(num2str(app.InputChannelNumbers'));
            else
                channelListCell = cellstr(num2str(app.InputChannelNumbers));
            end

            app.ChannelplotselectDropDown.Items = channelListCell;
            app.ChannelplotselectDropDown.Value = channelListCell{1};

            cellfun(@(x) delete(x), app.LiveAxesList)
            cellfun(@(x) delete(x), app.DataAxesList)

            app.LiveAxesList = {};
            app.DataAxesList = {};
            app.PostCaptureAxesList = {};
            
            updateSubplotPanel(app)
            updateCapturePlotPanel(app)
            updateCycleCapturePanel(app)

            plotParams = generatePlotParams(app);
            updateDataPlotPanel(app, plotParams);
            app.CurrentState = 'Unarm';
            if app.AcquireSwitch.Value == "Start"
                app.AcquireSwitch.Value = "Stop";
                flipAcquire(app, "Stop");
            end
            setAppViewState(app, app.CurrentState);
            app.StatusText.Text = 'Unarmed - List of channels changed.';
        end

        % Value changing function: InputChannelsList
        function InputChannelsListValueChanging(app, event)
            app.CurrentState = 'Unarm';
            if app.AcquireSwitch.Value == "Start"
                app.AcquireSwitch.Value = "Stop";
                flipAcquire(app, "Stop");
            end
            setAppViewState(app, app.CurrentState);
            app.StatusText.Text = 'Unarmed - List of channels is changing.';
        end

        % Display data changed function: MinMaxYTable
        function MinMaxYTableDisplayDataChanged(app, event)
            setYLimAxes(app)
            updateChannelData(app)
        end

        % Value changed function: CaptureButton
        function CaptureButtonValueChanged(app, event)
            updateCapturePlotPanel(app)
            value = app.CaptureButton.Value;
            if ~value
                % Capture cancelled
                app.CurrentState = 'Acquisition.ReadyForCapture';
                setAppViewState(app, app.CurrentState);
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plotParams = generatePlotParams(app);
            updateDataPlotPanel(app, plotParams)
            data = applyOffset(app, app.CaptureData);
            plotCaptureData(app, data)
        end

        % Button pushed function: ApplycalibrationButton
        function ApplycalibrationButtonPushed(app, event)
            data = applyOffset(app, app.CaptureData);
            plotParams = generatePlotParams(app);
            applyCalibration(app, data)
            plotParams.ylabel = 'Force (N)';
            updateDataPlotPanel(app, plotParams)
            
            plotCaptureData(app, app.CaptureDataCalibrate)
        end

        % Button pushed function: ExporttocsvButton
        function ExporttocsvButtonPushed(app, event)
            exportTocsv(app);
        end

        % Button pushed function: ExporttomatButton
        function ExporttomatButtonPushed(app, event)
            exportTomat(app);
        end

        % Value changed function: BiasTick
        function BiasTickValueChanged(app, event)
            value = app.BiasTick.Value;
            if value && app.CurrentState == "Acquisition.ReadyForCapture"
                app.OffsetStartMoment = app.Timestamps(end);
                app.PreOffsetDataRaw = app.Data;
                app.CurrentState = 'Acquisition.CollectingOffset';
                setAppViewState(app, app.CurrentState);
            end
        end

        % Button pushed function: PlotChosenChannel
        function PlotChosenChannelButtonPushed(app, event)
            chosenChannel = app.ChannelplotselectDropDown.Value;
            if ~isnumeric(chosenChannel)
                chosenChannel = str2double(chosenChannel);
            end
            dataYLim = app.MinMaxYTable.Data;
            indexChannel = find(app.InputChannelNumbers==chosenChannel);
            figure
            plot(app.CaptureTimestamps(:), app.CurrentPlottingData(:, indexChannel));
            xlim([app.CaptureTimestamps(1), app.CaptureTimestamps(end)]);
            ylim([dataYLim(indexChannel, 1), dataYLim(indexChannel, 2)]);
        end

        % Selection changed function: RecordingModeButtonGroup
        function RecordingModeButtonGroupSelectionChanged(app, event)
            app.CurrentState = 'Unarm';
            if app.AcquireSwitch.Value == "Start"
                app.AcquireSwitch.Value = "Stop";
                flipAcquire(app, "Stop");
            end
            setAppViewState(app, app.CurrentState);
            app.StatusText.Text = 'Unarmed - Recording mode changed.';

            if app.SingleCycleButton.Value == 1
                app.CaptureLengthField.Enable = 'on';
                app.cyclesEditField.Enable = 'off';
                app.RecordingperiodsEditField.Enable = 'off';
                app.RestperiodsEditField.Enable = 'off';
                app.ChecktocommitsettingsCheckBox.Enable = 'off';
            elseif app.MultipleCyclesButton.Value == 1
                app.CaptureLengthField.Enable = 'off';
                app.cyclesEditField.Enable = 'on';
                app.RecordingperiodsEditField.Enable = 'on';
                app.RestperiodsEditField.Enable = 'on';
                app.ChecktocommitsettingsCheckBox.Enable = 'on';
            end
            

        end

        % Value changed function: RecordingperiodsEditField
        function RecordingperiodsEditFieldValueChanged(app, event)
            app.CurrentState = 'Unarm';
            if app.AcquireSwitch.Value == "Start"
                app.AcquireSwitch.Value = "Stop";
                flipAcquire(app, "Stop");
            end
            setAppViewState(app, app.CurrentState);
            app.StatusText.Text = 'Unarmed - Recording period changed.';
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end

        % Value changed function: cyclesEditField
        function cyclesEditFieldValueChanged(app, event)
            app.CurrentState = 'Unarm';
            if app.AcquireSwitch.Value == "Start"
                app.AcquireSwitch.Value = "Stop";
                flipAcquire(app, "Stop");
            end
            setAppViewState(app, app.CurrentState);
            app.StatusText.Text = 'Unarmed - Number of recording cycles changed.';
            
        end

        % Value changed function: ChecktocommitsettingsCheckBox
        function ChecktocommitsettingsCheckBoxValueChanged(app, event)
            commitCycleSettings(app)
            app.CurrentState = 'Acquisition.ReadyForCapture';
            setAppViewState(app, app.CurrentState);
        end

        % Value changed function: Ao_0DCSpinner
        function Ao_0DCSpinnerValueChanged(app, event)
            value = app.Ao_0DCSpinner.Value;
            
        end

        % Button pushed function: ReloadMatricesButton
        function ReloadMatricesButtonPushed(app, event)
            calculateCalibratedMatrix(app)
        end

        % Button pushed function: UpdateMatricesButton
        function UpdateMatricesButtonPushed(app, event)
            updateCalibratedMatrix(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Recording = uitab(app.TabGroup2);
            app.Recording.Title = 'Recording';
            app.CaptureDataAxes = uiaxes(app.Recording);
            title(app.CaptureDataAxes, 'Captured data')
            xlabel(app.CaptureDataAxes, 'Time (s)')
            ylabel(app.CaptureDataAxes, 'Voltage (V)')
            zlabel(app.CaptureDataAxes, 'Z')
            app.RunningstatPanel = uipanel(app.Recording);
            app.RunningstatPanel.Title = 'Running stat';
            app.BuffersizeLabel = uilabel(app.RunningstatPanel);
            app.BuffersizeLabel.Text = 'Buffer size:';
            app.BufferSizeValue = uilabel(app.RunningstatPanel);
            app.BufferSizeValue.Text = '0';
            app.FIFOcountLabel = uilabel(app.RunningstatPanel);
            app.FIFOcountLabel.Text = 'FIFO count: ';
            app.FIFOCountValue = uilabel(app.RunningstatPanel);
            app.FIFOCountValue.Text = '0';
            app.TriggerchannelLabel = uilabel(app.RunningstatPanel);
            app.TriggerchannelLabel.Text = 'Trigger channel: ';
            app.TriggerChannelLabelValue = uilabel(app.RunningstatPanel);
            app.TriggerChannelLabelValue.Text = '';
            app.CapturetimerLabel = uilabel(app.RunningstatPanel);
            app.CapturetimerLabel.Text = 'Capture timer: ';
            app.CapturetimerLabelValue = uilabel(app.RunningstatPanel);
            app.CapturetimerLabelValue.Text = '0';
            app.SystemstatusLabel = uilabel(app.RunningstatPanel);
            app.SystemstatusLabel.Text = 'System status: ';
            app.StatusText = uilabel(app.RunningstatPanel);
            app.StatusText.Text = 'Standing by...';
            app.ExperimentalstatusLabel = uilabel(app.RunningstatPanel);
            app.ExperimentalstatusLabel.Text = 'Experimental status:';
            app.StatusText_2 = uilabel(app.RunningstatPanel);
            app.StatusText_2.Text = 'Standing by...';
            app.TabGroup = uitabgroup(app.Recording);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SubplotView = uitab(app.TabGroup);
            app.SubplotView.Title = 'Subplot View';
            app.SubplotAxesPanel = uipanel(app.SubplotView);
            app.CombinedPlotView = uitab(app.TabGroup);
            app.CombinedPlotView.Title = 'Combined Plot View';
            app.LiveDataAxes = uiaxes(app.CombinedPlotView);
            title(app.LiveDataAxes, 'Title')
            xlabel(app.LiveDataAxes, 'X')
            ylabel(app.LiveDataAxes, 'Y')
            zlabel(app.LiveDataAxes, 'Z')
            app.PostCaptureplotTab = uitab(app.TabGroup);
            app.PostCaptureplotTab.Title = 'Post-Capture plot';
            app.PostCaptureAxesPanel = uipanel(app.PostCaptureplotTab);
            app.ExperimentalsetupPanel = uipanel(app.Recording);
            app.ExperimentalsetupPanel.Title = 'Experimental setup';
            app.CaptureButton = uibutton(app.ExperimentalsetupPanel, 'state');
            app.CaptureButton.ValueChangedFcn = createCallbackFcn(app, @CaptureButtonValueChanged, true);
            app.CaptureButton.Text = 'Capture';
            app.LiveplotduringcapturingCheckBox = uicheckbox(app.ExperimentalsetupPanel);
            app.LiveplotduringcapturingCheckBox.Text = 'Live plot during capturing';
            app.OffsetDurationField = uieditfield(app.ExperimentalsetupPanel, 'numeric');
            app.OffsetDurationField.Value = 1;
            app.TriggerDelaysLabel = uilabel(app.ExperimentalsetupPanel);
            app.TriggerDelaysLabel.Text = 'Offset duration (s)';
            app.TriggerChannelLabel = uilabel(app.ExperimentalsetupPanel);
            app.TriggerChannelLabel.Text = 'Trigger Channel';
            app.TriggerChannel = uieditfield(app.ExperimentalsetupPanel, 'numeric');
            app.TriggerChannel.Value = 2;
            app.TriggerLevelVLabel = uilabel(app.ExperimentalsetupPanel);
            app.TriggerLevelVLabel.Text = ' Trigger Level (V)';
            app.TriggerVoltage = uieditfield(app.ExperimentalsetupPanel, 'numeric');
            app.TriggerVoltage.Value = 1;
            app.DurationsEditField_2Label_2 = uilabel(app.ExperimentalsetupPanel);
            app.DurationsEditField_2Label_2.Text = 'Duration (s)';
            app.CaptureLengthField = uieditfield(app.ExperimentalsetupPanel, 'numeric');
            app.CaptureLengthField.ValueChangedFcn = createCallbackFcn(app, @CaptureLengthFieldValueChanged, true);
            app.CaptureLengthField.Value = 5;
            app.TriggerConditionDropDownLabel = uilabel(app.ExperimentalsetupPanel);
            app.TriggerConditionDropDownLabel.Text = 'Trigger Condition';
            app.TriggerCondition = uidropdown(app.ExperimentalsetupPanel);
            app.TriggerCondition.Items = {'Rising', 'Falling'};
            app.TriggerCondition.Value = 'Rising';
            app.VariablenameEditFieldLabel = uilabel(app.ExperimentalsetupPanel);
            app.VariablenameEditFieldLabel.Text = 'Variable name';
            app.VariableNameEditField = uieditfield(app.ExperimentalsetupPanel, 'text');
            app.VariableNameEditField.Value = 'capturedData';
            app.SubjectnameLabel = uilabel(app.ExperimentalsetupPanel);
            app.SubjectnameLabel.Text = 'Subject name';
            app.SubjectNameEditField = uieditfield(app.ExperimentalsetupPanel, 'text');
            app.SubjectNameEditField.Value = 'subject1';
            app.TrialLabel = uilabel(app.ExperimentalsetupPanel);
            app.TrialLabel.Text = 'Trial #';
            app.CycleNumberField = uispinner(app.ExperimentalsetupPanel);
            app.CycleNumberField.Limits = [1 Inf];
            app.CycleNumberField.RoundFractionalValues = 'on';
            app.CycleNumberField.Value = 1;
            app.AutosaveduringrestCheckBox = uicheckbox(app.ExperimentalsetupPanel);
            app.AutosaveduringrestCheckBox.Text = 'Autosave during rest';
            app.AutosaveduringrestCheckBox.Value = true;
            app.cyclesEditFieldLabel = uilabel(app.ExperimentalsetupPanel);
            app.cyclesEditFieldLabel.Text = '# cycles';
            app.cyclesEditField = uieditfield(app.ExperimentalsetupPanel, 'numeric');
            app.cyclesEditField.Limits = [1 Inf];
            app.cyclesEditField.ValueChangedFcn = createCallbackFcn(app, @cyclesEditFieldValueChanged, true);
            app.cyclesEditField.Value = 2;
            app.RecordingperiodsEditFieldLabel = uilabel(app.ExperimentalsetupPanel);
            app.RecordingperiodsEditFieldLabel.Text = 'Recording period (s)';
            app.RecordingperiodsEditField = uieditfield(app.ExperimentalsetupPanel, 'numeric');
            app.RecordingperiodsEditField.ValueChangedFcn = createCallbackFcn(app, @RecordingperiodsEditFieldValueChanged, true);
            app.RecordingperiodsEditField.Value = 5;
            app.RestperiodsEditFieldLabel = uilabel(app.ExperimentalsetupPanel);
            app.RestperiodsEditFieldLabel.Text = 'Rest period (s)';
            app.RestperiodsEditField = uieditfield(app.ExperimentalsetupPanel, 'numeric');
            app.RestperiodsEditField.Value = 10;
            app.RecordingModeButtonGroup = uibuttongroup(app.ExperimentalsetupPanel);
            app.RecordingModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RecordingModeButtonGroupSelectionChanged, true);
            app.RecordingModeButtonGroup.Title = 'Recording Mode';
            app.SingleCycleButton = uiradiobutton(app.RecordingModeButtonGroup);
            app.SingleCycleButton.Text = 'Single Cycle';
            app.MultipleCyclesButton = uiradiobutton(app.RecordingModeButtonGroup);
            app.MultipleCyclesButton.Text = 'Multiple Cycles';
            app.MultipleCyclesButton.Value = true;
            app.ChecktocommitsettingsCheckBox = uicheckbox(app.ExperimentalsetupPanel);
            app.ChecktocommitsettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChecktocommitsettingsCheckBoxValueChanged, true);
            app.ChecktocommitsettingsCheckBox.Text = 'Check to commit settings';
            app.RestperiodfirstCheckBox = uicheckbox(app.ExperimentalsetupPanel);
            app.RestperiodfirstCheckBox.Text = 'Rest period first';
            app.UseclockchannelCheckBox = uicheckbox(app.ExperimentalsetupPanel);
            app.UseclockchannelCheckBox.Text = 'Use clock channel';
            app.UseclockchannelCheckBox.Value = true;
            app.PostcapturesetupPanel = uipanel(app.Recording);
            app.PostcapturesetupPanel.Title = 'Post capture setup';
            app.SavingdirEditFieldLabel = uilabel(app.PostcapturesetupPanel);
            app.SavingdirEditFieldLabel.Text = 'Saving dir';
            app.SavingdirEditField = uieditfield(app.PostcapturesetupPanel, 'text');
            app.SavingdirEditField.Value = 'E:\zhong';
            app.ExportcsvStatus = uilabel(app.PostcapturesetupPanel);
            app.ExportcsvStatus.Text = 'Waiting for data...';
            app.ExportmatStatus = uilabel(app.PostcapturesetupPanel);
            app.ExportmatStatus.Text = 'Waiting for data...';
            app.ExporttocsvButton = uibutton(app.PostcapturesetupPanel, 'push');
            app.ExporttocsvButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttocsvButtonPushed, true);
            app.ExporttocsvButton.Text = 'Export to .csv';
            app.ExporttomatButton = uibutton(app.PostcapturesetupPanel, 'push');
            app.ExporttomatButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttomatButtonPushed, true);
            app.ExporttomatButton.Text = 'Export to .mat';
            app.DataexportchecklistPanel = uipanel(app.PostcapturesetupPanel);
            app.DataexportchecklistPanel.Title = 'Data export checklist';
            app.data_woOffsetCheckBox = uicheckbox(app.DataexportchecklistPanel);
            app.data_woOffsetCheckBox.Text = 'data_woOffset';
            app.data_wPreOffset_woPostOffsetCheckBox = uicheckbox(app.DataexportchecklistPanel);
            app.data_wPreOffset_woPostOffsetCheckBox.Text = 'data_wPreOffset_woPostOffset';
            app.data_wPreOffset_wPostOffsetCheckBox = uicheckbox(app.DataexportchecklistPanel);
            app.data_wPreOffset_wPostOffsetCheckBox.Text = 'data_wPreOffset_wPostOffset';
            app.forceData_woOffsetCheckBox = uicheckbox(app.DataexportchecklistPanel);
            app.forceData_woOffsetCheckBox.Text = 'forceData_woOffset';
            app.forceData_wPreOffset_woPostOffsetCheckBox = uicheckbox(app.DataexportchecklistPanel);
            app.forceData_wPreOffset_woPostOffsetCheckBox.Text = 'forceData_wPreOffset_woPostOffset';
            app.forceData_wPreOffset_woPostOffsetCheckBox.Value = true;
            app.forceData_wPreOffset_wPostOffsetCheckBox = uicheckbox(app.DataexportchecklistPanel);
            app.forceData_wPreOffset_wPostOffsetCheckBox.Text = 'forceData_wPreOffset_wPostOffset';
            app.TestmatCheckBox = uicheckbox(app.DataexportchecklistPanel);
            app.TestmatCheckBox.Text = 'Test.mat';
            app.InputChannelslistLabel_2 = uilabel(app.PostcapturesetupPanel);
            app.InputChannelslistLabel_2.Text = 'Ignored Channels Plot';
            app.IgnoreChannelsPlot = uieditfield(app.PostcapturesetupPanel, 'text');
            app.IgnoreChannelsPlot.Value = '1, 2, 3';
            app.CapturingparametersPanel = uipanel(app.Recording);
            app.CapturingparametersPanel.Title = 'Capturing parameters';
            app.ArmButton = uibutton(app.CapturingparametersPanel, 'push');
            app.ArmButton.ButtonPushedFcn = createCallbackFcn(app, @ArmButtonPushed, true);
            app.ArmButton.Text = 'Arm';
            app.CalibrationTick = uicheckbox(app.CapturingparametersPanel);
            app.CalibrationTick.Text = 'Apply calibration';
            app.CalibrationTick.Value = true;
            app.BiasTick = uicheckbox(app.CapturingparametersPanel);
            app.BiasTick.ValueChangedFcn = createCallbackFcn(app, @BiasTickValueChanged, true);
            app.BiasTick.Text = 'Apply bias';
            app.PlottingtimesEditFieldLabel = uilabel(app.CapturingparametersPanel);
            app.PlottingtimesEditFieldLabel.Text = 'Plotting time (s)';
            app.LivePlotLengthField = uieditfield(app.CapturingparametersPanel, 'numeric');
            app.LivePlotLengthField.Value = 0.45;
            app.InputChannelslistLabel = uilabel(app.CapturingparametersPanel);
            app.InputChannelslistLabel.Text = 'Input Channels list';
            app.InputChannelsList = uieditfield(app.CapturingparametersPanel, 'text');
            app.InputChannelsList.ValueChangedFcn = createCallbackFcn(app, @InputChannelsListValueChanged, true);
            app.InputChannelsList.ValueChangingFcn = createCallbackFcn(app, @InputChannelsListValueChanging, true);
            app.InputChannelsList.Value = '0, 1, 2, 3, 16, 17, 18, 19, 20, 21';
            app.DeviceEditFieldLabel = uilabel(app.CapturingparametersPanel);
            app.DeviceEditFieldLabel.Text = 'Device';
            app.DeviceName = uieditfield(app.CapturingparametersPanel, 'text');
            app.DeviceName.Value = 'dev1';
            app.AcquireSwitchLabel = uilabel(app.CapturingparametersPanel);
            app.AcquireSwitchLabel.Text = 'Acquire';
            app.AcquireSwitch = uiswitch(app.CapturingparametersPanel, 'slider');
            app.AcquireSwitch.Items = {'Stop', 'Start'};
            app.AcquireSwitch.ValueChangedFcn = createCallbackFcn(app, @AcquireSwitchFlip, true);
            app.AcquireSwitch.Value = 'Stop';
            app.ArmedLamp = uilamp(app.CapturingparametersPanel);
            app.DurationsEditField_2Label = uilabel(app.CapturingparametersPanel);
            app.DurationsEditField_2Label.Text = 'Rate (scan/s)';
            app.Rate = uieditfield(app.CapturingparametersPanel, 'numeric');
            app.Rate.ValueChangedFcn = createCallbackFcn(app, @RateValueChanged, true);
            app.Rate.Value = 10000;
            app.PlotcolumnsEditFieldLabel = uilabel(app.CapturingparametersPanel);
            app.PlotcolumnsEditFieldLabel.Text = 'Plot columns #';
            app.PlotColumnCount = uieditfield(app.CapturingparametersPanel, 'numeric');
            app.PlotColumnCount.Value = 3;
            app.ArmedText = uilabel(app.CapturingparametersPanel);
            app.ArmedText.Text = 'Unarm';
            app.Ao_0FreqSpinnerLabel = uilabel(app.CapturingparametersPanel);
            app.Ao_0FreqSpinnerLabel.Text = 'Ao_0 Freq';
            app.Ao_0FreqSpinner = uispinner(app.CapturingparametersPanel);
            app.Ao_0FreqSpinner.Limits = [50 300];
            app.Ao_0FreqSpinner.Value = 200;
            app.Ao_0DCSpinnerLabel = uilabel(app.CapturingparametersPanel);
            app.Ao_0DCSpinnerLabel.Text = 'Ao_0 DC';
            app.Ao_0DCSpinner = uispinner(app.CapturingparametersPanel);
            app.Ao_0DCSpinner.Limits = [0 100];
            app.Ao_0DCSpinner.ValueChangedFcn = createCallbackFcn(app, @Ao_0DCSpinnerValueChanged, true);
            app.Ao_0DCSpinner.Value = 10;
            app.Ao_1FreqSpinnerLabel = uilabel(app.CapturingparametersPanel);
            app.Ao_1FreqSpinnerLabel.Text = 'Ao_1 Freq';
            app.Ao_1FreqSpinner = uispinner(app.CapturingparametersPanel);
            app.Ao_1FreqSpinner.Limits = [50 300];
            app.Ao_1FreqSpinner.Value = 75;
            app.Ao_1DCSpinnerLabel = uilabel(app.CapturingparametersPanel);
            app.Ao_1DCSpinnerLabel.Text = 'Ao_1 DC';
            app.Ao_1DCSpinner = uispinner(app.CapturingparametersPanel);
            app.Ao_1DCSpinner.Limits = [0 100];
            app.Ao_1DCSpinner.Value = 8;
            app.ClockInputChannelSpinnerLabel = uilabel(app.CapturingparametersPanel);
            app.ClockInputChannelSpinnerLabel.Text = 'Clock Input Channel';
            app.ClockInputChannelSpinner = uispinner(app.CapturingparametersPanel);
            app.ClockInputChannelSpinner.Limits = [0 36];
            app.ClockInputChannelSpinner.Value = 1;
            app.Plotting = uitab(app.TabGroup2);
            app.Plotting.Title = 'Plotting';
            app.DataPlotPanel = uipanel(app.Plotting);
            app.DataPlotPanel.Title = 'Data Plot Panel';
            app.PlotButton = uibutton(app.Plotting, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ApplycalibrationButton = uibutton(app.Plotting, 'push');
            app.ApplycalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplycalibrationButtonPushed, true);
            app.ApplycalibrationButton.Text = 'Apply calibration';
            app.PlotChosenChannel = uibutton(app.Plotting, 'push');
            app.PlotChosenChannel.ButtonPushedFcn = createCallbackFcn(app, @PlotChosenChannelButtonPushed, true);
            app.PlotChosenChannel.Text = 'Plot channel';
            app.PreOffsetSwitchLabel = uilabel(app.Plotting);
            app.PreOffsetSwitchLabel.Text = 'Pre-Offset';
            app.PreOffsetSwitch = uiswitch(app.Plotting, 'toggle');
            app.PreOffsetSwitch.Value = 'On';
            app.ChannelplotselectDropDownLabel = uilabel(app.Plotting);
            app.ChannelplotselectDropDownLabel.Text = 'Channel plot select';
            app.ChannelplotselectDropDown = uidropdown(app.Plotting);
            app.ChannelplotselectDropDown.Items = {'0', '1', '16', '17', '18', '19', '20', '21'};
            app.ChannelplotselectDropDown.Value = '0';
            app.PostOffsetSwitchLabel = uilabel(app.Plotting);
            app.PostOffsetSwitchLabel.Text = 'Post-Offset';
            app.PostOffsetSwitch = uiswitch(app.Plotting, 'toggle');
            app.PostOffsetPeriodSpinnerLabel = uilabel(app.Plotting);
            app.PostOffsetPeriodSpinnerLabel.Text = 'Post-Offset Period';
            app.PostOffsetPeriodSpinner = uispinner(app.Plotting);
            app.PostOffsetPeriodSpinner.Step = 0.5;
            app.PostOffsetPeriodSpinner.Limits = [0 Inf];
            app.SettingsTab = uitab(app.TabGroup2);
            app.SettingsTab.Title = 'Settings';
            app.calibrationMatrixTable = uitable(app.SettingsTab);
            app.calibrationMatrixTable.ColumnName = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.calibrationMatrixTable.ColumnRearrangeable = 'on';
            app.calibrationMatrixTable.RowName = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.calibrationMatrixTable.ColumnEditable = true;
            app.RotationalMatrixLabel = uilabel(app.SettingsTab);
            app.RotationalMatrixLabel.Text = 'Rotational Matrix';
            app.TranslationalMatrixLabel = uilabel(app.SettingsTab);
            app.TranslationalMatrixLabel.Text = 'Translational Matrix';
            app.CalibrationMatrixLabel = uilabel(app.SettingsTab);
            app.CalibrationMatrixLabel.Text = 'Calibration Matrix';
            app.UpdateMatricesButton = uibutton(app.SettingsTab, 'push');
            app.UpdateMatricesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateMatricesButtonPushed, true);
            app.UpdateMatricesButton.Text = 'Update Matrices';
            app.ReloadMatricesButton = uibutton(app.SettingsTab, 'push');
            app.ReloadMatricesButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadMatricesButtonPushed, true);
            app.ReloadMatricesButton.Text = 'Reload Matrices';
            app.translationMatrixTable = uitable(app.SettingsTab);
            app.translationMatrixTable.ColumnName = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.translationMatrixTable.ColumnRearrangeable = 'on';
            app.translationMatrixTable.RowName = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.translationMatrixTable.ColumnEditable = true;
            app.rotationMatrixTable = uitable(app.SettingsTab);
            app.rotationMatrixTable.ColumnName = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.rotationMatrixTable.ColumnRearrangeable = 'on';
            app.rotationMatrixTable.RowName = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.rotationMatrixTable.ColumnEditable = true;
            app.MinMaxYTable = uitable(app.SettingsTab);
            app.MinMaxYTable.ColumnName = {'Min Y'; 'Max Y'};
            app.MinMaxYTable.RowName = {'0, 1, 2'};
            app.MinMaxYTable.DisplayDataChangedFcn = createCallbackFcn(app, @MinMaxYTableDisplayDataChanged, true);
            app.calibratedMatrixTable = uitable(app.SettingsTab);
            app.calibratedMatrixTable.ColumnName = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.calibratedMatrixTable.ColumnRearrangeable = 'on';
            app.calibratedMatrixTable.RowName = {'0'; '1'; '2'; '3'; '4'; '5'};
            app.calibratedMatrixTable.ColumnEditable = true;
            app.CalibratedMatrixLabel = uilabel(app.SettingsTab);
            app.CalibratedMatrixLabel.Text = 'Calibrated Matrix';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotionControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end