classdef MotionControl_DevelopingCamera < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ForceTransducer                 matlab.ui.container.Tab
        CamerastatsPanel                matlab.ui.container.Panel
        StatusText_2                    matlab.ui.control.Label
        StatusLabel_5                   matlab.ui.control.Label
        CapturetimerLabelValue_2        matlab.ui.control.Label
        CapturetimerLabel_2             matlab.ui.control.Label
        ExportcontrolPanel              matlab.ui.container.Panel
        EncodevideoStatus               matlab.ui.control.Label
        EncodevideosButton              matlab.ui.control.Button
        StatusLabel_4                   matlab.ui.control.Label
        ExporttomatButton               matlab.ui.control.Button
        ExportmatStatus                 matlab.ui.control.Label
        ExportcsvStatus                 matlab.ui.control.Label
        ExporttocsvButton               matlab.ui.control.Button
        NIDAQcontrolPanel               matlab.ui.container.Panel
        CaptureButton                   matlab.ui.control.StateButton
        AcquireSwitch                   matlab.ui.control.Switch
        AcquireSwitchLabel              matlab.ui.control.Label
        ArmedLamp                       matlab.ui.control.Lamp
        ArmedLampLabel                  matlab.ui.control.Label
        ArmButton                       matlab.ui.control.Button
        CameracontrolPanel              matlab.ui.container.Panel
        ClosecamerasButton              matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        TriggerButton                   matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        UpdateparamsButton              matlab.ui.control.Button
        SetupvideosButton               matlab.ui.control.Button
        SaveasPanel                     matlab.ui.container.Panel
        AdddatetimetonameCheckBox       matlab.ui.control.CheckBox
        Videoname2EditField             matlab.ui.control.EditField
        Videoname2EditFieldLabel        matlab.ui.control.Label
        Videoname1EditField             matlab.ui.control.EditField
        Videoname1EditFieldLabel        matlab.ui.control.Label
        TrialNumberField                matlab.ui.control.Spinner
        TrialLabel                      matlab.ui.control.Label
        SubjectNameEditField            matlab.ui.control.EditField
        SubjectnameLabel                matlab.ui.control.Label
        VariableNameEditField           matlab.ui.control.EditField
        VariablenameEditFieldLabel      matlab.ui.control.Label
        CamerasavingDIREditField        matlab.ui.control.EditField
        CamerasavingDIREditFieldLabel   matlab.ui.control.Label
        NIDAQsavingDIREditField         matlab.ui.control.EditField
        NIDAQsavingDIREditFieldLabel    matlab.ui.control.Label
        CamerasavinginthesamedirectoryasNIDAQCheckBox  matlab.ui.control.CheckBox
        MinMaxYTable                    matlab.ui.control.Table
        NIDAQstatsPanel                 matlab.ui.container.Panel
        StatusText                      matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        TriggerChannelLabelValue        matlab.ui.control.Label
        CapturetimerLabelValue          matlab.ui.control.Label
        TriggerchannelLabel             matlab.ui.control.Label
        CapturetimerLabel               matlab.ui.control.Label
        FIFOCountValue                  matlab.ui.control.Label
        FIFOcountLabel                  matlab.ui.control.Label
        BufferSizeValue                 matlab.ui.control.Label
        BuffersizeLabel                 matlab.ui.control.Label
        TabGroup_2                      matlab.ui.container.TabGroup
        SubplotView                     matlab.ui.container.Tab
        SubplotAxesPanel                matlab.ui.container.Panel
        CombinedPlotView                matlab.ui.container.Tab
        LiveDataAxes                    matlab.ui.control.UIAxes
        VectorViewTab                   matlab.ui.container.Tab
        VectorAxesPanel                 matlab.ui.container.Panel
        TriggerparametersPanel          matlab.ui.container.Panel
        TriggerChannel                  matlab.ui.control.Spinner
        TriggerChannelLabel             matlab.ui.control.Label
        TriggerVoltage                  matlab.ui.control.Spinner
        TriggerVoltageVLabel            matlab.ui.control.Label
        OffsetDurationField             matlab.ui.control.Spinner
        OffsetDurationsLabel            matlab.ui.control.Label
        CaptureLengthField              matlab.ui.control.Spinner
        DurationsLabel_2                matlab.ui.control.Label
        TriggerCondition                matlab.ui.control.DropDown
        TriggerConditionDropDownLabel   matlab.ui.control.Label
        SetupequipmentsPanel            matlab.ui.container.Panel
        LiveplotsCheckBox               matlab.ui.control.CheckBox
        Synccam1and2settingsButton      matlab.ui.control.Button
        CombineFrameupdate              matlab.ui.control.Spinner
        DurationSpinner_3Label_3        matlab.ui.control.Label
        CombineFramerate                matlab.ui.control.Spinner
        DurationSpinner_3Label_2        matlab.ui.control.Label
        SamedurationasNIDAQCheckBox     matlab.ui.control.CheckBox
        CombineDuration                 matlab.ui.control.Spinner
        DurationSpinner_3Label          matlab.ui.control.Label
        SynccameraandNIDAQCheckBox      matlab.ui.control.CheckBox
        Camera2CheckBox                 matlab.ui.control.CheckBox
        Camera1CheckBox                 matlab.ui.control.CheckBox
        NIDAQCheckBox                   matlab.ui.control.CheckBox
        NIDAQparametersPanel            matlab.ui.container.Panel
        PlotColumnCount                 matlab.ui.control.NumericEditField
        PlotcolumnsLabel                matlab.ui.control.Label
        Rate                            matlab.ui.control.NumericEditField
        DurationsEditField_2Label_2     matlab.ui.control.Label
        DeviceName                      matlab.ui.control.EditField
        DeviceEditFieldLabel            matlab.ui.control.Label
        ChannelsList                    matlab.ui.control.EditField
        ChannelslistEditFieldLabel      matlab.ui.control.Label
        LivePlotLengthField             matlab.ui.control.NumericEditField
        PlottingtimesEditFieldLabel     matlab.ui.control.Label
        BiasTick                        matlab.ui.control.CheckBox
        CalibrationTick                 matlab.ui.control.CheckBox
        Camera2parametersPanel          matlab.ui.container.Panel
        ControlPanel_2                  matlab.ui.container.Panel
        PreviewCam_2                    matlab.ui.control.Button
        NotreadyLabel_2                 matlab.ui.control.Label
        StatusLabel_3                   matlab.ui.control.Label
        FrameUpdateSpinner_2            matlab.ui.control.Spinner
        FrameUpdateSpinner_2Label       matlab.ui.control.Label
        DurationSpinner_2               matlab.ui.control.Spinner
        DurationSpinner_2Label          matlab.ui.control.Label
        PropertiesPanel_2               matlab.ui.container.Panel
        ExposureSpinner_2               matlab.ui.control.Spinner
        ExposureSpinner_2Label          matlab.ui.control.Label
        TriggertypeDropDown_2           matlab.ui.control.DropDown
        TriggertypeDropDown_2Label      matlab.ui.control.Label
        GainSpinner_2                   matlab.ui.control.Spinner
        GainSpinner_2Label              matlab.ui.control.Label
        FramerateSpinner_2              matlab.ui.control.Spinner
        FramerateSpinner_2Label         matlab.ui.control.Label
        ROIPanel_2                      matlab.ui.container.Panel
        HeightSpinner_2                 matlab.ui.control.Spinner
        HeightSpinner_2Label            matlab.ui.control.Label
        WidthSpinner_2                  matlab.ui.control.Spinner
        WidthSpinner_2Label             matlab.ui.control.Label
        YoffsetSpinner_2                matlab.ui.control.Spinner
        YoffsetSpinner_2Label           matlab.ui.control.Label
        XoffsetSpinner_2                matlab.ui.control.Spinner
        XoffsetSpinner_2Label           matlab.ui.control.Label
        CameraprofileDropDown_2         matlab.ui.control.DropDown
        CameraprofileDropDown_2Label    matlab.ui.control.Label
        CameranameEditField_2           matlab.ui.control.EditField
        CameranameEditField_2Label      matlab.ui.control.Label
        Camera1parametersPanel          matlab.ui.container.Panel
        ControlPanel                    matlab.ui.container.Panel
        PreviewCam_1                    matlab.ui.control.Button
        NotreadyLabel                   matlab.ui.control.Label
        StatusLabel_2                   matlab.ui.control.Label
        FrameUpdateSpinner              matlab.ui.control.Spinner
        FrameUpdateSpinnerLabel         matlab.ui.control.Label
        DurationSpinner                 matlab.ui.control.Spinner
        DurationSpinnerLabel            matlab.ui.control.Label
        PropertiesPanel                 matlab.ui.container.Panel
        ExposureSpinner                 matlab.ui.control.Spinner
        ExposureSpinnerLabel            matlab.ui.control.Label
        TriggertypeDropDown             matlab.ui.control.DropDown
        TriggertypeDropDownLabel        matlab.ui.control.Label
        GainSpinner                     matlab.ui.control.Spinner
        GainSpinnerLabel                matlab.ui.control.Label
        FramerateSpinner                matlab.ui.control.Spinner
        FramerateSpinnerLabel           matlab.ui.control.Label
        ROIPanel                        matlab.ui.container.Panel
        HeightSpinner                   matlab.ui.control.Spinner
        HeightSpinnerLabel              matlab.ui.control.Label
        WidthSpinner                    matlab.ui.control.Spinner
        WidthSpinnerLabel               matlab.ui.control.Label
        YoffsetSpinner                  matlab.ui.control.Spinner
        YoffsetSpinnerLabel             matlab.ui.control.Label
        XoffsetSpinner                  matlab.ui.control.Spinner
        XoffsetSpinnerLabel             matlab.ui.control.Label
        CameraprofileDropDown           matlab.ui.control.DropDown
        CameraprofileDropDownLabel      matlab.ui.control.Label
        CameranameEditField             matlab.ui.control.EditField
        CameranameEditFieldLabel        matlab.ui.control.Label
        CaptureDataAxes                 matlab.ui.control.UIAxes
        CameraControlTab                matlab.ui.container.Tab
        PreOffsetSwitch                 matlab.ui.control.ToggleSwitch
        PreOffsetSwitchLabel            matlab.ui.control.Label
        ApplycalibrationButton          matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        DataPlotPanel                   matlab.ui.container.Panel
        ChannelplotselectDropDown       matlab.ui.control.DropDown
        ChannelplotselectDropDownLabel  matlab.ui.control.Label
        PlotChosenChannel               matlab.ui.control.Button
        PostOffsetSwitch                matlab.ui.control.ToggleSwitch
        PostOffsetSwitchLabel           matlab.ui.control.Label
        PostOffsetPeriodSpinner         matlab.ui.control.Spinner
        PostOffsetPeriodSpinnerLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        %% NIDAQ stuffs
        DAQ                      % Handle to DAQ object
        DevicesInfo              % Array of devices that provide analog input voltage or audio input measurements
        TimestampsFIFOBuffer     % Timestamps FIFO buffer used for live plot of latest "N" seconds of acquired data
        DataFIFOBuffer           % Data FIFO buffer used for live plot of latest "N" seconds of acquired data
        FIFOMaxSize = 1E+7;      % Maximum allowed FIFO buffer size for DataFIFOBuffer and TimestampsFIFOBuffer
        LivePlotsLineSubplot     % Handle to line plot of acquired data
        LivePlotsLineCombined    % Handle to line plot of single plot of acquired data
        LiveVectorPointSubplot    % List of scatter point in vector view
        CapturePlotLine          % Handle to line plot of capture data
        DataPlotsLine             % Handle to line plot of captured data
        TrigActive = false;      % Trigger detected flag
        TrigMoment = [];         % Relative timestamp (s) of detected trigger event
        CaptureTimestamps        % Captured data timestamps (s)
        CaptureData              % Captured data
        CaptureDataCalibrate     % Calibrated captured data
        Timestamps = [];         % Latest timestamps values used for trigger detection
        Data = [];               % Latest data values used for trigger detection       
        CallbackTimeSpan         % Timespan of data read in ScansAvailable callback
        TriggerDelay = 0         % Trigger delay (s)
        CaptureDuration          % Capture duration (s)
        ViewTimeWindow           % Live view timespan (s)
        CaptureStartMoment       % Relative timestamp of capture start moment (s)
        TimerValue               % Time value
        BufferSize               % Size of the data FIFO buffer
        TriggerDelayMax = 3600;  % Upper limit for trigger delay (s)
        CurrentNIDAQState = '';  % App current state       
        ChannelNumbers = [];     % List of channels in array
        LiveAxesList             % List of live channels' plots
        DataAxesList             % List of captured data's plots
        CaptureAxesList = [];    % List of captured channels' plots
        ChannelCount             % Number of recording channels
        ChannelIndexLU           % Channel lookup table
        OffsetData               % Offset data
        OffsetDataRaw            % Offset data raw
        OffsetStartMoment        % Offset starting moment
        OffsetDuration           % Offset duration
        CalibratedMatrix         % Calibrated matrix
        SGChannelList            % List of strain gage from channel list
        LiveVectorAxesList       % List of subplot axes in vector view
        SGChannelCheck           % Check if channels 16-18 presented
        CaptureDataRaw           % Capture data raw
        NameForceList            % List of force components name
        CurrentPlottingData      % Contain currently plotted data

        %% Camera stuffs
        CurrentCameraState = ''  % Configuration state of camera
        VideoSetupBool_1         % State of video 1's setup
        VideoSetupBool_2         % State of video 2's setup
        CameraAllCaptureDuration  % Capture duration to be used by all cameras
        VideoObject_1            % Video object for camera 1
        VideoObject_2            % Video object for camera 2
        VideoExport_1            % Video writer object for camera 1
        VideoExport_2            % Video writer object for camera 2
        TimestampFile_1          % Timestamp for camera 1
        TimestampFile_2          % Timestamp for camera 2
        FramesPerTrigger         % Number of frames to be captured
        TimestampfFile_1         % F-file of the timestamp file for camera 1
        TimestampfFile_2         % F-file of the timestamp file for camera 2
    end
    
    methods (Access = private)
        
        function setNIDAQViewState(app, state)
            switch state                
                    
                case 'Unarm'
                    app.DeviceName.Enable = 'on';
                    app.AcquireSwitch.Enable = 'off';
                    app.ChannelsList.Enable = 'on';
                    app.Rate.Enable = 'on';
                    app.CaptureLengthField.Enable = 'on';
                    app.LivePlotLengthField.Enable = 'on';
                    app.SubjectNameEditField.Enable = 'on';
                    app.VariableNameEditField.Enable = 'on';
                    app.ExporttomatButton.Enable = 'on';
                    app.ExporttocsvButton.Enable = 'on';                    
                    app.ExportcsvStatus.Text = 'Do not export without capturing data';
                    app.ExportmatStatus.Text = 'Do not export without capturing data';

                    app.CaptureButton.Enable = 'off';
                    app.StatusText.Text = 'Configurating...';
                    app.CaptureButton.Text = 'Capture';
                    app.CaptureButton.Value = 0;

                    app.OffsetDurationField.Enable = 'off';
                    app.ArmedLamp.Color = 'red';
                    app.ArmButton.Enable = 'on';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'on';
                    app.BiasTick.Enable = 'on';

                case 'Arming'
                    app.DeviceName.Enable = 'off';
                    app.ChannelsList.Enable = 'off';
                    app.Rate.Enable = 'off';
                    app.CaptureLengthField.Enable = 'off';
                    app.LivePlotLengthField.Enable = 'on';
                    app.SubjectNameEditField.Enable = 'on';
                    app.VariableNameEditField.Enable = 'on';
                    app.ExporttomatButton.Enable = 'on';
                    app.ExporttocsvButton.Enable = 'on';
                    app.ExportcsvStatus.Text = 'Do not export without capturing data';
                    app.ExportmatStatus.Text = 'Do not export without capturing data';
                    app.CaptureButton.Enable = 'off';
                    app.CaptureButton.BackgroundColor = [0.0745 0.6235 1];
                    app.OffsetDurationField.Enable = 'on';
                    app.CaptureLengthField.Enable = 'on';
                    app.StatusText.Text = 'Arming ...';
                    app.ArmedLamp.Color = 'yellow';
                    app.ArmButton.Enable = 'off';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.BiasTick.Enable = 'off';


                case 'Armed'
                    app.DeviceName.Enable = 'on';
                    app.AcquireSwitch.Enable = 'on';
                    app.AcquireSwitch.Value = 'Stop';
                    app.ChannelsList.Enable = 'on';
                    app.Rate.Enable = 'on';
                    app.CaptureLengthField.Enable = 'on';
                    app.LivePlotLengthField.Enable = 'on';
                    app.SubjectNameEditField.Enable = 'on';
                    app.VariableNameEditField.Enable = 'on';
                    app.ExporttomatButton.Enable = 'on';
                    app.ExporttocsvButton.Enable = 'on';
                    app.ExportcsvStatus.Text = 'Do not export without capturing data';
                    app.ExportmatStatus.Text = 'Do not export without capturing data';

                    app.CaptureButton.Enable = 'off';
                    app.StatusText.Text = 'Armed. Ready for capture!';
                    
                    app.OffsetDurationField.Enable = 'on';
                    app.ArmedLamp.Color = 'green';
                    app.ArmButton.Enable = 'on';
                    app.BiasTick.Enable = 'on';

                case 'Acquisition.Buffering'
                    app.DeviceName.Enable = 'off';
                    app.ChannelsList.Enable = 'off';
                    app.Rate.Enable = 'off';
                    app.CaptureLengthField.Enable = 'off';
                    app.LivePlotLengthField.Enable = 'on';
                    app.SubjectNameEditField.Enable = 'on';
                    app.VariableNameEditField.Enable = 'on';
                    app.CaptureButton.Enable = 'off';
                    app.CaptureButton.BackgroundColor = [0.0745 0.6235 1];
                    app.ArmButton.Enable = 'off';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.BiasTick.Enable = 'on';

                    app.OffsetDurationField.Enable = 'on';
                    app.CaptureLengthField.Enable = 'on';
                    app.ExporttomatButton.Enable = 'off';
                    app.ExporttocsvButton.Enable = 'off';
                    app.ExportcsvStatus.Text = 'Not available';
                    app.ExportmatStatus.Text = 'Not available';
                    app.StatusText.Text = 'Buffering pre-trigger data...';
                
                case 'Acquisition.CollectingOffset'
                    app.DeviceName.Enable = 'off';
                    app.ChannelsList.Enable = 'off';
                    app.Rate.Enable = 'off';
                    app.CaptureLengthField.Enable = 'off';
                    app.LivePlotLengthField.Enable = 'on';
                    app.SubjectNameEditField.Enable = 'on';
                    app.VariableNameEditField.Enable = 'on';
                    app.CaptureButton.Enable = 'off';
                    app.CaptureButton.BackgroundColor = [0.0745 0.6235 1];
                    app.ArmButton.Enable = 'off';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.BiasTick.Enable = 'off';

                    app.OffsetDurationField.Enable = 'on';
                    app.CaptureLengthField.Enable = 'on';
                    app.ExporttomatButton.Enable = 'off';
                    app.ExporttocsvButton.Enable = 'off';
                    app.ExportcsvStatus.Text = 'Not available';
                    app.ExportmatStatus.Text = 'Not available';
                    app.StatusText.Text = 'Buffering pre-trigger data...';

                case 'Acquisition.ReadyForCapture'
                    app.CaptureButton.Enable = 'on';
                    app.OffsetDurationField.Enable = 'on';
                    app.ArmButton.Enable = 'on';
                    app.CaptureLengthField.Enable = 'on';
                    app.StatusText.Text = 'Ready for capture';
                    app.TrigActive = 0;
                    app.CaptureButton.Text = 'Capture';
                    app.CaptureButton.Enable = 'on';
                    app.CaptureButton.BackgroundColor = [0.0745 0.6235 1];
                    app.PlotButton.Enable = 'on';
                    app.PlotChosenChannel.Enable = 'on';
                    app.ChannelplotselectDropDown.Enable = 'on';
                    app.ApplycalibrationButton.Enable = 'on';
                    app.CaptureButton.Value = 0;
                    app.SubjectNameEditField.Enable = 'on';
                    app.VariableNameEditField.Enable = 'on';
                    app.ExporttomatButton.Enable = 'on';
                    app.ExporttocsvButton.Enable = 'on';
                    app.ExportcsvStatus.Text = 'Do not export without capturing data';
                    app.ExportmatStatus.Text = 'Do not export without capturing data';
                    app.BiasTick.Enable = 'on';

                case 'Capture.CollectingOffset'
                    app.StatusText.Text = 'Collecting offset...';
                    app.OffsetDurationField.Enable = 'off';
                    app.CaptureLengthField.Enable = 'off';
                    app.ArmButton.Enable = 'off';
                    app.CaptureButton.Text = 'Cancel capture';
                    app.CaptureButton.BackgroundColor = [1 0 0];
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.SubjectNameEditField.Enable = 'off';
                    app.VariableNameEditField.Enable = 'off';
                    app.ExporttomatButton.Enable = 'off';
                    app.ExporttocsvButton.Enable = 'off';
                    app.ExportcsvStatus.Text = 'Not available';
                    app.ExportmatStatus.Text = 'Not available';

                case 'Capture.LookingForTrigger'
                    app.StatusText.Text = 'Waiting for trigger...';
                    app.OffsetDurationField.Enable = 'off';
                    app.CaptureLengthField.Enable = 'off';
                    app.ArmButton.Enable = 'off';
                    app.CaptureButton.Text = 'Cancel capture';
                    app.CaptureButton.BackgroundColor = [1 0 0];
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.SubjectNameEditField.Enable = 'off';
                    app.VariableNameEditField.Enable = 'off';
                    app.ExporttomatButton.Enable = 'off';
                    app.ExporttocsvButton.Enable = 'off';
                    app.ExportcsvStatus.Text = 'Not available';
                    app.ExportmatStatus.Text = 'Not available';
                    
                case 'Capture.CapturingData'
                    app.StatusText.Text = 'Trigger detected. Capturing';
                    app.ArmButton.Enable = 'off';
                    app.PlotButton.Enable = 'off';
                    app.PlotChosenChannel.Enable = 'off';
                    app.CaptureButton.Text = 'Cancel capture';
                    app.CaptureButton.BackgroundColor = [1 0 0];
                    app.ChannelplotselectDropDown.Enable = 'off';
                    app.ApplycalibrationButton.Enable = 'off';
                    app.CapturetimerLabelValue.Text = num2str(app.TimerValue);
                    app.SubjectNameEditField.Enable = 'off';
                    app.VariableNameEditField.Enable = 'off';
                    app.ExporttomatButton.Enable = 'off';
                    app.ExporttocsvButton.Enable = 'off';
                    app.ExportcsvStatus.Text = 'Not available';
                    app.ExportmatStatus.Text = 'Not available';

                case 'Capture.CaptureComplete'
                    app.StatusText.Text = 'Capture completed';
                    app.ArmButton.Enable = 'on';
                    app.PlotButton.Enable = 'on';
                    app.PlotChosenChannel.Enable = 'on';
                    app.ChannelplotselectDropDown.Enable = 'on';
                    app.ApplycalibrationButton.Enable = 'on';
                    app.CapturetimerLabelValue.Text = '0';
                    app.SubjectNameEditField.Enable = 'on';
                    app.VariableNameEditField.Enable = 'on';
                    app.ExporttomatButton.Enable = 'on';
                    app.ExporttocsvButton.Enable = 'on';
                    app.ExportcsvStatus.Text = 'Ready to export';
                    app.ExportmatStatus.Text = 'Ready to export';
                
                case 'DataPlot.NotAvailable'
                    

                case 'DataPlot.Available'
                    
                    
            end
            
        end
        
        function setDeviceParameters(app)
            delete(app.DAQ);
            app.DAQ = daq('ni');
            app.DAQ.Rate = app.Rate.Value;
            app.ChannelNumbers = str2num(app.ChannelsList.Value); %#ok<ST2NM> 
            app.ChannelCount = length(app.ChannelNumbers);
            app.OffsetData = zeros(1, app.ChannelCount);
            unitName = "Voltage";

            app.ChannelIndexLU = zeros(35, 1);
            indexLU = 1;
            for indexChannel = 1:length(app.ChannelNumbers)
                channelNumber = app.ChannelNumbers(indexChannel);
                if channelNumber < 16
                    channel = addinput(app.DAQ, app.DeviceName.Value, "ai" + num2str(channelNumber), unitName);
                    channel.TerminalConfig = 'SingleEnded';
                    app.ChannelIndexLU(channelNumber+1) = indexLU; 
                    indexLU = indexLU + 1;
                else
                    channel = addinput(app.DAQ, app.DeviceName.Value, "ai" + num2str(channelNumber), unitName);
                    app.ChannelIndexLU(channelNumber+1) = indexLU; 
                    indexLU = indexLU + 1;
                    channelRef = addinput(app.DAQ, app.DeviceName.Value, "ai" + num2str(channelNumber + 8), unitName);
                    app.ChannelIndexLU(channelNumber+1 + 8) = indexLU; 
                    indexLU = indexLU + 1;
                end
            end
            % Configure DAQ ScansAvailableFcn callback function
            app.DAQ.ScansAvailableFcn = @(src,event) scansAvailable_Callback(app, src, event);
            app.CallbackTimeSpan = double(app.DAQ.ScansAvailableFcnCount)/app.DAQ.Rate;

            % Calculate required FIFO data buffer size
            app.BufferSize = calculateBufferSize(app, app.CallbackTimeSpan, ...
                                                      app.LivePlotLengthField.Value, ...
                                                      app.OffsetDurationField.Value, ...
                                                      app.CaptureLengthField.Value, ...
                                                      app.DAQ.Rate);
            app.BufferSizeValue.Text = num2str(app.BufferSize);

        end
        
        function setCameraParameters(app)
            
        end
        function updateSubplotPanel(app)
            numberOfColumns = app.PlotColumnCount.Value;
            channelList = str2num(app.ChannelsList.Value); %#ok<ST2NM> 
            if mod(app.ChannelCount, numberOfColumns) > 0
                addRow = 1;
            else
                addRow = 0;
            end
            numberOfRows = floor(app.ChannelCount/numberOfColumns) + addRow;

            for indexPlot = 1:app.ChannelCount
                app.LiveAxesList{indexPlot} = subplot(numberOfRows, numberOfColumns, indexPlot, 'Parent', app.SubplotAxesPanel);
                app.LivePlotsLineSubplot{indexPlot} = plot(app.LiveAxesList{indexPlot}, NaN, NaN);

                % Setting subplot title
                channelNumber = channelList(indexPlot);
                channelName = "Channel " + num2str(channelNumber);
                title(app.LiveAxesList{indexPlot}, channelName)

                % Setting capture plot line - After capturing
                app.CapturePlotLine{indexPlot} = plot(app.CaptureDataAxes, NaN, NaN, 'DisplayName', channelName);
                hold(app.CaptureDataAxes, 'on');

                % Setting combined live plot line
                app.LivePlotsLineCombined{indexPlot} = plot(app.LiveDataAxes, NaN, NaN, 'DisplayName', channelName);
                hold(app.LiveDataAxes, 'on');
            end
            setYLimAxes(app)
            legend(app.CaptureDataAxes, 'Location', 'northeastoutside')
            
        end

        function updateVectorPlotPanel(app)
            app.SGChannelCheck = true;
            for indexSG = 16:18
                if ~any(app.ChannelNumbers(:)==indexSG)
                    app.SGChannelCheck = false;
                    return
                end
            end
            samplesToPlot = round(app.LivePlotLengthField.Value * app.Rate.Value);
            dummyData = zeros(samplesToPlot, 1);
            titleVector = ["XY"; "XZ"; "YZ"];
            for indexPlot = 2:4 % isometric view, xy view, xz view, yz view
                app.LiveVectorAxesList{indexPlot} = subplot(2, 2, indexPlot, 'Parent', app.VectorAxesPanel);
                c = linspace(1, 10, samplesToPlot);
                app.LiveVectorPointSubplot{indexPlot} = scatter(app.LiveVectorAxesList{indexPlot}, dummyData, dummyData, 10, c, "filled");
                colormap(app.LiveVectorAxesList{indexPlot}, 'jet');
                title(app.LiveVectorAxesList{indexPlot}, titleVector(indexPlot - 1));
                app.LiveVectorAxesList{indexPlot}.YLim = [-10 35];
                app.LiveVectorAxesList{indexPlot}.XLim = [-10 35];
            end
            
        end
        
        function plotParams = generatePlotParams(app)
            plotParams = struct;
            plotParams.ylabel = 'Voltage (V)';
            plotParams.ylim = [-10 10];
        end

        function updateDataPlotPanel(app, plotParams)
            numberOfColumns = app.PlotColumnCount.Value;
            channelList = str2num(app.ChannelsList.Value); %#ok<ST2NM> 
            if mod(app.ChannelCount, numberOfColumns) > 0
                addRow = 1;
            else
                addRow = 0;
            end
            numberOfRows = floor(app.ChannelCount/numberOfColumns) + addRow;
            for indexPlot = 1:app.ChannelCount
                app.DataAxesList{indexPlot} = subplot(numberOfRows, numberOfColumns, indexPlot, 'Parent', app.DataPlotPanel);
                app.DataPlotsLine{indexPlot} = plot(app.DataAxesList{indexPlot}, NaN, NaN);
                xlabel(app.DataAxesList{indexPlot}, "Time (s)");
                
                % Setting subplot title
                channelNumber = channelList(indexPlot);
                channelName = "Channel " + num2str(channelNumber) + ...
                              " - " + num2str(app.OffsetData(indexPlot), '%.4f') + " V";
                if channelNumber > 15
                    ylabel(app.DataAxesList{indexPlot}, plotParams.ylabel);
                    ylim(app.DataAxesList{indexPlot}, plotParams.ylim);
                else
                    ylabel(app.DataAxesList{indexPlot}, 'Voltage (V)');
                    ylim(app.DataAxesList{indexPlot}, [-10 10]);
                end
                title(app.DataAxesList{indexPlot}, channelName);
            end
        end


        function updateCapturePlotPanel(app)
            cla(app.CaptureDataAxes, 'reset')
            channelList = str2num(app.ChannelsList.Value); %#ok<ST2NM> 
            
            for indexPlot = 1:app.ChannelCount
                channelNumber = channelList(indexPlot);
                channelName = "Channel " + num2str(channelNumber);
                app.CapturePlotLine{indexPlot} = plot(app.CaptureDataAxes, NaN, NaN, 'DisplayName', channelName);
                hold(app.CaptureDataAxes, 'on');
            end
            legend(app.CaptureDataAxes, 'Location', 'northeastoutside')
        end


        function scansAvailable_Callback(app, src, ~)
            if ~isvalid(app)
                return
            end
            

            % Continuous acquisition data and timestamps are stored in FIFO data buffers
            % Calculate required buffer size -- this should be large enough to accomodate the
            % the data required for the live view time window and the data for the requested
            % capture duration.
            app.ViewTimeWindow = app.LivePlotLengthField.Value;
            app.OffsetDuration = app.OffsetDurationField.Value;
            app.CaptureDuration = app.CaptureLengthField.Value;
            
            [data,timestamps] = read(src, src.ScansAvailableFcnCount, 'OutputFormat','Matrix');

            % Offset straingage
            SGRawIndexList = app.ChannelIndexLU(app.SGChannelList + 1);
            SGRefIndexList = app.ChannelIndexLU(app.SGChannelList + 1 + 8);
            data(:, SGRawIndexList) = data(:, SGRawIndexList) - data(:, SGRefIndexList);

            % Store continuous acquisition data in FIFO data buffers
            app.TimestampsFIFOBuffer = storeDataInFIFO(app, app.TimestampsFIFOBuffer, app.BufferSize, timestamps);
            app.DataFIFOBuffer = storeDataInFIFO(app, app.DataFIFOBuffer, app.BufferSize, data);
            
            app.FIFOCountValue.Text = num2str(size(app.TimestampsFIFOBuffer, 1));


            % Update live plot data
            if ~app.TrigActive || (app.LiveplotsCheckBox.Value == 1 && app.TrigActive)
                samplesToPlot = min([round(app.ViewTimeWindow * src.Rate), size(app.DataFIFOBuffer,1)]);
                firstPoint = size(app.DataFIFOBuffer, 1) - samplesToPlot + 1;
                
                dataPlot = app.DataFIFOBuffer;
                if app.BiasTick.Value && app.CurrentNIDAQState == "Acquisition.ReadyForCapture"  
                    dataIndex = app.ChannelIndexLU(app.ChannelNumbers+1);
                    dataPlot(:, dataIndex) = dataPlot(:, dataIndex) - app.OffsetDataRaw(:, dataIndex);
                end
    
                if app.CalibrationTick.Value == 1
                    dataPlot(:, SGRawIndexList) = (app.CalibratedMatrix*dataPlot(:, SGRawIndexList)')';
                end
    
    
                for indexChannel = 1:app.ChannelCount
                    channelNumber = app.ChannelNumbers(indexChannel);
                    dataIndex = app.ChannelIndexLU(channelNumber+1);
                    if samplesToPlot > 1
                        xlim(app.LiveAxesList{indexChannel}, [app.TimestampsFIFOBuffer(firstPoint), app.TimestampsFIFOBuffer(end)])
                        xlim(app.LiveDataAxes, [app.TimestampsFIFOBuffer(firstPoint), app.TimestampsFIFOBuffer(end)])
                    end
                  
                    switch  app.TabGroup_2.SelectedTab.Title
                        case "Subplot View"
                            plotVectorBool = false;
                            set(app.LivePlotsLineSubplot{indexChannel}, 'XData', app.TimestampsFIFOBuffer(firstPoint:end), ...
                                                                'YData', dataPlot(firstPoint:end, dataIndex));
    
                            channelName = "Channel " + num2str(channelNumber) + " - " + num2str(mean(dataPlot(end-10:end, dataIndex)));
                            title(app.LiveAxesList{indexChannel}, channelName)
                        case "Combined Plot View"
                            plotVectorBool = false;
                            set(app.LivePlotsLineCombined{indexChannel}, 'XData', app.TimestampsFIFOBuffer(firstPoint:end), ...
                                                                'YData', dataPlot(firstPoint:end, dataIndex));
                        case "Vector View"
                            plotVectorBool = true;
                            break
                    end
                end
    
                if plotVectorBool && app.SGChannelCheck && (size(dataPlot, 1) > app.ViewTimeWindow * src.Rate)
                    channelPair = [16 17; 16 18; 17 18];
                    for indexPair = 1:3
                        dataIndex = app.ChannelIndexLU(channelPair(indexPair, :)+1);
                        set(app.LiveVectorPointSubplot{indexPair+1}, 'XData', dataPlot(firstPoint:end, dataIndex(1)), ...
                                                                     'YData', dataPlot(firstPoint:end, dataIndex(2)));
                    end
                    
                end
            end
         
            % If capture is requested, analyze latest acquired data until a trigger
            % condition is met. After enough data is acquired for a complete capture,
            % as specified by the capture duration, extract the capture data from the
            % data buffer and save it to a base workspace variable.
            
            % For trigger detection, store previous and current ScansAvailable callback data and timestamps
            if isempty(app.Timestamps)
                app.Data = data;
                app.Timestamps = timestamps;
            else
                app.Data = [app.Data(end, :); data];
                app.Timestamps = [app.Timestamps(end); timestamps];
            end

            % App state control logic 
            switch app.CurrentNIDAQState
                case 'Acquisition.Buffering'
                   % Buffering pre-trigger data
                    if isEnoughDataBuffered(app)
                        app.CurrentNIDAQState = 'Acquisition.CollectingOffset';
                        setNIDAQViewState(app, app.CurrentNIDAQState)

                        app.OffsetStartMoment = app.Timestamps(end);
                        app.OffsetDataRaw = app.Data;
                    end
                
                case 'Acquisition.CollectingOffset'
                    if isEnoughDataOffset(app)
                        app.OffsetDataRaw = mean(app.OffsetDataRaw, 1);
                        app.CurrentNIDAQState = 'Acquisition.ReadyForCapture';
                        setNIDAQViewState(app, app.CurrentNIDAQState)
                    end
                case 'Acquisition.ReadyForCapture'
                    % Ready for capture
                    if app.CaptureButton.Value
                        app.CurrentNIDAQState = 'Capture.CollectingOffset';
                        setNIDAQViewState(app, app.CurrentNIDAQState)

                        app.OffsetStartMoment = app.Timestamps(end);
                        app.OffsetDataRaw = app.Data;
                    end
                case 'Capture.CollectingOffset'
                    if isEnoughDataOffset(app)
                        app.OffsetDataRaw = mean(app.OffsetDataRaw, 1);
                        app.CurrentNIDAQState = 'Capture.LookingForTrigger';
                        setNIDAQViewState(app, app.CurrentNIDAQState)
                    end
                case 'Capture.LookingForTrigger'
                    % Looking for trigger event in the latest data
                    detectTrigger(app)
                    if app.TrigActive
                        tic;
                        app.CurrentNIDAQState = 'Capture.CapturingData';
                        setNIDAQViewState(app, app.CurrentNIDAQState)
                        if app.SynccameraandNIDAQCheckBox.Value == 1
                            triggerCamera(app);
                        end
                    end
                case 'Capture.CapturingData'
                    % Capturing data
                    % Not enough acquired data to cover capture timespan during this ScansAvailable callback execution
                    app.CapturetimerLabelValue.Text = num2str(toc);
                    if isEnoughDataCaptured(app)     
                        app.CurrentNIDAQState = 'Capture.CaptureComplete';
                        setNIDAQViewState(app, app.CurrentNIDAQState)
                        if app.SynccameraandNIDAQCheckBox.Value == 1
                            stopCamera(app);
                        end
                    end
                case 'Capture.CaptureComplete'
                    % Acquired enough data to complete capture of specified duration
                    completeCapture(app)
                    app.CurrentNIDAQState = 'Acquisition.ReadyForCapture';
                    setNIDAQViewState(app, app.CurrentNIDAQState)
            end
        end


        function data = storeDataInFIFO(~, data, buffersize, datablock)
        %storeDataInFIFO Store continuous acquisition data in a FIFO data buffer
        %  Storing data in a finite-size FIFO buffer is used to plot the latest "N" seconds of acquired data for
        %  a smooth live plot update and without continuously increasing memory use.
        %  The most recently acquired data (datablock) is added to the buffer and if the amount of data in the
        %  buffer exceeds the specified buffer size (buffersize) the oldest data is discarded to cap the size of
        %  the data in the buffer to buffersize.
        %  input data is the existing data buffer (column vector Nx1).
        %  buffersize is the desired buffer size (maximum number of rows in data buffer) and can be changed.
        %  datablock is a new data block to be added to the buffer (column vector Kx1).
        %  output data is the updated data buffer (column vector Mx1).
        
            % If the data size is greater than the buffer size, keep only the
            % the latest "buffer size" worth of data
            % This can occur if the buffer size is changed to a lower value during acquisition
            if size(data,1) > buffersize
                data = data(end-buffersize+1:end,:);
            end
            
            if size(datablock,1) < buffersize
                % Data block size (number of rows) is smaller than the buffer size
                if size(data,1) == buffersize
                    % Current data size is already equal to buffer size.
                    % Discard older data and append new data block,
                    % and keep data size equal to buffer size.
                    shiftPosition = size(datablock,1);
                    data = circshift(data,-shiftPosition);
                    data(end-shiftPosition+1:end,:) = datablock;
                elseif (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) > buffersize)
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size greater than the buffer size.
                    data = [data; datablock];
                    shiftPosition = size(data,1) - buffersize;
                    data = circshift(data,-shiftPosition);
                    data(buffersize+1:end, :) = [];
                else
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size smaller than or equal to the buffer size.
                    % (if (size(data,1) < buffersize) && (size(data,1)+size(datablock,1) <= buffersize))
                    data = [data; datablock];
                end
            else
                % Data block size (number of rows) is larger than or equal to buffer size
                data = datablock(end-buffersize+1:end,:);
            end
            
        end

        function  detectTrigger(app)
            %detectTrigger Detects trigger condition and updates relevant app properties
            % Updates TrigActive, TrigMoment, and CaptureStartMoment app properties
            
            trigConfig.Channel = find(app.ChannelNumbers==app.TriggerChannel.Value);
            trigConfig.Level = app.TriggerVoltage.Value;
            trigConfig.Condition = app.TriggerCondition.Value;
            [app.TrigActive, app.TrigMoment] = ...
                trigDetect(app, app.Timestamps, app.Data, trigConfig);
            app.CaptureStartMoment = app.TrigMoment + app.TriggerDelay;
            
        end
        
        function [trigDetected, trigMoment] = trigDetect(~, timestamps, data, trigConfig)
           %trigDetect Detect if trigger event condition is met in acquired data
            %   [trigDetected, trigMoment] = trigDetect(app, data, trigConfig)
            %   Returns a detection flag (trigDetected) and the corresponding data point index
            %   (trigMoment) of the first data point which meets the trigger condition
            %   based on signal level and condition specified by the trigger parameters
            %   structure (trigConfig).
            %   The input data (data) is an M x N matrix corresponding to M acquired
            %   data scans from N channels.
            %   trigConfig.Channel = index of trigger channel in DAQ channels
            %   trigConfig.Level   = signal trigger level
            %   trigConfig.Condition = trigger condition ('Rising' or 'Falling')
            
            switch trigConfig.Condition
                case 'Rising'
                    % Logical array condition for signal trigger level
                    trigConditionMet = data(:, trigConfig.Channel) > trigConfig.Level;
                case 'Falling'
                    % Logical array condition for signal trigger level
                    trigConditionMet = data(:, trigConfig.Channel) < trigConfig.Level;
            end
            
            trigDetected = any(trigConditionMet) & ~all(trigConditionMet);
            trigMoment = [];
            if trigDetected
                % Find time moment when trigger condition has been met
                trigMomentIndex = 1 + find(diff(trigConditionMet)==1, 1, 'first');
                trigMoment = timestamps(trigMomentIndex);
            end
            
        end

        function completeCapture(app)
        %completeCapture Saves captured data to workspace variable and plots it
            
            % Find index of first sample in data buffer to be captured
            firstSampleIndex = find(app.TimestampsFIFOBuffer >= app.CaptureStartMoment, 1, 'first');
            
            % Find index of last sample in data buffer that complete the capture
            lastSampleIndex = firstSampleIndex + round(app.CaptureLengthField.Value * app.DAQ.Rate);
            if isempty(firstSampleIndex) || isempty(lastSampleIndex) || lastSampleIndex > size(app.TimestampsFIFOBuffer, 1)
                % Something went wrong
                % Abort capture
                app.StatusText.Text = 'Capture error';
                app.CaptureButton.Value = 0;
                uialert(app.UIFigure, 'Could not complete capture.', 'Capture error');
                return
            end
            
            % Extract capture data and shift timestamps so that 0 corresponds to the trigger moment
            app.CaptureDataRaw = app.DataFIFOBuffer(firstSampleIndex:lastSampleIndex, :);
            app.CaptureTimestamps = app.TimestampsFIFOBuffer(firstSampleIndex:lastSampleIndex, :) - app.TrigMoment;
            
         
            app.CaptureData = zeros(size(app.CaptureDataRaw, 1), app.ChannelCount);
            
            % Remove ref channels from raw captured data and offset data
            for indexChannel = 1:app.ChannelCount
                channelNumber = app.ChannelNumbers(indexChannel);
                dataIndex = app.ChannelIndexLU(channelNumber+1);
                app.CaptureData(:, indexChannel) = app.CaptureDataRaw(:, dataIndex);
                if isempty(app.OffsetDataRaw)
                    app.OffsetData(1, indexChannel) = app.CaptureDataRaw(1, dataIndex);
                else
                    app.OffsetData(1, indexChannel) = app.OffsetDataRaw(:, dataIndex);
                end
            end

            % Update captured data plot (one line for each acquisition channel)
            for indexChannel = 1:app.ChannelCount
                set(app.CapturePlotLine{indexChannel}, 'XData', app.CaptureTimestamps, ...
                                                       'YData', app.CaptureData(:, indexChannel));
            end

            app.CaptureDataAxes.XLim = [min(app.CaptureTimestamps), max(app.CaptureTimestamps)];
            
            % Save capture data
            varCaptureName = app.VariableNameEditField.Value;
            saveCaptureData(app, app.CaptureData, varCaptureName);

            % Save offset data
            varOffsetName = 'OffsetData';
            assignin('base', varOffsetName, app.OffsetData);
            
        end

        function plotCaptureData(app, data)
            if isempty(app.CaptureData) || isempty(app.CaptureTimestamps)
                return
            end
            app.CurrentPlottingData = data;
            
            dataYLim = app.MinMaxYTable.Data;
            for indexChannel = 1:app.ChannelCount
                
                xlim(app.DataAxesList{indexChannel}, [app.CaptureTimestamps(1), app.CaptureTimestamps(end)]);
                ylim(app.DataAxesList{indexChannel}, [dataYLim(indexChannel, 1), dataYLim(indexChannel, 2)]);
                set(app.DataPlotsLine{indexChannel}, 'XData', app.CaptureTimestamps(:), ...
                                                     'YData', data(:, indexChannel));
            end
        end
        
        function data = applyOffset(app, data)
            if isempty(app.CaptureData) || isempty(app.CaptureTimestamps)
                return
            end
            %% Process pre-offset data
            if app.PreOffsetSwitch.Value == "Off"
                offsetDataSelect = zeros(size(app.OffsetData));
            else
                offsetDataSelect = app.OffsetData;
            end
            for indexChannel = 1:app.ChannelCount
                data(:, indexChannel) = data(:, indexChannel) - offsetDataSelect(:, indexChannel);
            end

            %% Process post-offset data
            if app.PostOffsetSwitch.Value == "Off"
                return
            end
            postOffsetDataCount = round(app.PostOffsetPeriodSpinner.Value * app.Rate.Value);
            if postOffsetDataCount == 0
                return
            end
            for indexChannel = 1:app.ChannelCount
                postOffsettingValue = mean(data(1:postOffsetDataCount, indexChannel), 1);
                data(:, indexChannel) = data(:, indexChannel) - postOffsettingValue;
            end

        end

        function [buffersize, bufferTimeSpan] = calculateBufferSize(~, callbackTimeSpan, liveViewTimeSpan, triggerDelay, captureDuration, rate)
        %calculateBufferSize Calculates the required FIFO data buffer size 
        %  callbackTimeSpan is the wall-clock timespan (seconds) of the data read in the DAQ ScansAvailable callback function.
        %  liveViewTimeSpan is the wall-clock timespan (seconds) of the DAQ data in the live view plot.
        %  triggerDelay is the trigger delay (seconds).
        %  captureDuration is the total capture duration (seconds).
        %  rate is the DAQ acquisition rate (scans/second).
        %  buffersize is the calculated buffer size (number of scans).
        %  bufferTimeSpan is the wall-clock timespan (seconds) corresponding to <buffersize> scans.       
        
            if triggerDelay < 0
                bufferTimeSpan = max([abs(triggerDelay), captureDuration, liveViewTimeSpan]);
            else
                bufferTimeSpan = max([captureDuration, liveViewTimeSpan]);
            end
            bufferTimeSpan = bufferTimeSpan + 2*callbackTimeSpan;
            buffersize = ceil(rate * bufferTimeSpan) + 1;
            
        end
        

        function results = isEnoughDataBuffered(app)
        %isEnoughDataBuffered Checks whether buffering pre-trigger data is complete    
       
            % If specified trigger delay is less than 0, need to check
            % whether enough pre-trigger data is buffered so that a
            % triggered capture can be requested
            
            results = (app.TriggerDelay >= 0) || ...
                (size(app.TimestampsFIFOBuffer,1) > ceil(abs(app.TriggerDelay)*app.DAQ.Rate));
            
        end

        function results = isEnoughDataCaptured(app)
        %isEnoughDataCaptured Check whether captured-data duration exceeds specified capture duration

            results = (app.TimestampsFIFOBuffer(end,1)-app.CaptureStartMoment) > app.CaptureDuration;
        end

        function results = isEnoughDataOffset(app)
            app.OffsetDataRaw = [app.OffsetDataRaw; app.Data];
            results = (app.TimestampsFIFOBuffer(end,1)-app.OffsetStartMoment) > app.OffsetDuration;
        end

        function results = doesTriggerDelayChangeRequireBuffering(app)
        
            value = app.TriggerDelayEditField.Value;
            if value < 0 && size(app.TimestampsFIFOBuffer,1) < ceil(abs(value)*app.DAQ.Rate)
                results = true;
            else
                results = false;
            end            
        end

        function setMinMaxYTableDefault(app, minY, maxY)
            RowName = {};
            app.MinMaxYTable.ColumnEditable = [true, true, true];
            channelList = str2num(app.ChannelsList.Value); %#ok<ST2NM> 
            for indexChannel = 1:app.ChannelCount
                channelNumber = channelList(indexChannel);
                RowName{indexChannel} = "Channel " + num2str(channelNumber);
                
                app.MinMaxYTable.Data(indexChannel, 1) = minY;
                app.MinMaxYTable.Data(indexChannel, 2) = maxY;
                if channelNumber > 15
                    app.MinMaxYTable.Data(indexChannel, 3) = "Strain gauge " + num2str(channelNumber - 15);
                else
                    app.MinMaxYTable.Data(indexChannel, 3) = RowName{indexChannel};
                end
             
            end
            app.MinMaxYTable.RowName = RowName;

        end

        function setYLimAxes(app)
            data = app.MinMaxYTable.Data;
            for indexChannel = 1:app.ChannelCount
                app.LiveAxesList{indexChannel}.YLim = [data(indexChannel, 1), data(indexChannel, 2)];
                app.DataAxesList{indexChannel}.YLim = [data(indexChannel, 1), data(indexChannel, 2)];
            end
        end

        function flipAcquire(app, value)
            if value == "Start"
                % Reset FIFO buffer data
                app.DataFIFOBuffer = [];
                app.TimestampsFIFOBuffer = [];
    
                % Reset Data and Timestamps
                app.Data = [];
                app.Timestamps = [];
                

                try
                    start(app.DAQ,'continuous');
                    
                    % Configure the app for Acquisition state
                    app.CurrentNIDAQState = 'Acquisition.Buffering';
                    setNIDAQViewState(app, app.CurrentNIDAQState);
               
                    setYLimAxes(app)               
                catch exception
                    uialert(app.UIFigure, exception.message, 'Start error');   
                end
            elseif value == "Stop"
                stop(app.DAQ);

                app.DataFIFOBuffer = [];
                app.TimestampsFIFOBuffer = [];
                % Configure the app for device configuration state
                app.CurrentNIDAQState = 'Armed';
                setNIDAQViewState(app, app.CurrentNIDAQState);
            end

        end
        
        function calculateCalibratedMatrix(app)
            calibrationMatrix = load('calibrationMatrixATI.mat');
            toolMatrix = load('toolTransMatrix.mat');
            app.CalibratedMatrix = toolMatrix.toolTransMatrix*calibrationMatrix.calibrationMatrixATI;
            assignin('base', 'calibratedMatrix', app.CalibratedMatrix);
        end

        function applyCalibration(app, data)
            indexSG = find(app.ChannelNumbers>15);
            countSG = size(indexSG, 2);
            captureSG= zeros(size(data, 1), countSG);
            indexCurrentSG = 1;
            for indexChannel = 1:app.ChannelCount
                channelNumber = app.ChannelNumbers(indexChannel);
                if channelNumber >= 16
                    captureSG(:, indexCurrentSG) = data(:, indexChannel);
                    indexCurrentSG = indexCurrentSG + 1;
                end
            end
            calibratedSG = app.CalibratedMatrix*captureSG';
            app.CaptureDataCalibrate = data;
            app.CaptureDataCalibrate(:, indexSG) = calibratedSG';

            varCaptureName = char(string(app.VariableNameEditField.Value) + "_force");
            saveCaptureData(app, app.CaptureDataCalibrate, varCaptureName);

        end
        
        function captureData = saveCaptureData(app, data, varnameInput, options)
            % Save captured data to a base workspace variable
            % For simplicity, validation of user input and checking whether a variable
            % with the same name already exists are not addressed in this example.
            % Get the variable name from UI text input
            arguments
                app
                data
                varnameInput
                options.force (1, 1) {mustBeNumericOrLogical} = false
                options.toWorkspace (1, 1) {mustBeNumericOrLogical} = true
                options.timetable (1, 1) {mustBeNumericOrLogical} = true
            end
            columnNames = {};
            channelList = str2num(app.ChannelsList.Value); %#ok<ST2NM> 
            for indexChannel = 1:app.ChannelCount
                channelNumber = channelList(indexChannel);
                if channelNumber >= 16 && options.force
                    columnNames{indexChannel} = char(app.NameForceList{channelNumber-15});
                else
                    columnNames{indexChannel} = char("Channel " + num2str(channelNumber));
                end
            end
            if app.PreOffsetSwitch.Value == "Off"
                varName = genvarname(char(string(varnameInput) + "_woOffset"));
            else
                varName = genvarname(char(string(varnameInput) + "_wOffset"));
            end
            if options.timetable
                captureData = timetable(seconds(app.CaptureTimestamps), data, 'VariableNames', {varName});
            else
                columnName = [{'Timestamps'}, varName];
                captureData = table(app.CaptureTimestamps, data, 'VariableNames', columnName);
            end
            captureData = splitvars(captureData, varName, 'NewVariableNames', columnNames);
            
            % Use assignin function to save the captured data in a base workspace variable
            if options.toWorkspace
                assignin('base', varName, captureData);
            end
            
        end
        
        %% Camera stuffs
        function setCameraViewState(app, state)
            switch state
                case 'Unarm' % Ground state
                    app.UpdateparamsButton.Enable = 'off';
                    app.SetupvideosButton.Enable = 'on';
                    app.StartButton.Enable = 'off';
                    app.TriggerButton.Enable = 'off';
                    app.StopButton.Enable = 'off';
                    app.ClosecamerasButton.Enable = 'off';
                    app.SynccameraandNIDAQCheckBox.Enable = 'on';
                    if app.Camera1CheckBox.Value == 1
                        app.PreviewCam_1.Enable = 'off';
                    end
                    if app.Camera2CheckBox.Value == 1
                        app.PreviewCam_2.Enable = 'off';
                    end
                    app.StatusText_2.Text = 'Standing by';
                case 'Standby.Video' % Videos are already set
                    app.UpdateparamsButton.Enable = 'on';
                    app.SetupvideosButton.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.TriggerButton.Enable = 'off';
                    app.StopButton.Enable = 'off';
                    app.ClosecamerasButton.Enable = 'off';
                    app.SynccameraandNIDAQCheckBox.Enable = 'on';
                    if app.Camera1CheckBox.Value == 1
                        app.PreviewCam_1.Enable = 'off';
                    end
                    if app.Camera2CheckBox.Value == 1
                        app.PreviewCam_2.Enable = 'off';
                    end
                    app.StatusText_2.Text = 'Video file is initialized. Can start preparing the parameters now!';
                case 'Standby.Camera' % Camera parameters are already set
                    app.UpdateparamsButton.Enable = 'on';
                    app.SetupvideosButton.Enable = 'off';
                    app.StartButton.Enable = 'on';
                    app.TriggerButton.Enable = 'off';
                    app.StopButton.Enable = 'off';
                    app.ClosecamerasButton.Enable = 'on';
                    app.SynccameraandNIDAQCheckBox.Enable = 'on';
                    if app.Camera1CheckBox.Value == 1
                        app.PreviewCam_1.Enable = 'off';
                    end
                    if app.Camera2CheckBox.Value == 1
                        app.PreviewCam_2.Enable = 'off';
                    end
                    app.StatusText_2.Text = 'Camera parameters are loaded. The camera is ready!';
                case 'Standby.Ready' % Camera is running
                    app.UpdateparamsButton.Enable = 'off';
                    app.SetupvideosButton.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.SynccameraandNIDAQCheckBox.Enable = 'on';
                    app.Synccam1and2settingsButton.Enable = 'off';
                    if app.SynccameraandNIDAQCheckBox.Value == 0
                        app.TriggerButton.Enable = 'on';
                    elseif app.SynccameraandNIDAQCheckBox.Value == 1
                        app.TriggerButton.Enable = 'off';
                    end
                    app.StopButton.Enable = 'on';
                    app.ClosecamerasButton.Enable = 'on';
                    if app.Camera1CheckBox.Value == 1
                        app.PreviewCam_1.Enable = 'on';
                    end
                    if app.Camera2CheckBox.Value == 1
                        app.PreviewCam_2.Enable = 'on';
                    end
                    app.StatusText_2.Text = 'Camera is running. Waiting for trigger ...';
                case 'Capture.Busy'
                    app.UpdateparamsButton.Enable = 'off';
                    app.SetupvideosButton.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.TriggerButton.Enable = 'off';
                    app.StopButton.Enable = 'on';
                    app.ClosecamerasButton.Enable = 'off';
                    app.SynccameraandNIDAQCheckBox.Enable = 'off';
                    app.Synccam1and2settingsButton.Enable = 'off';
                    if app.Camera1CheckBox.Value == 1
                        app.PreviewCam_1.Enable = 'off';
                    end
                    if app.Camera2CheckBox.Value == 1
                        app.PreviewCam_2.Enable = 'off';
                    end
                    app.StatusText_2.Text = 'Triggers received. Capturing the signal ...';
                case 'Capture.Cleaning Up'
                    app.UpdateparamsButton.Enable = 'off';
                    app.SetupvideosButton.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.TriggerButton.Enable = 'off';
                    app.StopButton.Enable = 'on';
                    app.ClosecamerasButton.Enable = 'on';
                    app.SynccameraandNIDAQCheckBox.Enable = 'off';
                    app.Synccam1and2settingsButton.Enable = 'off';
                    if app.Camera1CheckBox.Value == 1
                        app.PreviewCam_1.Enable = 'off';
                    end
                    if app.Camera2CheckBox.Value == 1
                        app.PreviewCam_2.Enable = 'off';
                    end
                    app.StatusText_2.Text = 'Done capturing. Cleaning up ...';
            end
        end

        function saveCameraData(app, src, event, filename)
            [~, ~, metadata] = getdata(src, src.FramesAcquiredFcnCount);
            for indexFrame = 1:src.FramesAcquiredFcnCount
                fprintf(filename, '%i %i %i %i %i %.7f\n', metadata(indexFrame).AbsTime(1:6));
            end

        end

        function setupVideoObjects(app)
            time_start = '';
            if app.AdddatetimetonameCheckBox.Value == 1
                time_start = string(datetime('now', 'Format','dd_MMM_yyyy-HH_mm_ss'));
            end

            if app.Camera1CheckBox.Value == 1
                app.TimestampfFile_1 = fullfile(app.NIDAQsavingDIREditField.Value, ...
                                      app.Videoname1EditField.Value + "_" + time_start); 

                video_name_file = app.TimestampfFile_1 + ".avi";
                app.VideoExport_1 = VideoWriter(video_name_file, 'Grayscale AVI'); 
                app.VideoExport_1.FrameRate = app.FramerateSpinner.Value;
                app.VideoSetupBool_1 = true;
                app.TimestampFile_1 = fopen(app.TimestampfFile_1 + ".txt", 'wt');
            end
            if app.Camera2CheckBox.Value == 1

                app.TimestampfFile_2 = fullfile(app.NIDAQsavingDIREditField.Value, ...
                                      app.Videoname2EditField.Value + "_" + time_start); 
                video_name_file = app.TimestampfFile_2 + ".avi";
                app.VideoExport_2 = VideoWriter(video_name_file, 'Grayscale AVI'); 
                app.VideoExport_2.FrameRate = app.FramerateSpinner_2.Value;
                app.VideoSetupBool_2 = true;
                app.TimestampFile_2 = fopen(app.TimestampfFile_2 + ".txt", 'wt');
            end
            app.CurrentCameraState = 'Standby.Video';
            app.setCameraViewState(app.CurrentCameraState);
        end

        function updateCamera(app)
            
            if app.SamedurationasNIDAQCheckBox.Value == 1
                app.DurationSpinner.Value = app.CombineDuration.Value;
                app.DurationSpinner_2.Value = app.CombineDuration.Value;
            end

            FramesPerTrigger_Cam1 = app.DurationSpinner.Value * app.FramerateSpinner.Value;
            FramesPerTrigger_Cam2 = app.DurationSpinner_2.Value * app.FramerateSpinner_2.Value;

            if app.Camera1CheckBox.Value == 1
                app.VideoObject_1 = videoinput('tisimaq_r2013_64', '1', app.CameraprofileDropDown.Value);
                app.VideoObject_1.ReturnedColorspace = "grayscale";
                app.VideoObject_1.ROIPosition =  [app.XoffsetSpinner.Value app.YoffsetSpinner.Value app.WidthSpinner.Value app.HeightSpinner.Value];
                triggerconfig(app.VideoObject_1, 'manual');
                app.VideoObject_1.LoggingMode = 'disk&memory';
                app.VideoObject_1.DiskLogger = app.VideoExport_1;
                
                set(app.VideoObject_1, 'TriggerRepeat', 0);
                set(app.VideoObject_1, 'FramesPerTrigger', FramesPerTrigger_Cam1);
                set(app.VideoObject_1, 'FramesAcquiredFcnCount', app.FrameUpdateSpinner.Value);
                set(app.VideoObject_1, 'Timeout', max(10, 0.1*app.DurationSpinner.Value));

                src = getselectedsource(app.VideoObject_1);
                src.Exposure = 1/2^(app.ExposureSpinner.Value);
                src.Gain = app.GainSpinner.Value;
                src.FrameRate = app.FramerateSpinner.Value;
                src.GainAuto = 'Off';

                app.VideoObject_1.FramesAcquiredFcn = @(src, event) saveCameraData(app, src, event, app.TimestampFile_1);
                app.VideoObject_1.StopFcn = @(src, event) stopCamera(app);
            end
            if app.Camera2CheckBox.Value == 1
                app.VideoObject_2 = videoinput('tisimaq_r2013_64', '2', app.CameraprofileDropDown_2.Value);
                app.VideoObject_2.ReturnedColorspace = "grayscale";
                app.VideoObject_2.ROIPosition =  [app.XoffsetSpinner_2.Value app.YoffsetSpinner_2.Value app.WidthSpinner_2.Value app.HeightSpinner_2.Value];
                triggerconfig(app.VideoObject_2, 'manual');
                app.VideoObject_2.LoggingMode = 'disk&memory';
                app.VideoObject_2.DiskLogger = app.VideoExport_2;

                set(app.VideoObject_2, 'TriggerRepeat', 0);
                set(app.VideoObject_2, 'FramesPerTrigger', FramesPerTrigger_Cam2);
                set(app.VideoObject_2, 'FramesAcquiredFcnCount', app.FrameUpdateSpinner_2.Value);
                set(app.VideoObject_2, 'Timeout', max(10, 0.1*app.DurationSpinner_2.Value));

                src = getselectedsource(app.VideoObject_2);
                src.Exposure = 1/2^(app.ExposureSpinner_2.Value);
                src.Gain = app.GainSpinner_2.Value;
                src.FrameRate = app.FramerateSpinner_2.Value;
                src.GainAuto = 'Off';

                app.VideoObject_2.FramesAcquiredFcn = @(src, event) saveCameraData(app, src, event, app.TimestampFile_2);
                app.VideoObject_2.StopFcn = @(src, event) stopCamera(app);
            end
            app.CurrentCameraState = 'Standby.Camera';
            app.setCameraViewState(app.CurrentCameraState);
        end

        
        function startCamera(app)
            if app.Camera1CheckBox.Value == 1
                start(app.VideoObject_1);
            end
            if app.Camera2CheckBox.Value == 1
                start(app.VideoObject_2);
            end
            app.CurrentCameraState = 'Standby.Ready';
            app.setCameraViewState(app.CurrentCameraState);
        end

        function triggerCamera(app)
            if app.Camera1CheckBox.Value == 1
                trigger(app.VideoObject_1);
            end
            if app.Camera2CheckBox.Value == 1
                trigger(app.VideoObject_2);
            end
            app.CurrentCameraState = 'Capture.Busy';
            app.setCameraViewState(app.CurrentCameraState);
        end

        function stopCamera(app)
            if strcmp(app.CurrentCameraState, 'Capture.Busy')
                app.CurrentCameraState = 'Cleaning Up';
                app.setCameraViewState(app.CurrentCameraState);
                if app.Camera1CheckBox.Value == 1
                   wait(app.VideoObject_1);
                end
                if app.Camera2CheckBox.Value == 1
                    wait(app.VideoObject_2);
                end
                while (1)
                    if (app.Camera1CheckBox.Value == 1 && (app.VideoObject_1.FramesAcquired ~= app.VideoObject_1.DiskLoggerFrameCount)) ... 
                            || (app.Camera2CheckBox.Value == 1 && (app.VideoObject_2.FramesAcquired ~= app.VideoObject_2.DiskLoggerFrameCount))
                        pause(.1)
                    else 
                        break
                    end
                end
            end
            if app.Camera1CheckBox.Value == 1
                stop(app.VideoObject_1);
            end
            if app.Camera2CheckBox.Value == 1
                stop(app.VideoObject_2);
            end
            fclose('all');
            app.CurrentCameraState = 'Unarm';
            app.setCameraViewState(app.CurrentCameraState);
        end

        function closeCamera(app)
            fclose('all');
            if app.Camera1CheckBox.Value == 1
                delete(app.VideoObject_1);
            end
            if app.Camera2CheckBox.Value == 1
                delete(app.VideoObject_2);
            end
            app.CurrentCameraState = 'Unarm';
            app.setCameraViewState(app.CurrentCameraState);
            imaqreset;
        end

        function previewCamera(app)
            if app.Camera1CheckBox.Value == 1
                preview(app.VideoObject_1);
                app.PreviewCam_1.Enable = 'off';
            end
            if app.Camera2CheckBox.Value == 1
                preview(app.VideoObject_2);
                app.PreviewCam_2.Enable = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ChannelNumbers = str2num(app.ChannelsList.Value); %#ok<ST2NM> 
            app.ChannelCount = length(app.ChannelNumbers);
            app.SGChannelList = app.ChannelNumbers(find(app.ChannelNumbers>=16));
            app.NameForceList = {"Fx", "Fy", "Fz", "Tx", "Ty", "Tz"};

            app.OffsetData = zeros(1, app.ChannelCount);
            
            calculateCalibratedMatrix(app)
            setMinMaxYTableDefault(app, -10, 10)
            plotParams = generatePlotParams(app);
            updateSubplotPanel(app)
            updateVectorPlotPanel(app)
            updateDataPlotPanel(app, plotParams)
            setYLimAxes(app)

            % Populate the plot selected channel list
            if isrow(app.ChannelNumbers)
                channelListCell = cellstr(num2str(app.ChannelNumbers'));
            else
                channelListCell = cellstr(num2str(app.ChannelNumbers));
            end

            app.ChannelplotselectDropDown.Items = channelListCell;
            app.ChannelplotselectDropDown.Value = channelListCell{1};

            app.CurrentNIDAQState = 'Unarm';
            setNIDAQViewState (app, app.CurrentNIDAQState)

            app.CurrentCameraState = 'Unarm';
            setCameraViewState (app, app.CurrentCameraState)

            app.VideoSetupBool_1 = false;
            app.VideoSetupBool_2 = false;
            
            if app.Camera1CheckBox.Value == 1 || app.Camera2CheckBox.Value == 1
                app.LiveplotsCheckBox.Value = 0;
            end
            
        end

        % Button pushed function: ArmButton
        function ArmButtonPushed(app, event)
            app.CurrentNIDAQState = 'Arming';
            cla(app.CaptureDataAxes, 'reset');
            setNIDAQViewState (app, app.CurrentNIDAQState)
            
            setDeviceParameters(app)
            updateSubplotPanel(app)
            updateVectorPlotPanel(app)

            plotParams = generatePlotParams(app);
            updateDataPlotPanel(app, plotParams)

            app.CurrentNIDAQState = 'Armed';
            setNIDAQViewState (app, app.CurrentNIDAQState)
        end

        % Value changed function: AcquireSwitch
        function AcquireSwitchFlip(app, event)
            value = app.AcquireSwitch.Value;
            flipAcquire(app, value);
        end

        % Value changed function: CaptureLengthField
        function CaptureLengthFieldValueChanged(app, event)
            app.CurrentNIDAQState = 'Unarm';
            if app.AcquireSwitch.Value == "Start"
                app.AcquireSwitch.Value = "Stop";
                flipAcquire(app, "Stop");
            end
            setNIDAQViewState(app, app.CurrentNIDAQState);
            if app.SamedurationasNIDAQCheckBox.Value == 1
                app.CombineDuration.Value = app.CaptureLengthField.Value;
            end
        end

        % Value changed function: Rate
        function RateValueChanged(app, event)
            app.CurrentNIDAQState = 'Unarm';
            setNIDAQViewState(app, app.CurrentNIDAQState);
        end

        % Value changed function: ChannelsList
        function ChannelsListValueChanged(app, event)
            app.ChannelNumbers = str2num(app.ChannelsList.Value); %#ok<ST2NM> 
            app.ChannelCount = length(app.ChannelNumbers);
            setMinMaxYTableDefault(app, -10, 10)
            app.OffsetData = zeros(1, app.ChannelCount);
            app.SGChannelList = app.ChannelNumbers(find(app.ChannelNumbers>=16));

            % Populate the plot selected channel list
            if isrow(app.ChannelNumbers)
                channelListCell = cellstr(num2str(app.ChannelNumbers'));
            else
                channelListCell = cellstr(num2str(app.ChannelNumbers));
            end

            app.ChannelplotselectDropDown.Items = channelListCell;
            app.ChannelplotselectDropDown.Value = channelListCell{1};

            cellfun(@(x) delete(x), app.LiveAxesList)
            cellfun(@(x) delete(x), app.DataAxesList)

            app.LiveAxesList = {};
            app.DataAxesList = {};
            app.LiveVectorAxesList = {};
            
            updateSubplotPanel(app)
            updateCapturePlotPanel(app)
            updateVectorPlotPanel(app)

            plotParams = generatePlotParams(app);
            updateDataPlotPanel(app, plotParams);
            app.CurrentNIDAQState = 'Unarm';
            if app.AcquireSwitch.Value == "Start"
                app.AcquireSwitch.Value = "Stop";
                flipAcquire(app, "Stop");
            end
            setNIDAQViewState(app, app.CurrentNIDAQState);
        end

        % Value changing function: ChannelsList
        function ChannelsListValueChanging(app, event)
            app.CurrentNIDAQState = 'Unarm';
            if app.AcquireSwitch.Value == "Start"
                app.AcquireSwitch.Value = "Stop";
                flipAcquire(app, "Stop");
            end
            setNIDAQViewState(app, app.CurrentNIDAQState);
        end

        % Display data changed function: MinMaxYTable
        function MinMaxYTableDisplayDataChanged(app, event)
            setYLimAxes(app)
            updateChannelData(app)
        end

        % Value changed function: CaptureButton
        function CaptureButtonValueChanged(app, event)
            updateCapturePlotPanel(app)
            value = app.CaptureButton.Value;
            if ~value
                % Capture cancelled
                app.CurrentNIDAQState = 'Acquisition.ReadyForCapture';
                setNIDAQViewState(app, app.CurrentNIDAQState);
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plotParams = generatePlotParams(app);
            updateDataPlotPanel(app, plotParams)
            data = applyOffset(app, app.CaptureData);
            plotCaptureData(app, data)
        end

        % Button pushed function: ApplycalibrationButton
        function ApplycalibrationButtonPushed(app, event)
            data = applyOffset(app, app.CaptureData);
            plotParams = generatePlotParams(app);
            applyCalibration(app, data)
            plotParams.ylabel = 'Force (N)';
            updateDataPlotPanel(app, plotParams)
            
            plotCaptureData(app, app.CaptureDataCalibrate)
        end

        % Button pushed function: ExporttocsvButton
        function ExporttocsvButtonPushed(app, event)
            currentDIR = app.NIDAQsavingDIREditField.Value;

            exportFolderName = char(string(app.SubjectNameEditField.Value) + "_Trial" + num2str(app.TrialNumberField.Value));
            exportFolderDIR = fullfile(currentDIR, exportFolderName);
            mkdir(exportFolderDIR);
            force = true;
            toWorkspace = false;
            timetable = false;
            initialOffsetState = app.PreOffsetSwitch.Value;

            %% Get data without offset
            app.PreOffsetSwitch.Value = "Off";
            data_woOffset = applyOffset(app, app.CaptureData);
            applyCalibration(app, data_woOffset);
            forceData_woOffset = app.CaptureDataCalibrate;
  
            % Exporting raw data
            data_woOffset = saveCaptureData(app, data_woOffset, "savingData", toWorkspace=toWorkspace, timetable=timetable);
            exportFileName = fullfile(exportFolderDIR, "data_woOffset.csv");
            if isfile(exportFileName)
                delete(exportFileName);
                pause(1);
            end
            writetable(data_woOffset, exportFileName, 'Delimiter', ',')
            pause(1);

            % Exporting force data
            forceData_woOffset = saveCaptureData(app, forceData_woOffset, "savingData", force=force, toWorkspace=toWorkspace, timetable=timetable);
            exportFileName = fullfile(exportFolderDIR, "forceData_woOffset.csv");
            if isfile(exportFileName)
                delete(exportFileName);
                pause(1);
            end
            writetable(forceData_woOffset, exportFileName, 'Delimiter', ',')
            pause(1);

            %% get data with offset
            app.PreOffsetSwitch.Value = "On";
            data_wOffset = applyOffset(app, app.CaptureData);
            applyCalibration(app, data_wOffset);
            forceData_wOffset = app.CaptureDataCalibrate;

            % Exporting raw data
            data_wOffset = saveCaptureData(app, data_wOffset, "savingData", toWorkspace=toWorkspace, timetable=timetable);
            exportFileName = fullfile(exportFolderDIR, "data_wOffset.csv");
            if isfile(exportFileName)
                delete(exportFileName);
                pause(1);
            end
            writetable(data_wOffset, exportFileName, 'Delimiter', ',')
            pause(1);

            % Exporting force data
            forceData_wOffset = saveCaptureData(app, forceData_wOffset, "savingData", force=force, toWorkspace=toWorkspace, timetable=timetable);
            exportFileName = fullfile(exportFolderDIR, "forceData_wOffset.csv");
            if isfile(exportFileName)
                delete(exportFileName);
                pause(1);
            end
            writetable(forceData_wOffset, exportFileName, 'Delimiter', ',')
            pause(1);
    
            % Export other params
            params.Channels = app.ChannelNumbers;
            params.ChannelsInfo = app.DAQ.Channels;
            params.OffsetDuration = app.OffsetDuration;
            params.offsetData = app.OffsetData;
            params.CalibratedMatrix = app.CalibratedMatrix;
            params.CaptureDuration = app.CaptureDuration;

            % Raw data
            rawData.CaptureData = app.CaptureDataRaw;
            rawData.OffsetData = app.OffsetDataRaw;
            
            % Timestamps data
            timestamps = app.CaptureTimestamps;
            exportFileName = fullfile(exportFolderDIR, "Test.mat");
            save(exportFileName, "rawData", ...
                                 "timestamps", ...
                                 "params");
            app.PreOffsetSwitch.Value = initialOffsetState;
            app.ExportcsvStatus.Text = 'Done!';
        end

        % Button pushed function: ExporttomatButton
        function ExporttomatButtonPushed(app, event)
            exportFileName = char(string(app.SubjectNameEditField.Value) + "_" + num2str(app.TrialNumberField.Value) + ".mat");
            initialOffsetState = app.PreOffsetSwitch.Value;
            force = true;
            toWorkspace = false;

            % Get data without offset
            app.PreOffsetSwitch.Value = "Off";
            data_woOffset = applyOffset(app, app.CaptureData);
            applyCalibration(app, data_woOffset);
            forceData_woOffset = app.CaptureDataCalibrate;
  
            data_woOffset = saveCaptureData(app, data_woOffset, "savingData", toWorkspace=toWorkspace);
            forceData_woOffset = saveCaptureData(app, forceData_woOffset, "savingData", force=force, toWorkspace=toWorkspace);

            % Get data with offset
            app.PreOffsetSwitch.Value = "On";
            data_wOffset = applyOffset(app, app.CaptureData);
            applyCalibration(app, data_wOffset);
            forceData_wOffset = app.CaptureDataCalibrate;

            data_wOffset = saveCaptureData(app, data_wOffset, "savingData", toWorkspace=toWorkspace);
            forceData_wOffset = saveCaptureData(app, forceData_wOffset, "savingData", force=force, toWorkspace=toWorkspace);

            % Capture parameters
            params.Channels = app.ChannelNumbers;
            params.ChannelsInfo = app.DAQ.Channels;
            params.OffsetDuration = app.OffsetDuration;
            params.offsetData = app.OffsetData;
            params.CalibratedMatrix = app.CalibratedMatrix;
            params.CaptureDuration = app.CaptureDuration;

            % Raw data
            rawData.CaptureData = app.CaptureDataRaw;
            rawData.OffsetData = app.OffsetDataRaw;
            
            % Timestamps data
            timestamps = app.CaptureTimestamps;

            save(exportFileName, "data_woOffset", ...
                                 "forceData_woOffset", ...
                                 "data_wOffset", ...
                                 "forceData_wOffset", ...
                                 "rawData", ...
                                 "timestamps", ...
                                 "params");

            app.PreOffsetSwitch.Value = initialOffsetState;
            app.ExportmatStatus.Text = 'Done!';
        end

        % Value changed function: BiasTick
        function BiasTickValueChanged(app, event)
            value = app.BiasTick.Value;
            if value && app.CurrentNIDAQState == "Acquisition.ReadyForCapture"
                app.OffsetStartMoment = app.Timestamps(end);
                app.OffsetDataRaw = app.Data;
                app.CurrentNIDAQState = 'Acquisition.CollectingOffset';
                setNIDAQViewState(app, app.CurrentNIDAQState);
            end
        end

        % Button pushed function: PlotChosenChannel
        function PlotChosenChannelButtonPushed(app, event)
            chosenChannel = app.ChannelplotselectDropDown.Value;
            if ~isnumeric(chosenChannel)
                chosenChannel = str2double(chosenChannel);
            end
            dataYLim = app.MinMaxYTable.Data;
            indexChannel = find(app.ChannelNumbers==chosenChannel);
            figure
            plot(app.CaptureTimestamps(:), app.CurrentPlottingData(:, indexChannel));
            xlim([app.CaptureTimestamps(1), app.CaptureTimestamps(end)]);
            ylim([dataYLim(indexChannel, 1), dataYLim(indexChannel, 2)]);
        end

        % Value changed function: XoffsetSpinner_2
        function XoffsetSpinner_2ValueChanged(app, event)
            value = app.XoffsetSpinner_2.Value;
            
        end

        % Value changed function: YoffsetSpinner_2
        function YoffsetSpinner_2ValueChanged(app, event)
            value = app.YoffsetSpinner_2.Value;
            
        end

        % Value changed function: WidthSpinner_2
        function WidthSpinner_2ValueChanged(app, event)
            value = app.WidthSpinner_2.Value;
            
        end

        % Value changed function: HeightSpinner_2
        function HeightSpinner_2ValueChanged(app, event)
            value = app.HeightSpinner_2.Value;
            
        end

        % Value changed function: FramerateSpinner_2
        function FramerateSpinner_2ValueChanged(app, event)
            value = app.FramerateSpinner_2.Value;
            
        end

        % Value changed function: GainSpinner_2
        function GainSpinner_2ValueChanged(app, event)
            value = app.GainSpinner_2.Value;
            
        end

        % Value changed function: DurationSpinner_2
        function DurationSpinner_2ValueChanged(app, event)
            value = app.DurationSpinner_2.Value;
            
        end

        % Value changed function: FrameUpdateSpinner_2
        function FrameUpdateSpinner_2ValueChanged(app, event)
            value = app.FrameUpdateSpinner_2.Value;
            
        end

        % Value changed function: Camera1CheckBox
        function Camera1CheckBoxValueChanged(app, event)
            if app.Camera1CheckBox.Value == 1 && app.Camera2CheckBox.Value == 1
                app.Synccam1and2CheckBox.Enable = 'on';
            else
                app.Synccam1and2CheckBox.Enable = 'off';
            end
            if aapp.Camera1CheckBox.Value == 1
                app.LiveplotsCheckBox.Value = 0;
            end
        end

        % Value changed function: Camera2CheckBox
        function Camera2CheckBoxValueChanged(app, event)
            if app.Camera1CheckBox.Value == 1 && app.Camera2CheckBox.Value == 1
                app.Synccam1and2CheckBox.Enable = 'on';
            else
                app.Synccam1and2CheckBox.Enable = 'off';
            end
            if aapp.Camera2CheckBox.Value == 1
                app.LiveplotsCheckBox.Value = 0;
            end
        end

        % Button pushed function: Synccam1and2settingsButton
        function Synccam1and2settingsButtonPushed(app, event)
            % Sync duration
            app.DurationSpinner.Value = app.CombineDuration.Value;
            app.DurationSpinner_2.Value = app.CombineDuration.Value;

            % Sync framerate
            app.FramerateSpinner.Value = app.CombineFramerate.Value;
            app.FramerateSpinner_2.Value = app.CombineFramerate.Value;

            % Sync frameupdate
            app.FrameUpdateSpinner.Value = app.CombineFrameupdate.Value;
            app.FrameUpdateSpinner_2.Value = app.CombineFrameupdate.Value;

        end

        % Value changed function: XoffsetSpinner
        function XoffsetSpinnerValueChanged(app, event)
            value = app.XoffsetSpinner.Value;
            
        end

        % Value changed function: WidthSpinner
        function WidthSpinnerValueChanged(app, event)
            value = app.WidthSpinner.Value;
            
        end

        % Button pushed function: SetupvideosButton
        function SetupvideosButtonPushed(app, event)
            if strcmp(app.CurrentCameraState, 'Unarm')
                setupVideoObjects(app);
            end
        end

        % Button pushed function: UpdateparamsButton
        function UpdateparamsButtonPushed(app, event)
            if strcmp(app.CurrentCameraState, 'Standby.Video')
                updateCamera(app);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if strcmp(app.CurrentCameraState, 'Standby.Camera')
                startCamera(app);
            end
        end

        % Button pushed function: TriggerButton
        function TriggerButtonPushed(app, event)
            if strcmp(app.CurrentCameraState, 'Standby.Ready') && ~(app.SynccameraandNIDAQCheckBox.Value == 1)
                triggerCamera(app);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if strcmp(app.CurrentCameraState, 'Capture.Busy') || strcmp(app.CurrentCameraState, 'Standby.Ready')
                app.StopButton.Enable = 'off';
                stopCamera(app);
            end
        end

        % Button pushed function: ClosecamerasButton
        function ClosecamerasButtonPushed(app, event)
            if ~strcmp(app.CurrentCameraState, 'Capture.Busy') || ~strcmp(app.CurrentCameraState, 'Cleaning Up') 
                app.ClosecamerasButton.Enable = 'off';
                closeCamera(app);
            end
        end

        % Button pushed function: PreviewCam_1
        function PreviewCam_1Pushed(app, event)
            if strcmp(app.CurrentCameraState, 'Standby.Ready')
                preview(app.VideoObject_1);
                app.PreviewCam_1.Enable = 'off';
            end
        end

        % Button pushed function: PreviewCam_2
        function PreviewCam_2Pushed(app, event)
            if strcmp(app.CurrentCameraState, 'Standby.Ready')
                preview(app.VideoObject_2);
                app.PreviewCam_2.Enable = 'off';
            end
        end

        % Value changed function: SynccameraandNIDAQCheckBox
        function SynccameraandNIDAQCheckBoxValueChanged(app, event)
            if app.SynccameraandNIDAQCheckBox.Value == 1
                app.CombineDuration.Value = app.CaptureLengthField.Value;
            end
            if strcmp(app.CurrentCameraState, 'Standby.Camera')
                updateCamera(app);
                app.CurrentCameraState = 'Standby.Camera';
                app.setCameraViewState(app.CurrentCameraState); 
            end
        end

        % Value changed function: SamedurationasNIDAQCheckBox
        function SamedurationasNIDAQCheckBoxValueChanged(app, event)
            value = app.SamedurationasNIDAQCheckBox.Value;
            if value == 1
                app.CombineDuration.Value = app.CaptureLengthField.Value;
            end
            if strcmp(app.CurrentCameraState, 'Standby.Camera')
                updateCamera(app);
                app.CurrentCameraState = 'Standby.Camera';
                app.setCameraViewState(app.CurrentCameraState); 
            end
        end

        % Value changed function: CombineDuration
        function CombineDurationValueChanged(app, event)
            value = app.CombineDuration.Value;
            if value == 1
                app.CaptureLengthField.Value = value;
            end
            if strcmp(app.CurrentCameraState, 'Standby.Camera')
                updateCamera(app);
                app.CurrentCameraState = 'Standby.Camera';
                app.setCameraViewState(app.CurrentCameraState); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ForceTransducer = uitab(app.TabGroup);
            app.ForceTransducer.Title = 'ForceTransducer';
            app.CaptureDataAxes = uiaxes(app.ForceTransducer);
            title(app.CaptureDataAxes, 'Captured data')
            xlabel(app.CaptureDataAxes, 'Time (s)')
            ylabel(app.CaptureDataAxes, 'Voltage (V)')
            zlabel(app.CaptureDataAxes, 'Z')
            app.Camera1parametersPanel = uipanel(app.ForceTransducer);
            app.Camera1parametersPanel.Title = 'Camera 1 parameters';
            app.CameranameEditFieldLabel = uilabel(app.Camera1parametersPanel);
            app.CameranameEditFieldLabel.Text = 'Camera name';
            app.CameranameEditField = uieditfield(app.Camera1parametersPanel, 'text');
            app.CameranameEditField.Value = 'tisimaq_r2013_64';
            app.CameraprofileDropDownLabel = uilabel(app.Camera1parametersPanel);
            app.CameraprofileDropDownLabel.Text = 'Camera profile';
            app.CameraprofileDropDown = uidropdown(app.Camera1parametersPanel);
            app.CameraprofileDropDown.Items = {'Y800 (1280x1024)', 'Option 2', 'Option 3', 'Option 4'};
            app.CameraprofileDropDown.Value = 'Y800 (1280x1024)';
            app.ROIPanel = uipanel(app.Camera1parametersPanel);
            app.ROIPanel.Title = 'ROI';
            app.XoffsetSpinnerLabel = uilabel(app.ROIPanel);
            app.XoffsetSpinnerLabel.Text = 'X-offset';
            app.XoffsetSpinner = uispinner(app.ROIPanel);
            app.XoffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @XoffsetSpinnerValueChanged, true);
            app.YoffsetSpinnerLabel = uilabel(app.ROIPanel);
            app.YoffsetSpinnerLabel.Text = 'Y-offset';
            app.YoffsetSpinner = uispinner(app.ROIPanel);
            app.WidthSpinnerLabel = uilabel(app.ROIPanel);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.ROIPanel);
            app.WidthSpinner.ValueChangedFcn = createCallbackFcn(app, @WidthSpinnerValueChanged, true);
            app.WidthSpinner.Value = 785;
            app.HeightSpinnerLabel = uilabel(app.ROIPanel);
            app.HeightSpinnerLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.ROIPanel);
            app.HeightSpinner.Value = 232;
            app.PropertiesPanel = uipanel(app.Camera1parametersPanel);
            app.PropertiesPanel.Title = 'Properties';
            app.FramerateSpinnerLabel = uilabel(app.PropertiesPanel);
            app.FramerateSpinnerLabel.Text = 'Framerate';
            app.FramerateSpinner = uispinner(app.PropertiesPanel);
            app.FramerateSpinner.Step = 10;
            app.FramerateSpinner.Limits = [30 400];
            app.FramerateSpinner.RoundFractionalValues = 'on';
            app.FramerateSpinner.Value = 200;
            app.GainSpinnerLabel = uilabel(app.PropertiesPanel);
            app.GainSpinnerLabel.Text = 'Gain';
            app.GainSpinner = uispinner(app.PropertiesPanel);
            app.GainSpinner.Value = 16;
            app.TriggertypeDropDownLabel = uilabel(app.PropertiesPanel);
            app.TriggertypeDropDownLabel.Text = 'Trigger type';
            app.TriggertypeDropDown = uidropdown(app.PropertiesPanel);
            app.TriggertypeDropDown.Items = {'Manual', 'Immediate', 'NIDAQ', 'TDT'};
            app.TriggertypeDropDown.Value = 'NIDAQ';
            app.ExposureSpinnerLabel = uilabel(app.PropertiesPanel);
            app.ExposureSpinnerLabel.Text = 'Exposure';
            app.ExposureSpinner = uispinner(app.PropertiesPanel);
            app.ExposureSpinner.Value = 10;
            app.DurationSpinnerLabel = uilabel(app.Camera1parametersPanel);
            app.DurationSpinnerLabel.Text = 'Duration';
            app.DurationSpinner = uispinner(app.Camera1parametersPanel);
            app.DurationSpinner.Limits = [1 Inf];
            app.DurationSpinner.RoundFractionalValues = 'on';
            app.DurationSpinner.Value = 20;
            app.FrameUpdateSpinnerLabel = uilabel(app.Camera1parametersPanel);
            app.FrameUpdateSpinnerLabel.Text = 'Frame Update';
            app.FrameUpdateSpinner = uispinner(app.Camera1parametersPanel);
            app.FrameUpdateSpinner.Step = 5;
            app.FrameUpdateSpinner.Limits = [5 Inf];
            app.FrameUpdateSpinner.RoundFractionalValues = 'on';
            app.FrameUpdateSpinner.Value = 60;
            app.ControlPanel = uipanel(app.Camera1parametersPanel);
            app.ControlPanel.Title = 'Control';
            app.StatusLabel_2 = uilabel(app.ControlPanel);
            app.StatusLabel_2.Text = 'Status:';
            app.NotreadyLabel = uilabel(app.ControlPanel);
            app.NotreadyLabel.Text = 'Not ready';
            app.PreviewCam_1 = uibutton(app.ControlPanel, 'push');
            app.PreviewCam_1.ButtonPushedFcn = createCallbackFcn(app, @PreviewCam_1Pushed, true);
            app.PreviewCam_1.Enable = 'off';
            app.PreviewCam_1.Text = 'Preview';
            app.Camera2parametersPanel = uipanel(app.ForceTransducer);
            app.Camera2parametersPanel.Title = 'Camera 2 parameters';
            app.CameranameEditField_2Label = uilabel(app.Camera2parametersPanel);
            app.CameranameEditField_2Label.Text = 'Camera name';
            app.CameranameEditField_2 = uieditfield(app.Camera2parametersPanel, 'text');
            app.CameranameEditField_2.Value = 'tisimaq_r2013_64';
            app.CameraprofileDropDown_2Label = uilabel(app.Camera2parametersPanel);
            app.CameraprofileDropDown_2Label.Text = 'Camera profile';
            app.CameraprofileDropDown_2 = uidropdown(app.Camera2parametersPanel);
            app.CameraprofileDropDown_2.Items = {'Y800 (1280x1024)', 'Option 2', 'Option 3', 'Option 4'};
            app.CameraprofileDropDown_2.Value = 'Y800 (1280x1024)';
            app.ROIPanel_2 = uipanel(app.Camera2parametersPanel);
            app.ROIPanel_2.Title = 'ROI';
            app.XoffsetSpinner_2Label = uilabel(app.ROIPanel_2);
            app.XoffsetSpinner_2Label.Text = 'X-offset';
            app.XoffsetSpinner_2 = uispinner(app.ROIPanel_2);
            app.XoffsetSpinner_2.ValueChangedFcn = createCallbackFcn(app, @XoffsetSpinner_2ValueChanged, true);
            app.YoffsetSpinner_2Label = uilabel(app.ROIPanel_2);
            app.YoffsetSpinner_2Label.Text = 'Y-offset';
            app.YoffsetSpinner_2 = uispinner(app.ROIPanel_2);
            app.YoffsetSpinner_2.ValueChangedFcn = createCallbackFcn(app, @YoffsetSpinner_2ValueChanged, true);
            app.WidthSpinner_2Label = uilabel(app.ROIPanel_2);
            app.WidthSpinner_2Label.Text = 'Width';
            app.WidthSpinner_2 = uispinner(app.ROIPanel_2);
            app.WidthSpinner_2.ValueChangedFcn = createCallbackFcn(app, @WidthSpinner_2ValueChanged, true);
            app.WidthSpinner_2.Value = 785;
            app.HeightSpinner_2Label = uilabel(app.ROIPanel_2);
            app.HeightSpinner_2Label.Text = 'Height';
            app.HeightSpinner_2 = uispinner(app.ROIPanel_2);
            app.HeightSpinner_2.ValueChangedFcn = createCallbackFcn(app, @HeightSpinner_2ValueChanged, true);
            app.HeightSpinner_2.Value = 232;
            app.PropertiesPanel_2 = uipanel(app.Camera2parametersPanel);
            app.PropertiesPanel_2.Title = 'Properties';
            app.FramerateSpinner_2Label = uilabel(app.PropertiesPanel_2);
            app.FramerateSpinner_2Label.Text = 'Framerate';
            app.FramerateSpinner_2 = uispinner(app.PropertiesPanel_2);
            app.FramerateSpinner_2.Step = 10;
            app.FramerateSpinner_2.Limits = [30 400];
            app.FramerateSpinner_2.RoundFractionalValues = 'on';
            app.FramerateSpinner_2.ValueChangedFcn = createCallbackFcn(app, @FramerateSpinner_2ValueChanged, true);
            app.FramerateSpinner_2.Value = 200;
            app.GainSpinner_2Label = uilabel(app.PropertiesPanel_2);
            app.GainSpinner_2Label.Text = 'Gain';
            app.GainSpinner_2 = uispinner(app.PropertiesPanel_2);
            app.GainSpinner_2.ValueChangedFcn = createCallbackFcn(app, @GainSpinner_2ValueChanged, true);
            app.GainSpinner_2.Value = 16;
            app.TriggertypeDropDown_2Label = uilabel(app.PropertiesPanel_2);
            app.TriggertypeDropDown_2Label.Text = 'Trigger type';
            app.TriggertypeDropDown_2 = uidropdown(app.PropertiesPanel_2);
            app.TriggertypeDropDown_2.Items = {'Manual', 'Immediate', 'NIDAQ', 'TDT'};
            app.TriggertypeDropDown_2.Value = 'NIDAQ';
            app.ExposureSpinner_2Label = uilabel(app.PropertiesPanel_2);
            app.ExposureSpinner_2Label.Text = 'Exposure';
            app.ExposureSpinner_2 = uispinner(app.PropertiesPanel_2);
            app.ExposureSpinner_2.Value = 10;
            app.DurationSpinner_2Label = uilabel(app.Camera2parametersPanel);
            app.DurationSpinner_2Label.Text = 'Duration';
            app.DurationSpinner_2 = uispinner(app.Camera2parametersPanel);
            app.DurationSpinner_2.Limits = [1 Inf];
            app.DurationSpinner_2.RoundFractionalValues = 'on';
            app.DurationSpinner_2.ValueChangedFcn = createCallbackFcn(app, @DurationSpinner_2ValueChanged, true);
            app.DurationSpinner_2.Value = 20;
            app.FrameUpdateSpinner_2Label = uilabel(app.Camera2parametersPanel);
            app.FrameUpdateSpinner_2Label.Text = 'Frame Update';
            app.FrameUpdateSpinner_2 = uispinner(app.Camera2parametersPanel);
            app.FrameUpdateSpinner_2.Step = 5;
            app.FrameUpdateSpinner_2.Limits = [5 Inf];
            app.FrameUpdateSpinner_2.RoundFractionalValues = 'on';
            app.FrameUpdateSpinner_2.ValueChangedFcn = createCallbackFcn(app, @FrameUpdateSpinner_2ValueChanged, true);
            app.FrameUpdateSpinner_2.Value = 60;
            app.ControlPanel_2 = uipanel(app.Camera2parametersPanel);
            app.ControlPanel_2.Title = 'Control';
            app.StatusLabel_3 = uilabel(app.ControlPanel_2);
            app.StatusLabel_3.Text = 'Status:';
            app.NotreadyLabel_2 = uilabel(app.ControlPanel_2);
            app.NotreadyLabel_2.Text = 'Not ready';
            app.PreviewCam_2 = uibutton(app.ControlPanel_2, 'push');
            app.PreviewCam_2.ButtonPushedFcn = createCallbackFcn(app, @PreviewCam_2Pushed, true);
            app.PreviewCam_2.Enable = 'off';
            app.PreviewCam_2.Text = 'Preview';
            app.NIDAQparametersPanel = uipanel(app.ForceTransducer);
            app.NIDAQparametersPanel.Title = 'NIDAQ parameters';
            app.CalibrationTick = uicheckbox(app.NIDAQparametersPanel);
            app.CalibrationTick.Text = 'Apply calibration';
            app.CalibrationTick.Value = true;
            app.BiasTick = uicheckbox(app.NIDAQparametersPanel);
            app.BiasTick.ValueChangedFcn = createCallbackFcn(app, @BiasTickValueChanged, true);
            app.BiasTick.Text = 'Apply bias';
            app.PlottingtimesEditFieldLabel = uilabel(app.NIDAQparametersPanel);
            app.PlottingtimesEditFieldLabel.Text = 'Plotting time (s)';
            app.LivePlotLengthField = uieditfield(app.NIDAQparametersPanel, 'numeric');
            app.LivePlotLengthField.Value = 0.45;
            app.ChannelslistEditFieldLabel = uilabel(app.NIDAQparametersPanel);
            app.ChannelslistEditFieldLabel.Text = 'Channels list';
            app.ChannelsList = uieditfield(app.NIDAQparametersPanel, 'text');
            app.ChannelsList.ValueChangedFcn = createCallbackFcn(app, @ChannelsListValueChanged, true);
            app.ChannelsList.ValueChangingFcn = createCallbackFcn(app, @ChannelsListValueChanging, true);
            app.ChannelsList.Value = '0, 1, 16, 17, 18, 19, 20, 21';
            app.DeviceEditFieldLabel = uilabel(app.NIDAQparametersPanel);
            app.DeviceEditFieldLabel.Text = 'Device';
            app.DeviceName = uieditfield(app.NIDAQparametersPanel, 'text');
            app.DeviceName.Value = 'dev1';
            app.DurationsEditField_2Label_2 = uilabel(app.NIDAQparametersPanel);
            app.DurationsEditField_2Label_2.Text = 'Rate (scan/s)';
            app.Rate = uieditfield(app.NIDAQparametersPanel, 'numeric');
            app.Rate.ValueChangedFcn = createCallbackFcn(app, @RateValueChanged, true);
            app.Rate.Value = 1000;
            app.PlotcolumnsLabel = uilabel(app.NIDAQparametersPanel);
            app.PlotcolumnsLabel.Text = 'Plot columns #';
            app.PlotColumnCount = uieditfield(app.NIDAQparametersPanel, 'numeric');
            app.PlotColumnCount.Value = 3;
            app.SetupequipmentsPanel = uipanel(app.ForceTransducer);
            app.SetupequipmentsPanel.Title = 'Setup equipments';
            app.NIDAQCheckBox = uicheckbox(app.SetupequipmentsPanel);
            app.NIDAQCheckBox.Text = 'NIDAQ';
            app.NIDAQCheckBox.Value = true;
            app.Camera1CheckBox = uicheckbox(app.SetupequipmentsPanel);
            app.Camera1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Camera1CheckBoxValueChanged, true);
            app.Camera1CheckBox.Text = 'Camera 1';
            app.Camera1CheckBox.Value = true;
            app.Camera2CheckBox = uicheckbox(app.SetupequipmentsPanel);
            app.Camera2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Camera2CheckBoxValueChanged, true);
            app.Camera2CheckBox.Text = 'Camera 2';
            app.Camera2CheckBox.Value = true;
            app.SynccameraandNIDAQCheckBox = uicheckbox(app.SetupequipmentsPanel);
            app.SynccameraandNIDAQCheckBox.ValueChangedFcn = createCallbackFcn(app, @SynccameraandNIDAQCheckBoxValueChanged, true);
            app.SynccameraandNIDAQCheckBox.Text = 'Sync camera and NIDAQ';
            app.DurationSpinner_3Label = uilabel(app.SetupequipmentsPanel);
            app.DurationSpinner_3Label.Text = 'Duration';
            app.CombineDuration = uispinner(app.SetupequipmentsPanel);
            app.CombineDuration.Limits = [1 Inf];
            app.CombineDuration.RoundFractionalValues = 'on';
            app.CombineDuration.ValueChangedFcn = createCallbackFcn(app, @CombineDurationValueChanged, true);
            app.CombineDuration.Value = 20;
            app.SamedurationasNIDAQCheckBox = uicheckbox(app.SetupequipmentsPanel);
            app.SamedurationasNIDAQCheckBox.ValueChangedFcn = createCallbackFcn(app, @SamedurationasNIDAQCheckBoxValueChanged, true);
            app.SamedurationasNIDAQCheckBox.Text = 'Same duration as NIDAQ';
            app.SamedurationasNIDAQCheckBox.Value = true;
            app.DurationSpinner_3Label_2 = uilabel(app.SetupequipmentsPanel);
            app.DurationSpinner_3Label_2.Text = 'Framerate';
            app.CombineFramerate = uispinner(app.SetupequipmentsPanel);
            app.CombineFramerate.Limits = [1 Inf];
            app.CombineFramerate.RoundFractionalValues = 'on';
            app.CombineFramerate.Value = 200;
            app.DurationSpinner_3Label_3 = uilabel(app.SetupequipmentsPanel);
            app.DurationSpinner_3Label_3.Text = 'Frameupdate';
            app.CombineFrameupdate = uispinner(app.SetupequipmentsPanel);
            app.CombineFrameupdate.Limits = [1 Inf];
            app.CombineFrameupdate.RoundFractionalValues = 'on';
            app.CombineFrameupdate.Value = 30;
            app.Synccam1and2settingsButton = uibutton(app.SetupequipmentsPanel, 'push');
            app.Synccam1and2settingsButton.ButtonPushedFcn = createCallbackFcn(app, @Synccam1and2settingsButtonPushed, true);
            app.Synccam1and2settingsButton.Text = 'Sync cam 1 and 2 settings';
            app.LiveplotsCheckBox = uicheckbox(app.SetupequipmentsPanel);
            app.LiveplotsCheckBox.Text = 'Live plots';
            app.TriggerparametersPanel = uipanel(app.ForceTransducer);
            app.TriggerparametersPanel.Title = 'Trigger parameters';
            app.TriggerConditionDropDownLabel = uilabel(app.TriggerparametersPanel);
            app.TriggerConditionDropDownLabel.Text = 'Trigger Condition';
            app.TriggerCondition = uidropdown(app.TriggerparametersPanel);
            app.TriggerCondition.Items = {'Rising', 'Falling'};
            app.TriggerCondition.Value = 'Rising';
            app.DurationsLabel_2 = uilabel(app.TriggerparametersPanel);
            app.DurationsLabel_2.Text = 'Duration (s)';
            app.CaptureLengthField = uispinner(app.TriggerparametersPanel);
            app.CaptureLengthField.Limits = [1 Inf];
            app.CaptureLengthField.RoundFractionalValues = 'on';
            app.CaptureLengthField.ValueChangedFcn = createCallbackFcn(app, @CaptureLengthFieldValueChanged, true);
            app.CaptureLengthField.Value = 30;
            app.OffsetDurationsLabel = uilabel(app.TriggerparametersPanel);
            app.OffsetDurationsLabel.Text = 'Offset Duration (s)';
            app.OffsetDurationField = uispinner(app.TriggerparametersPanel);
            app.OffsetDurationField.Limits = [0 Inf];
            app.TriggerVoltageVLabel = uilabel(app.TriggerparametersPanel);
            app.TriggerVoltageVLabel.Text = 'Trigger Voltage (V)';
            app.TriggerVoltage = uispinner(app.TriggerparametersPanel);
            app.TriggerChannelLabel = uilabel(app.TriggerparametersPanel);
            app.TriggerChannelLabel.Text = 'Trigger Channel';
            app.TriggerChannel = uispinner(app.TriggerparametersPanel);
            app.TabGroup_2 = uitabgroup(app.ForceTransducer);
            app.SubplotView = uitab(app.TabGroup_2);
            app.SubplotView.Title = 'Subplot View';
            app.SubplotAxesPanel = uipanel(app.SubplotView);
            app.CombinedPlotView = uitab(app.TabGroup_2);
            app.CombinedPlotView.Title = 'Combined Plot View';
            app.LiveDataAxes = uiaxes(app.CombinedPlotView);
            title(app.LiveDataAxes, 'Title')
            xlabel(app.LiveDataAxes, 'X')
            ylabel(app.LiveDataAxes, 'Y')
            zlabel(app.LiveDataAxes, 'Z')
            app.VectorViewTab = uitab(app.TabGroup_2);
            app.VectorViewTab.Title = 'Vector View';
            app.VectorAxesPanel = uipanel(app.VectorViewTab);
            app.NIDAQstatsPanel = uipanel(app.ForceTransducer);
            app.NIDAQstatsPanel.Title = 'NIDAQ stats';
            app.BuffersizeLabel = uilabel(app.NIDAQstatsPanel);
            app.BuffersizeLabel.Text = 'Buffer size:';
            app.BufferSizeValue = uilabel(app.NIDAQstatsPanel);
            app.BufferSizeValue.Text = '0';
            app.FIFOcountLabel = uilabel(app.NIDAQstatsPanel);
            app.FIFOcountLabel.Text = 'FIFO count: ';
            app.FIFOCountValue = uilabel(app.NIDAQstatsPanel);
            app.FIFOCountValue.Text = '0';
            app.CapturetimerLabel = uilabel(app.NIDAQstatsPanel);
            app.CapturetimerLabel.Text = 'Capture timer: ';
            app.TriggerchannelLabel = uilabel(app.NIDAQstatsPanel);
            app.TriggerchannelLabel.Text = 'Trigger channel: ';
            app.CapturetimerLabelValue = uilabel(app.NIDAQstatsPanel);
            app.CapturetimerLabelValue.Text = '0';
            app.TriggerChannelLabelValue = uilabel(app.NIDAQstatsPanel);
            app.TriggerChannelLabelValue.Text = '';
            app.StatusLabel = uilabel(app.NIDAQstatsPanel);
            app.StatusLabel.Text = 'Status: ';
            app.StatusText = uilabel(app.NIDAQstatsPanel);
            app.StatusText.Text = 'Standing by';
            app.MinMaxYTable = uitable(app.ForceTransducer);
            app.MinMaxYTable.ColumnName = {'Min Y'; 'Max Y'};
            app.MinMaxYTable.RowName = {'0, 1, 2'};
            app.MinMaxYTable.DisplayDataChangedFcn = createCallbackFcn(app, @MinMaxYTableDisplayDataChanged, true);
            app.SaveasPanel = uipanel(app.ForceTransducer);
            app.SaveasPanel.Title = 'Save as ...';
            app.CamerasavinginthesamedirectoryasNIDAQCheckBox = uicheckbox(app.SaveasPanel);
            app.CamerasavinginthesamedirectoryasNIDAQCheckBox.Text = 'Camera saving in the same directory as NIDAQ?';
            app.CamerasavinginthesamedirectoryasNIDAQCheckBox.Value = true;
            app.NIDAQsavingDIREditFieldLabel = uilabel(app.SaveasPanel);
            app.NIDAQsavingDIREditFieldLabel.Text = 'NIDAQ saving DIR';
            app.NIDAQsavingDIREditField = uieditfield(app.SaveasPanel, 'text');
            app.NIDAQsavingDIREditField.Value = 'E:\Test 2';
            app.CamerasavingDIREditFieldLabel = uilabel(app.SaveasPanel);
            app.CamerasavingDIREditFieldLabel.Text = 'Camera saving DIR';
            app.CamerasavingDIREditField = uieditfield(app.SaveasPanel, 'text');
            app.CamerasavingDIREditField.Editable = 'off';
            app.CamerasavingDIREditField.Value = 'E:\Test 2';
            app.VariablenameEditFieldLabel = uilabel(app.SaveasPanel);
            app.VariablenameEditFieldLabel.Text = 'Variable name';
            app.VariableNameEditField = uieditfield(app.SaveasPanel, 'text');
            app.VariableNameEditField.Value = 'capturedData';
            app.SubjectnameLabel = uilabel(app.SaveasPanel);
            app.SubjectnameLabel.Text = 'Subject name';
            app.SubjectNameEditField = uieditfield(app.SaveasPanel, 'text');
            app.SubjectNameEditField.Value = 'subject1';
            app.TrialLabel = uilabel(app.SaveasPanel);
            app.TrialLabel.Text = 'Trial #';
            app.TrialNumberField = uispinner(app.SaveasPanel);
            app.TrialNumberField.RoundFractionalValues = 'on';
            app.Videoname1EditFieldLabel = uilabel(app.SaveasPanel);
            app.Videoname1EditFieldLabel.Text = 'Video name  1';
            app.Videoname1EditField = uieditfield(app.SaveasPanel, 'text');
            app.Videoname1EditField.Value = 'Camera_1';
            app.Videoname2EditFieldLabel = uilabel(app.SaveasPanel);
            app.Videoname2EditFieldLabel.Text = 'Video name  2';
            app.Videoname2EditField = uieditfield(app.SaveasPanel, 'text');
            app.Videoname2EditField.Value = 'Camera_2';
            app.AdddatetimetonameCheckBox = uicheckbox(app.SaveasPanel);
            app.AdddatetimetonameCheckBox.Text = 'Add datetime to name';
            app.AdddatetimetonameCheckBox.Value = true;
            app.CameracontrolPanel = uipanel(app.ForceTransducer);
            app.CameracontrolPanel.Title = 'Camera control';
            app.SetupvideosButton = uibutton(app.CameracontrolPanel, 'push');
            app.SetupvideosButton.ButtonPushedFcn = createCallbackFcn(app, @SetupvideosButtonPushed, true);
            app.SetupvideosButton.Text = 'Setup videos';
            app.UpdateparamsButton = uibutton(app.CameracontrolPanel, 'push');
            app.UpdateparamsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateparamsButtonPushed, true);
            app.UpdateparamsButton.Text = 'Update params';
            app.StartButton = uibutton(app.CameracontrolPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TriggerButton = uibutton(app.CameracontrolPanel, 'push');
            app.TriggerButton.ButtonPushedFcn = createCallbackFcn(app, @TriggerButtonPushed, true);
            app.TriggerButton.Text = 'Trigger';
            app.StopButton = uibutton(app.CameracontrolPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ClosecamerasButton = uibutton(app.CameracontrolPanel, 'push');
            app.ClosecamerasButton.ButtonPushedFcn = createCallbackFcn(app, @ClosecamerasButtonPushed, true);
            app.ClosecamerasButton.Text = 'Close cameras';
            app.NIDAQcontrolPanel = uipanel(app.ForceTransducer);
            app.NIDAQcontrolPanel.Title = 'NIDAQ control';
            app.ArmButton = uibutton(app.NIDAQcontrolPanel, 'push');
            app.ArmButton.ButtonPushedFcn = createCallbackFcn(app, @ArmButtonPushed, true);
            app.ArmButton.Text = 'Arm';
            app.ArmedLampLabel = uilabel(app.NIDAQcontrolPanel);
            app.ArmedLampLabel.Text = 'Armed';
            app.ArmedLamp = uilamp(app.NIDAQcontrolPanel);
            app.AcquireSwitchLabel = uilabel(app.NIDAQcontrolPanel);
            app.AcquireSwitchLabel.Text = 'Acquire';
            app.AcquireSwitch = uiswitch(app.NIDAQcontrolPanel, 'slider');
            app.AcquireSwitch.Items = {'Stop', 'Start'};
            app.AcquireSwitch.ValueChangedFcn = createCallbackFcn(app, @AcquireSwitchFlip, true);
            app.AcquireSwitch.Value = 'Stop';
            app.CaptureButton = uibutton(app.NIDAQcontrolPanel, 'state');
            app.CaptureButton.ValueChangedFcn = createCallbackFcn(app, @CaptureButtonValueChanged, true);
            app.CaptureButton.Text = 'Capture';
            app.ExportcontrolPanel = uipanel(app.ForceTransducer);
            app.ExportcontrolPanel.Title = 'Export control';
            app.ExporttocsvButton = uibutton(app.ExportcontrolPanel, 'push');
            app.ExporttocsvButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttocsvButtonPushed, true);
            app.ExporttocsvButton.Text = 'Export to .csv';
            app.ExportcsvStatus = uilabel(app.ExportcontrolPanel);
            app.ExportcsvStatus.Text = 'NA';
            app.ExportmatStatus = uilabel(app.ExportcontrolPanel);
            app.ExportmatStatus.Text = 'NA';
            app.ExporttomatButton = uibutton(app.ExportcontrolPanel, 'push');
            app.ExporttomatButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttomatButtonPushed, true);
            app.ExporttomatButton.Text = 'Export to .mat';
            app.StatusLabel_4 = uilabel(app.ExportcontrolPanel);
            app.StatusLabel_4.Text = 'Status:';
            app.EncodevideosButton = uibutton(app.ExportcontrolPanel, 'push');
            app.EncodevideosButton.Text = 'Encode videos';
            app.EncodevideoStatus = uilabel(app.ExportcontrolPanel);
            app.EncodevideoStatus.Text = 'NA';
            app.CamerastatsPanel = uipanel(app.ForceTransducer);
            app.CamerastatsPanel.Title = 'Camera stats';
            app.CapturetimerLabel_2 = uilabel(app.CamerastatsPanel);
            app.CapturetimerLabel_2.Text = 'Capture timer: ';
            app.CapturetimerLabelValue_2 = uilabel(app.CamerastatsPanel);
            app.CapturetimerLabelValue_2.Text = '0';
            app.StatusLabel_5 = uilabel(app.CamerastatsPanel);
            app.StatusLabel_5.Text = 'Status: ';
            app.StatusText_2 = uilabel(app.CamerastatsPanel);
            app.StatusText_2.Text = 'Standing by';
            app.CameraControlTab = uitab(app.TabGroup);
            app.CameraControlTab.Title = 'CameraControl';
            app.PostOffsetPeriodSpinnerLabel = uilabel(app.CameraControlTab);
            app.PostOffsetPeriodSpinnerLabel.Text = 'Post-Offset Period';
            app.PostOffsetPeriodSpinner = uispinner(app.CameraControlTab);
            app.PostOffsetPeriodSpinner.Step = 0.5;
            app.PostOffsetPeriodSpinner.Limits = [0 Inf];
            app.PostOffsetSwitchLabel = uilabel(app.CameraControlTab);
            app.PostOffsetSwitchLabel.Text = 'Post-Offset';
            app.PostOffsetSwitch = uiswitch(app.CameraControlTab, 'toggle');
            app.PlotChosenChannel = uibutton(app.CameraControlTab, 'push');
            app.PlotChosenChannel.ButtonPushedFcn = createCallbackFcn(app, @PlotChosenChannelButtonPushed, true);
            app.PlotChosenChannel.Text = 'Plot channel';
            app.ChannelplotselectDropDownLabel = uilabel(app.CameraControlTab);
            app.ChannelplotselectDropDownLabel.Text = 'Channel plot select';
            app.ChannelplotselectDropDown = uidropdown(app.CameraControlTab);
            app.ChannelplotselectDropDown.Items = {'0', '1', '16', '17', '18', '19', '20', '21'};
            app.ChannelplotselectDropDown.Value = '0';
            app.DataPlotPanel = uipanel(app.CameraControlTab);
            app.DataPlotPanel.Title = 'Data Plot Panel';
            app.PlotButton = uibutton(app.CameraControlTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ApplycalibrationButton = uibutton(app.CameraControlTab, 'push');
            app.ApplycalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @ApplycalibrationButtonPushed, true);
            app.ApplycalibrationButton.Text = 'Apply calibration';
            app.PreOffsetSwitchLabel = uilabel(app.CameraControlTab);
            app.PreOffsetSwitchLabel.Text = 'Pre-Offset';
            app.PreOffsetSwitch = uiswitch(app.CameraControlTab, 'toggle');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotionControl_DevelopingCamera

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end